classdef HoloDoppler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HoloDopplerUIFigure           matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        MainviewTab                   matlab.ui.container.Tab
        FileselectionPanel            matlab.ui.container.Panel
        LoadfileButton                matlab.ui.control.Button
        SavevideosButton              matlab.ui.control.Button
        batchsizeEditFieldLabel       matlab.ui.control.Label
        batchsizeEditField            matlab.ui.control.NumericEditField
        batchstrideEditFieldLabel     matlab.ui.control.Label
        batchstrideEditField          matlab.ui.control.NumericEditField
        zEditFieldLabel               matlab.ui.control.Label
        zEditField                    matlab.ui.control.NumericEditField
        wavelengthEditFieldLabel      matlab.ui.control.Label
        wavelengthEditField           matlab.ui.control.NumericEditField
        imageregistrationCheckBox     matlab.ui.control.CheckBox
        temporalfilterCheckBox        matlab.ui.control.CheckBox
        ParallelismDropDown           matlab.ui.control.DropDown
        positioninfileSliderLabel     matlab.ui.control.Label
        positioninfileSlider          matlab.ui.control.Slider
        temporalfilterEditField       matlab.ui.control.NumericEditField
        saverawvideosCheckBox         matlab.ui.control.CheckBox
        AutofocusButton               matlab.ui.control.Button
        SVDCheckBox                   matlab.ui.control.CheckBox
        phaseregistrationCheckBox     matlab.ui.control.CheckBox
        lowfrequencyCheckBox          matlab.ui.control.CheckBox
        DxEditFieldLabel              matlab.ui.control.Label
        DxEditField                   matlab.ui.control.NumericEditField
        DyEditFieldLabel              matlab.ui.control.Label
        DyEditField                   matlab.ui.control.NumericEditField
        EditFieldLabel                matlab.ui.control.Label
        EditField                     matlab.ui.control.NumericEditField
        refbatchsizeEditFieldLabel    matlab.ui.control.Label
        refbatchsizeEditField         matlab.ui.control.NumericEditField
        saveadditionalvideosCheckBox  matlab.ui.control.CheckBox
        CurrentFilePanel              matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        AberationcompensationPanel    matlab.ui.container.Panel
        Label_2                       matlab.ui.control.Label
        ShackHarmannaberrationcompensationCheckBox  matlab.ui.control.CheckBox
        zernikeranksEditFieldLabel    matlab.ui.control.Label
        shackhartmannzernikeranksEditField  matlab.ui.control.NumericEditField
        numsubapEditFieldLabel        matlab.ui.control.Label
        numsubapEditField             matlab.ui.control.NumericEditField
        subaperturemarginEditFieldLabel  matlab.ui.control.Label
        subaperturemarginEditField    matlab.ui.control.NumericEditField
        PreviewButton                 matlab.ui.control.Button
        IterativeaberrationcompensationCheckBox  matlab.ui.control.CheckBox
        zernikeranksEditField_2Label  matlab.ui.control.Label
        optimizationzernikeranksEditField  matlab.ui.control.NumericEditField
        tolLabel                      matlab.ui.control.Label
        zernikestolEditField          matlab.ui.control.NumericEditField
        maxconstraintLabel            matlab.ui.control.Label
        maxconstraintEditField        matlab.ui.control.NumericEditField
        masknumiterLabel              matlab.ui.control.Label
        masknumiterEditField          matlab.ui.control.NumericEditField
        UIAxes_1                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        GridLayout2                   matlab.ui.container.GridLayout
        GridLayout3                   matlab.ui.container.GridLayout
        PowerdopplerPanel             matlab.ui.container.Panel
        f1Label                       matlab.ui.control.Label
        f1EditField                   matlab.ui.control.NumericEditField
        f2EditFieldLabel              matlab.ui.control.Label
        f2EditField                   matlab.ui.control.NumericEditField
        ColorimagePanel               matlab.ui.container.Panel
        LowfrequencyrangeLabel        matlab.ui.control.Label
        HighfrequencyrangeLabel       matlab.ui.control.Label
        f1EditField_2Label            matlab.ui.control.Label
        compositef1EditField          matlab.ui.control.NumericEditField
        f2EditField_2Label            matlab.ui.control.Label
        compositef2EditField          matlab.ui.control.NumericEditField
        f3EditFieldLabel              matlab.ui.control.Label
        compositef3EditField          matlab.ui.control.NumericEditField
        Panel_6                       matlab.ui.container.Panel
        NotesTextAreaLabel            matlab.ui.control.Label
        NotesTextArea                 matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        % skip callbacks if no file_M0 is loaded
        file_loaded
        
        filename % current file_M0 loaded
        filepath
        extension % .raw or .cine
        interferogram_stream
        
        % data related to current displayed frame
        frame_batch
        
        % misc proxy data
        Nx
        Ny
        Fs
        pix_width
        pix_height
        gw
        kernel
        FH
        hologram
        DX % x circshift for registration
        DY % y circshift for registration
        
        
        % misc
        cache % cache containg all parameters
    end
    
    methods (Access = public)
        function compute_kernel(app, use_gpu)
            app.kernel = propagation_kernel(app.Nx, app.Ny, app.zEditField.Value, app.wavelengthEditField.Value, app.pix_width, app.pix_height, use_gpu);
        end
    
        function compute_FH(app, use_gpu)
            if use_gpu
                app.FH = gpuArray(fftshift(fft2(app.frame_batch)) .* app.kernel);
            else
                app.FH = fftshift(fft2(app.frame_batch)) .* app.kernel;
            end
        end
    
        function compute_hologram(app, use_gpu)
            acquisition = DopplerAcquisition(app.Nx,app.Ny,app.Fs/1000,app.zEditField.Value,app.wavelengthEditField.Value,app.DX,app.DY,app.pix_width,app.pix_height);

            app.hologram = reconstruct_hologram(app.FH, app.f1EditField.Value, app.f2EditField.Value, acquisition, app.gw, use_gpu, app.SVDCheckBox.Value);
            app.hologram = flip(app.hologram);
        end
    
        function compute_color_hologram(app, use_gpu)
            acquisition = DopplerAcquisition(app.Nx,app.Ny,app.Fs/1000,app.zEditField.Value,app.wavelengthEditField.Value,app.DX,app.DY,app.pix_width,app.pix_height);
            
            [~,~,~,~,freq_low,freq_high] = reconstruct_hologram(app.FH, app.f1EditField.Value, app.f2EditField.Value, acquisition, app.gw, use_gpu, app.SVDCheckBox.Value,...
                                                                [], app.compositef1EditField.Value, app.compositef2EditField.Value, app.compositef3EditField.Value);
            
            if app.lowfrequencyCheckBox.Value
                app.hologram = construct_colored_image(gather(freq_high), gather(freq_low), app.lowfrequencyCheckBox.Value);
            else
                app.hologram = construct_colored_image(gather(freq_low), gather(freq_high), app.lowfrequencyCheckBox.Value);
            end
          
            app.hologram = flip(app.hologram);
        end
       
        function show_hologram(app)
            if app.lowfrequencyCheckBox.Value
                sign = -1;
            else
                sign = 1;
            end
            
            imshow(mat2gray(sign*app.hologram), 'Parent', app.UIAxes_1,...
                   'XData', [1 app.UIAxes_1.Position(3)], ...
                   'YData', [1 app.UIAxes_1.Position(4)]);
        end
    
        function show_color_hologram(app)
            imshow(mat2gray(app.hologram), 'Parent', app.UIAxes_2,...
                   'XData', [1 app.UIAxes_2.Position(3)], ...
                   'YData', [1 app.UIAxes_2.Position(4)]);
        end
    end
    
    methods (Access = private)   
        function create_cache(app)
            c = GuiCache();
            
            c.batch_size = app.batchsizeEditField.Value;
            c.batch_stride = app.batchstrideEditField.Value;
            c.z = app.zEditField.Value;
            c.f1 = app.f1EditField.Value;
            c.f2 = app.f2EditField.Value;
            c.wavelength = app.wavelengthEditField.Value;
            c.Fs = app.Fs;
            c.pix_width = app.pix_width;
            c.pix_height = app.pix_height;
            c.registration = app.imageregistrationCheckBox.Value;
            c.temporal_filter_flag = app.temporalfilterCheckBox.Value;
            c.temporal_filter = app.temporalfilterEditField.Value;
            c.parallelism = app.ParallelismDropDown.Value;
            c.registration_via_phase = app.phaseregistrationCheckBox.Value;
            c.aberration_compensation = app.IterativeaberrationcompensationCheckBox.Value;
            
            c.notes = app.NotesTextArea.Value;
            c.DX = app.DX;
            c.DY = app.DY;
            c.position_in_file = app.positioninfileSlider.Value;
            c.save_additional_videos = app.saveadditionalvideosCheckBox.Value;
            
            c.color_f1 = app.compositef1EditField.Value;
            c.color_f2 = app.compositef2EditField.Value;
            c.color_f3 = app.compositef3EditField.Value;
            c.low_frequency = app.lowfrequencyCheckBox.Value;
            
            % iterative optimization parameters
            c.iterative_aberration_compensation = app.IterativeaberrationcompensationCheckBox.Value;
            c.optimization_zernike_ranks = app.optimizationzernikeranksEditField.Value;
            c.mask_num_iter = app.masknumiterEditField.Value;
            c.zernikes_tol = app.zernikestolEditField.Value;
            c.max_constraint = app.maxconstraintEditField.Value;
            
            % shack-hartmann parameters
            c.shack_hartmann_aberration_compensation = app.ShackHarmannaberrationcompensationCheckBox.Value;
            c.shack_hartmann_zernike_ranks = app.shackhartmannzernikeranksEditField.Value;
            c.num_subapertures = app.numsubapEditField.Value;
            c.subaperture_margin = app.subaperturemarginEditField.Value;
            
            app.cache = c;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set app constants
            app.gw = 35;
            app.file_loaded = false;
            app.DX = 0;
            app.DY = 0;
        end

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            %% open file and manage file extension
            [fname, fpath] = uigetfile('*.raw;*.cine;*.holo');
            if fname == 0
                return
            end
            app.Label_2.Text = '';
            [~, ~, ext] = fileparts(fname);
            app.extension = ext;
            
            switch ext
                case '.cine'
                    app.filename = fname;
                    app.filepath = fpath;
                    
                    app.interferogram_stream = CineReader(fullfile(app.filepath, app.filename));
                    
                    % fetch data from cine file
                    app.Fs = double(app.interferogram_stream.frame_rate);
                    app.f2EditField.Value = floor(app.Fs / (2*1000));
                    app.compositef3EditField.Value = floor(app.Fs / (2*1000));
                    app.pix_width = 1 / double(app.interferogram_stream.horizontal_pix_per_meter);
                    app.pix_height = 1 / double(app.interferogram_stream.vertical_pix_per_meter);
                    app.Nx = double(app.interferogram_stream.frame_width);
                    app.Ny = double(app.interferogram_stream.frame_height);
                case '.raw'
                    app.filename = fname;
                    app.filepath = fpath;
                    
                    % raw file - fetching missing data from user
                    
                    % Dialog box
                    prompt = {'Nx:', 'Ny:', 'Fs:', 'pix width:', 'pix height:', 'endianness (b/l):'};
                    dlgtitle = 'Raw file detected - Input missing data';
                    dims = [1 35];
                    definput = {'512', '512', '75000', '28e-6', '28e-6', 'b'};
                    
                    answer = inputdlg(prompt, dlgtitle, dims, definput);
                    frame_width = str2num(answer{1});
                    frame_height = str2num(answer{2});
                    app.Fs = str2num(answer{3});
                    app.pix_width = str2double(answer{4});
                    app.pix_height = str2double(answer{5});
                    endianness = answer{6};
                    
                    if isempty(app.Nx) || isempty(app.Ny) || isempty(app.Fs) || isempty(app.pix_width) || isempty(app.pix_height)
                        error('Bad raw data input - file could not be loaded correctly');
                    end
                    
                    app.f2EditField.Value = floor(app.Fs / (2*1000));
                    app.compositef3EditField.Value = floor(app.Fs / (2*1000));

                    acquisition = DopplerAcquisition(frame_width, frame_height,app.Fs/1000,app.zEditField.Value,app.wavelengthEditField.Value,app.DX,app.DY,app.pix_width,app.pix_height);
                    app.interferogram_stream = RawReader(fullfile(app.filepath, app.filename), endianness, acquisition, app.batchsizeEditField.Value, app.batchstrideEditField.Value);
            
                    app.Nx = app.interferogram_stream.acquisition.Nx;
                    app.Ny = app.interferogram_stream.acquisition.Ny;
                case '.holo'
                    app.filename = fname;
                    app.filepath = fpath;
                    
                    app.interferogram_stream = HoloReader(fullfile(app.filepath, app.filename));
                    
                    app.zEditField.Value = app.interferogram_stream.footer.z;
                    app.wavelengthEditField.Value = app.interferogram_stream.footer.lambda;
                    
                    app.Nx = single(app.interferogram_stream.frame_width);
                    app.Ny = single(app.interferogram_stream.frame_height);
                    app.pix_width = app.interferogram_stream.footer.pixel_size * 1e-6;
                    app.pix_height = app.interferogram_stream.footer.pixel_size * 1e-6;
                    % TODO
                    % app.Fs = ????????
            end
            
            % display file name in GUI
            app.CurrentFilePanel.Title = sprintf("Current file: %s", fullfile(app.filepath, app.filename));            
            
            % change position slider limits according to number of frames in the file
            app.positioninfileSlider.Limits = [0, double(app.interferogram_stream.num_frames-app.batchsizeEditField.Value)];
            app.positioninfileSlider.Value = 0;
            app.EditField.Limits = [0, double(app.interferogram_stream.num_frames-app.batchsizeEditField.Value)];
            app.EditField.Value = 0;
            
            % load data required to display images in the GUI
            app.frame_batch = app.interferogram_stream.read_frame_batch(app.batchsizeEditField.Value, floor(app.positioninfileSlider.Value));
            
            %% Load settings from previous runs if available
            %  This might not work, for instance if previous runs were made
            %  with a different version of the software, such that the
            %  cache structure is different and cannot be loaded
            try
                [cache, cache_found] = fetch_cache(app.filepath, app.filename, ext);
            catch
                % the directory is present but is missing files or contains
                % corrupt data
                cache = [];
                cache_found = false;
            end
            
            if cache_found
                try
                    % set gui parameters from cache
                    app.batchsizeEditField.Value = cache.batch_size;
                    app.batchstrideEditField.Value = cache.batch_stride;
                    app.zEditField.Value = cache.z;
                    app.f1EditField.Value = cache.f1;
                    app.f2EditField.Value = cache.f2;
                    app.wavelengthEditField.Value = cache.wavelength;
                    
                    app.saveadditionalvideosCheckBox.Value = cache.save_additional_videos;
                    
                    app.NotesTextArea.Value = cache.notes;
                    app.positioninfileSlider.Value = cache.position_in_file;
                    app.EditField.Value = cache.position_in_file;
                    
                    app.IterativeaberrationcompensationCheckBox.Value = cache.iterative_aberration_compensation;
                    app.masknumiterEditField.Value = cache.mask_num_iter;
                    app.zernikestolEditField.Value = cache.low_order_zernikes;
                    app.maxconstraintEditField.Value = cache.low_order_max_constraint;
                    
                    app.ShackHarmannaberrationcompensationCheckBox.Value = cache.shack_hartmann_aberration_compensation;
                    app.shackhartmannzernikeranksEditField.Value = cache.shack_hartmann_zernike_ranks;
                    app.numsubapEditField.Value = cache.num_subapertures;
                    app.subaperturemarginEditField.Value = cache.subaperture_margin;
                    
                    app.compositef1EditField.Value = cache.color_f1;
                    app.compositef2EditField.Value = cache.color_f2;
                    app.compositef3EditField.Value = cache.color_f3;
                    app.lowfrequencyCheckBox.Value = cache.low_frequency;
                catch
                  % an expection may have been thrown if the .mat file does
                  % not contain all of the required fields (e.g. if the .mat file was generated by
                  % an older version of the software). This is not a bug.
                  
                  % do nothing
                end
            else
                % set default gui parameters
                
                % do nothing, default parameters are already set in the GUI
                % toolbox
            end
            
            %% display images on file load
            
            % compute FH
            app.file_loaded = true;
            app.compute_kernel(false);
            app.compute_FH(true);
            
            app.compute_hologram(true);
            app.show_hologram();
            
            app.compute_color_hologram(true);
            app.show_color_hologram();
        end

        % Value changed function: zEditField
        function zEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.compute_kernel(false);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
            
            app.compute_color_hologram(true);
            app.show_color_hologram();
        end

        % Value changed function: f1EditField
        function f1EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: f2EditField
        function f2EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: wavelengthEditField
        function wavelengthEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.compute_kernel(false);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
            
            app.compute_color_hologram(true);
            app.show_color_hologram();
        end

        % Value changed function: batchsizeEditField
        function batchsizeEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.positioninfileSlider.Limits = [0, double(app.interferogram_stream.num_frames-app.batchsizeEditField.Value)];
            app.positioninfileSlider.Value = min(app.positioninfileSlider.Value, app.positioninfileSlider.Limits(2));
            app.EditField.Limits = [0, double(app.interferogram_stream.num_frames-app.batchsizeEditField.Value)];
            app.EditField.Value = min(app.EditField.Value, app.EditField.Limits(2));
            
            app.frame_batch = app.interferogram_stream.read_frame_batch(app.batchsizeEditField.Value, 0);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
            
            app.compute_color_hologram(true);
            app.show_color_hologram();
        end

        % Button pushed function: SavevideosButton
        function SavevideosButtonPushed(app, event)
            % main program loop
            % Select a file and call other functions
            % to perform a full computation and create
            % output videos.
            
            if ~app.file_loaded
                return
            end
            
            % start clock to monitor
            % computation duration
            tic;
            
            % save all gui parameters to a struct.
            % The current computation will fetch every parameter
            % from the cache and not from the gui.
            % The purpose of this is to prevent gui interactions
            % to mess up the parameters while a computation is ongoing.
            app.create_cache();
            
            aberration_correction = AberrationCorrection();
            image_registration = ImageRegistration();
            
            % select parallelism policy
            switch app.cache.parallelism
                case 'CPU multithread'
                    parfor_arg = Inf;
                    use_multithread = true;
                    use_gpu = false;
                case 'GPU parallelization'
                    parfor_arg = 0;
                    use_multithread = false;
                    use_gpu = true;
                case 'CPU singlethread'
                    parfor_arg = 0;
                    use_multithread = false;
                    use_gpu = false;
                case 'CPU/GPU rendering/optim'
                    % TODO
                    parfor_arg = Inf;
                    use_multithread = true;
                    use_gpu = true;
            end
            
            % create proxy variables so that the entire gui
            % is not broadcasted to workers
            % in the parfor loops
            istream = app.interferogram_stream;
            j_win = app.cache.batch_size;
            j_step = app.cache.batch_stride;
            local_f1 = app.cache.f1;
            local_f2 = app.cache.f2;
            local_gw = app.gw;
            local_Nx = app.Nx;
            local_Ny = app.Ny;
            local_svd = app.SVDCheckBox.Value;
            local_save_additional_videos = app.cache.save_additional_videos;
            
            local_kernel = app.kernel;
            acquisition = DopplerAcquisition(app.Nx,app.Ny,app.cache.Fs/1000,app.cache.z,app.cache.wavelength,app.cache.DX,app.cache.DY,app.cache.pix_width,app.cache.pix_height);
            
            local_low_frequency = app.cache.low_frequency;
            local_color_f1 = app.cache.color_f1;
            local_color_f2 = app.cache.color_f2;
            local_color_f3 = app.cache.color_f3;
            
            % allocated video buffers
            num_batches = floor((app.interferogram_stream.num_frames - app.cache.batch_size) / app.cache.batch_stride);
            video_M0 = zeros(app.Nx,app.Ny,1,num_batches-1);
            
            if local_save_additional_videos
                video_M1 = video_M0;
                video_M2 = video_M0;
                video_M1_over_M0 = video_M0;
                video_M2_over_M0 = video_M0;
                video_M_freq_low = video_M0;
                video_M_freq_high = video_M0;
            end
            
            % temporal variation of image average (currently unused)
            temporal_avg = zeros(num_batches, 1);
            
            % parfor waitbar
            % we have to use a DataQueue
            % since there are no synchronization
            % primitives in matlab (mutex)
            D = parallel.pool.DataQueue;
            h = waitbar(0, '');
            afterEach(D, @update_waitbar);
            N = double(num_batches-1);
            p = 1;
                
            %% first pass - compute shifts for video registration
            registration_pass = app.cache.registration_via_phase;
            
            if registration_pass
                current_batch_idx = 1 + floor(app.positioninfileSlider.Value / (app.cache.batch_size + app.cache.batch_stride));
                shifts = compute_temporal_registration(istream, app.cache, j_win, j_step, current_batch_idx, local_gw,...
                                                       local_kernel,[], D, use_multithread);   
                % tilts zernikes used for translating an image
                zernikes = evaluate_zernikes([1 1], [1 -1], app.Nx, app.Ny);
            else
                % declare unused variables to make matlab parfor
                % loop parser happy - stupid matlab
                shifts = zeros(2, istream.num_frames);
                zernikes = zeros(app.Nx, app.Ny, 2);
            end
            
            enable_iterative_optimization = app.IterativeaberrationcompensationCheckBox.Value;
            enable_shack_hartmann = app.ShackHarmannaberrationcompensationCheckBox.Value;
            
            correction_coefs = [];
            correction_zernikes = [];
            previous_zernike_indices = [];
            
            if enable_shack_hartmann
                % aberration computation pass
                %
                % Implementation - Shack Hartmann simulation
                
                % shack hartmann params - TODO get them from GUI
                zernike_ranks = app.cache.shack_hartmann_zernike_ranks;
                num_subapertures = app.cache.num_subapertures;
                subaperture_margin = app.cache.subaperture_margin;
                
                calibration_factor = 20;
                corrmap_margin = 0.4;
                
                power_filter_corrector = 1;
                sigma_filter_corrector = 1;
                
                % select subapertures to exclude, depending on the number
                % of subaperture used. This is chosen experimentally, the
                % value of the number of subapertures is constrained in the
                % GUI so the following switch should always branch to a
                % valid choice of excluded_subapertures
                switch num_subapertures
                    case 4
                        excluded_subapertures = [1; 4; 13; 16];
                    case 5
                        excluded_subapertures = [1; 5; 21; 25];
                    case 6
                        excluded_subapertures = [1; 2; 5; 6; 7; 12; 25; 30; 31; 32; 35; 36];
                    case 7
                        excluded_subapertures = [1; 2; 6; 7; 8; 14; 36; 42; 43; 44; 48; 49];
                    case 8
                        excluded_subapertures = [1; 2; 7; 8; 9; 16; 49; 56; 57; 58; 63; 64];
                    otherwise
                        error('Unreachable code was reached. Check value of num_subapertures');
                end
                
                % perform iterative aberration computation with shack-hartmann
                for r = 2:zernike_ranks
                    send(D, 3); % reset progress bar
                    
                    % r-th zernike line has r+1 zernikes in it
                    cur_num_zernikes = r + 1;
                    
                    
                    % compute indices of the zernikes of current rank
                    current_zernike_indices = (cur_num_zernikes * (cur_num_zernikes - 1) / 2):(cur_num_zernikes * (cur_num_zernikes - 1) / 2 + cur_num_zernikes - 1);
                    
                    % apply previous aberration correction
                    current_correction_coefs = compute_correction_shack_hartmann(istream,app.cache,local_kernel,app.gw,[],D,use_gpu,use_multithread,current_zernike_indices,...
                                                                     shifts,num_subapertures,calibration_factor,subaperture_margin,corrmap_margin,...
                                                                     excluded_subapertures,power_filter_corrector,sigma_filter_corrector,...
                                                                     previous_zernike_indices, correction_coefs);
                    [~, current_correction_zernikes] = zernike_phase(current_zernike_indices, app.Nx, app.Ny);
                                                                 
                    correction_coefs = [correction_coefs; current_correction_coefs];
                    correction_zernikes = cat(3, correction_zernikes, current_correction_zernikes);
                    previous_zernike_indices = [previous_zernike_indices, current_zernike_indices];
                end
                
                % save correction coefs and indices for future export
                aberration_correction.shack_hartmann_zernike_indices = previous_zernike_indices;
                aberration_correction.shack_hartmann_zernike_coefs = correction_coefs;
            end
            
            if enable_iterative_optimization
                % aberration computation pass
                %
                % Implementation - Entropy optimization
                %
                % here we generate a set of zernike coefficients
                % that represent the aberation present on the
                % reconstructed images
                
                % compute coefficients for high order zernikes
                % we make a different optimization pass for each
                % additional zernike rank
                
                % reset correction indices and coefs arrays that ay have
                % been set by the shack hartmann pass
                zernike_indices = [];
                correction_coefs = [];
                
                zernike_ranks = app.optimizationzernikeranksEditField.Value;
                for r = 2:zernike_ranks
                    send(D, 4); % reset progress bar
                    num_zernikes = r + 1; % map to zernfun zernike ordering
                    
                    % construct high order zernike indices
                    current_zernike_indices = (num_zernikes * (num_zernikes - 1) / 2):(num_zernikes * (num_zernikes - 1) / 2 + num_zernikes - 1);
                    
                    % compute correction
                    current_cor = compute_correction(istream,app.cache,app.gw,[],D,use_gpu,use_multithread,current_zernike_indices,app.cache.zernikes_tol,app.cache.mask_num_iter,...
                                                     app.cache.max_constraint,shifts,previous_zernike_indices, correction_coefs);
                    [~, current_correction_zernikes] = zernike_phase(current_zernike_indices, app.Nx, app.Ny);
                    
                    % append coefs to correction coefs array
                    correction_coefs = [correction_coefs; current_cor];
                    correction_zernikes = cat(3, correction_zernikes, current_correction_zernikes);
                    previous_zernike_indices = [previous_zernike_indices current_zernike_indices];
                    zernike_indices = [zernike_indices current_zernike_indices];
                end
                
                % save correction coefs and indices for future export
                aberration_correction.iterative_opt_zernike_indices = zernike_indices;
                aberration_correction.iterative_opt_zernike_coefs = correction_coefs;
            end
            
            if ~enable_shack_hartmann && ~enable_iterative_optimization % no aberration compensation at all
                % define dummy unused variables with good size
                % to make matlab parfor parser happy - stupid matlab
                correction_coefs = zeros(3, istream.num_frames);
                correction_zernikes = zeros(app.Nx, app.Ny, 3);
            end
            
            if enable_shack_hartmann
                [~, shack_zernikes] = zernike_phase(aberration_correction.shack_hartmann_zernike_indices, app.Nx, app.Ny);
            else
                shack_zernikes = [];
            end
            
            if enable_iterative_optimization
                [~, iterative_zernikes] = zernike_phase(aberration_correction.iterative_opt_zernike_indices, app.Nx, app.Ny);
            else
                iterative_zernikes = [];
            end
            
            
            %% Final pass
            % Here we apply all transfromations computed in previous passes
            % and construct the videos
            
            send(D,-2); % display 'video construction' on progress bar
            parfor (batch_idx = 1:num_batches-1, parfor_arg)
                frame_batch = istream.read_frame_batch(j_win, batch_idx * j_step);
                FH = compute_FH_from_frame_batch(frame_batch, local_kernel);
                
                % add tilted phase for FH registration
                if registration_pass
                    FH = register_FH(FH, shifts(:, batch_idx:(batch_idx+1)-1), j_win, 1);
                end
                
                if enable_iterative_optimization || enable_shack_hartmann
                    phase = aberration_correction.compute_total_phase(batch_idx,shack_zernikes,iterative_zernikes);
                    correction = exp(-1i * phase);
                    
                    % apply correction
                    FH = FH .* correction;
                end

                [M0, sqrt_M0, M1, M2, M_freq_low, M_freq_high] = reconstruct_hologram(FH, local_f1, local_f2, acquisition, local_gw, false, local_svd, [],...
                                                                                      local_color_f1, local_color_f2, local_color_f3);

                % store current batch computed frames to their video buffer
                video_M0(:,:,:,batch_idx) = gather(M0);
                
                if local_save_additional_videos
                    video_M1(:,:,:,batch_idx) = gather(M1);
                    video_M2(:,:,:,batch_idx) = gather(M2);
                    video_M1_over_M0(:,:,:,batch_idx) = gather(M1) ./ gather(M0);
                    video_M2_over_M0(:,:,:,batch_idx) = gather(M2) ./ gather(sqrt_M0);
                    video_M_freq_low(:,:,:,batch_idx) = gather(M_freq_low);
                    video_M_freq_high(:,:,:,batch_idx) = gather(M_freq_high);
                end
                temporal_avg(batch_idx) = mean(mean(gather(M0), 1), 2);
                send(D, 0);
            end
            
            function update_waitbar(sig)
                % signal table
                % 0 => increment value
                % 1 => reset for stage 1 (registration)
                % 2 => reset for stage 2 (video_M0 computation)
                switch sig
                    case 0
                        waitbar(p/N, h);
                        p = p + 1;
                    case -1
                        waitbar(0,h,'Video registration...');
                        p = 1;
                    case -2
                        waitbar(0,h,'Video construction...');
                        p = 1;
                    case 1
                        waitbar(0,h,'Optimizing defocus...');
                        p = 1;
                    case 2
                        waitbar(0,h,'Optimizing astigmatism...');
                        p = 1;
                    case 3
                        waitbar(0,h,'Shack-Hartmann simulation...')
                        p = 1;
                    otherwise
                        waitbar(0,h,'Optimizing zernikes...');
                        p = 1;
                end
            end
            
            % generate color video
            if local_save_additional_videos
                if local_low_frequency
                    video_color = construct_colored_video(video_M_freq_high, video_M_freq_low);
                else
                    video_color = construct_colored_video(video_M_freq_low, video_M_freq_high);
                end
            end
        
            if app.cache.registration
                % post reconstruction image registration.
                % This registration is performed on the final video,
                % it does not use tilts zernikes to perform the
                % registration.
                current_batch_idx = 1 + floor(app.positioninfileSlider.Value / (app.cache.batch_size + app.cache.batch_stride));
                
%                 [video_M0, shifts] = register_video(mat2gray(video_M0), current_batch_idx);

                % construct reference image
                reg_frame_batch = app.interferogram_stream.read_frame_batch(app.refbatchsizeEditField.Value, floor(app.positioninfileSlider.Value));
                reg_FH = fftshift(fft2(reg_frame_batch)) .* app.kernel;
                acquisition = DopplerAcquisition(app.Nx,app.Ny,app.Fs/1000,app.cache.z,app.cache.wavelength,app.cache.DX,app.cache.DY,app.pix_width,app.pix_height);
                reg_hologram = reconstruct_hologram(reg_FH, app.cache.f1, app.cache.f2, acquisition, app.gw, use_gpu, app.SVDCheckBox.Value);
                [video_M0, shifts] = register_video_from_reference(mat2gray(video_M0), reg_hologram);
                
                if local_save_additional_videos
                    video_M1 = register_video_from_shifts(mat2gray(video_M1), shifts);
                    video_M2 = register_video_from_shifts(mat2gray(video_M2), shifts);
                    video_M1_over_M0 = register_video_from_shifts(mat2gray(video_M1_over_M0), shifts);
                    video_M2_over_M0 = register_video_from_shifts(mat2gray(video_M2_over_M0), shifts);
                    video_color = register_video_from_shifts(mat2gray(video_color), shifts);
                    
                    video_M_freq_high = register_video_from_shifts(video_M_freq_high, shifts);
                    video_M_freq_low = register_video_from_shifts(video_M_freq_low, shifts);
                end
                
                % save shifts for export
                image_registration.translation_x = shifts(1,:);
                image_registration.translation_y = shifts(2,:);
            end
            
            % export shifts to AberrationCorrection struct as zernikes
            if registration_pass || app.cache.registration
                magic_number = 710;
                aberration_correction.rephasing_zernike_indices = [1 2];
                aberration_correction.rephasing_zernike_coefs = [shifts(1,:)/local_Nx; shifts(2,:)/local_Ny] * magic_number * 2;
            end
            
            % guassian temporal filter
            if app.cache.temporal_filter_flag
                windowSize = app.cache.temporal_filter; 
                
                % sigma_x and sigma_y should be 0
                % but matlab won't accept that input
                sigma = [0.0001 0.0001 windowSize];
                
                % filter every video
                video_M0 = imgaussfilt3(reshape(video_M0, app.Nx, app.Nx, size(video_M0,4)), sigma);
                video_M0 = reshape(video_M0, app.Nx, app.Ny, 1, size(video_M0,3));
                
                if local_save_additional_videos
                    video_M1 = imgaussfilt3(reshape(video_M1, app.Nx, app.Nx, size(video_M1,4)), sigma);
                    video_M1 = reshape(video_M1, app.Nx, app.Ny, 1, size(video_M1,3));
                    video_M2 = imgaussfilt3(reshape(video_M2, app.Nx, app.Nx, size(video_M2,4)), sigma);
                    video_M2 = reshape(video_M2, app.Nx, app.Ny, 1, size(video_M2,3));
                    video_M1_over_M0 = imgaussfilt3(reshape(video_M1_over_M0, app.Nx, app.Nx, size(video_M1_over_M0,4)), sigma);
                    video_M1_over_M0 = reshape(video_M1_over_M0, app.Nx, app.Ny, 1, size(video_M1_over_M0,3));
                    video_M2_over_M0 = imgaussfilt3(reshape(video_M2_over_M0, app.Nx, app.Nx, size(video_M2_over_M0,4)), sigma);
                    video_M2_over_M0 = reshape(video_M2_over_M0, app.Nx, app.Ny, 1, size(video_M2_over_M0,3));
                    
                    video_color(:,:,1,:) = imgaussfilt3(squeeze(video_color(:,:,1,:)), sigma);
                    video_color(:,:,2,:) = imgaussfilt3(squeeze(video_color(:,:,2,:)), sigma);
                    video_color(:,:,3,:) = imgaussfilt3(squeeze(video_color(:,:,3,:)), sigma);
                end
            end
            
            % enhance video_M0 contrast
            tol_pdi_low = 0.0005; % hardcoded value that previously was a slider - this value seems fine in all cases
            tol_pdi = [tol_pdi_low 1-tol_pdi_low];
            video_M0 = enhance_video_constrast(video_M0, tol_pdi);
            if local_save_additional_videos
                video_M1 = enhance_video_constrast(video_M1, tol_pdi);
                video_M2 = enhance_video_constrast(video_M2, tol_pdi);
                video_M2_over_M0 = enhance_video_constrast(video_M2_over_M0, tol_pdi);
            end
            
            % fix intensity flashes
            video_M0 = video_M0 - mean(mean(video_M0,1),2);
            if local_save_additional_videos
                video_M1 = video_M1 - mean(mean(video_M1,1),2);
                video_M2 = video_M2 - mean(mean(video_M2,1),2);
                video_M1_over_M0 = video_M1_over_M0 - mean(mean(video_M1_over_M0,1),2);
                video_M2_over_M0 = video_M2_over_M0 - mean(mean(video_M2_over_M0,1),2);
            end
            
            % flip videos
            video_M0 = flip(video_M0);
            if local_save_additional_videos
                video_M1 = flip(video_M1);
                video_M2 = flip(video_M2);
                video_M1_over_M0 = flip(video_M1_over_M0);
                video_M2_over_M0 = flip(video_M2_over_M0);
                video_color = flip(video_color);
            end
            
            file_M0 = create_output_filename(app.filepath, app.filename, 'M0', 'avi');
            if local_save_additional_videos
                file_M1 = create_output_filename(app.filepath, app.filename, 'DopplerAVG', 'avi');
                file_M2 = create_output_filename(app.filepath, app.filename, 'DopplerRMS', 'avi');
                file_M1_over_M0 = create_output_filename(app.filepath, app.filename, 'NormalizedDopplerAVG', 'avi');
                file_M2_over_M0 = create_output_filename(app.filepath, app.filename, 'NormalizedDopplerRMS', 'avi');
                file_color = create_output_filename(app.filepath, app.filename, 'Color', 'avi');
            end
            
            % create output directory
            output_dirname = strrep(file_M0, '_M0.avi', '');
            output_path = fullfile(app.filepath, output_dirname);
            mkdir(output_path);

            % export patient informations to text file_M0
            txt_filename = sprintf('%s.txt', output_dirname);
            save_string_cells_to_file(fullfile(output_path, txt_filename), app.cache.notes);
            
            % compute images to plot
            moment_mean = mean(video_M0,4);
            moment_std = std(video_M0,0,4);
            moment_std_avg = moment_std./moment_mean;
            
            % generate average images for each video
            M0_avg = mean(video_M0, 4);
            if local_save_additional_videos
                M1_avg = mean(video_M1, 4);
                M2_avg = mean(video_M2, 4);
                M1_over_M0_avg = mean(video_M1_over_M0, 4);
                M2_over_M0_avg = mean(video_M2_over_M0, 4);
            end
            
            power_doppler_avg_img = moment_mean;
            
            % save coefficient of variation
            % TODO: put this in a separate function
            bottom = [0 0 0.5];
            botmiddle = [0 0.5 1];
            middle = [1 1 1];
            topmiddle = [1 0 0];
            top = [0.5 0 0];
            vec = [bottom; botmiddle; middle; topmiddle; top];
            wave = interp1(1:length(vec), vec, linspace(1, length(vec), 256), 'linear');
            IMG = mat2gray(moment_std_avg);
            Tol = [0.03 0.997];
            lowhigh = stretchlim(IMG, Tol);
            img_avg_stretch = imadjust(IMG, lowhigh);
            pulse_wave_modulation_depth_img = ind2rgb(im2uint8(img_avg_stretch), wave);
            
            % color img
            if local_save_additional_videos
                if local_low_frequency
                    color_img = construct_colored_image(video_M_freq_high, video_M_freq_low, true);
                else
                    color_img = construct_colored_image(video_M_freq_low, video_M_freq_high, false);
                end
                
                % flip color img (other images are already flipped because they are constructed from videos
                % that were previously flipped)
                color_img = flip(color_img);
            end

            % export some workspace variables to .mat file
            mat_filename = sprintf('%s.mat', output_dirname);
            cache = app.cache;
            save(fullfile(output_path, mat_filename), 'cache');
            
            save(fullfile(output_path, mat_filename), 'aberration_correction', '-append');
            save(fullfile(output_path, mat_filename), 'image_registration', '-append');
            save(fullfile(output_path, mat_filename), 'pulse_wave_modulation_depth_img', '-append');
            save(fullfile(output_path, mat_filename), 'power_doppler_avg_img', '-append');

            % sign to handle contrast inversion for low frequency
            % acquisitions
            if local_low_frequency
                sign = -1;
            else
                sign = 1;
            end
            
            % create folders for output videos
            mkdir(fullfile(output_path, "avi"));
            mkdir(fullfile(output_path, "mp4"));
            mkdir(fullfile(output_path, "raw"));
            mkdir(fullfile(output_path, "png"));
            
            w_M0 = VideoWriter(sprintf('%s\\avi\\%s', output_path, file_M0));
            if local_save_additional_videos
                w_M1 = VideoWriter(sprintf('%s\\avi\\%s', output_path, file_M1));
                w_M2 = VideoWriter(sprintf('%s\\avi\\%s', output_path, file_M2));
                w_M1_over_M0 = VideoWriter(sprintf('%s\\avi\\%s', output_path, file_M1_over_M0));
                w_M2_over_M0 = VideoWriter(sprintf('%s\\avi\\%s', output_path, file_M2_over_M0));
                w_color = VideoWriter(sprintf('%s\\avi\\%s', output_path, file_color));
            end
            
            open(w_M0);
            if local_save_additional_videos
                open(w_M1);
                open(w_M2);
                open(w_M1_over_M0);
                open(w_M2_over_M0);
                open(w_color);
            end
            
            writeVideo(w_M0, mat2gray(sign*video_M0));
            if local_save_additional_videos
                writeVideo(w_M1, mat2gray(video_M1));
                writeVideo(w_M2, mat2gray(video_M2));
                writeVideo(w_M1_over_M0, mat2gray(video_M1_over_M0));
                writeVideo(w_M2_over_M0, mat2gray(video_M2_over_M0));
                writeVideo(w_color, mat2gray(video_color));
            end
            
            close(w_M0);
            if local_save_additional_videos
                close(w_M1);
                close(w_M2);
                close(w_M1_over_M0);
                close(w_M2_over_M0);
                close(w_color);
            end
            
            file_M0 = strrep(file_M0, '.avi', '.mp4');
            if local_save_additional_videos
                file_M1 = strrep(file_M1, '.avi', '.mp4');
                file_M2 = strrep(file_M2, '.avi', '.mp4');
                file_M1_over_M0 = strrep(file_M1_over_M0, '.avi', '.mp4');
                file_M2_over_M0 = strrep(file_M2_over_M0, '.avi', '.mp4');
                file_color = strrep(file_color, '.avi', '.mp4');
            end
            
            w_M0 = VideoWriter(sprintf('%s\\mp4\\%s', output_path, file_M0), 'MPEG-4');
            if local_save_additional_videos
                w_M1 = VideoWriter(sprintf('%s\\mp4\\%s', output_path, file_M1), 'MPEG-4');
                w_M2 = VideoWriter(sprintf('%s\\mp4\\%s', output_path, file_M2), 'MPEG-4');
                w_M1_over_M0 = VideoWriter(sprintf('%s\\mp4\\%s', output_path, file_M1_over_M0), 'MPEG-4');
                w_M2_over_M0 = VideoWriter(sprintf('%s\\mp4\\%s', output_path, file_M2_over_M0), 'MPEG-4');
                w_color = VideoWriter(sprintf('%s\\mp4\\%s', output_path, file_color), 'MPEG-4');
            end
            
            
            open(w_M0);
            if local_save_additional_videos
                open(w_M1);
                open(w_M2);
                open(w_M1_over_M0);
                open(w_M2_over_M0);
                open(w_color);
            end
            
            writeVideo(w_M0, mat2gray(sign*video_M0));
            if local_save_additional_videos
                writeVideo(w_M1, mat2gray(video_M1));
                writeVideo(w_M2, mat2gray(video_M2));
                writeVideo(w_M1_over_M0, mat2gray(video_M1_over_M0));
                writeVideo(w_M2_over_M0, mat2gray(video_M2_over_M0));
                writeVideo(w_color, mat2gray(video_color));
            end
            
            close(w_M0);
            if local_save_additional_videos
                close(w_M1);
                close(w_M2);
                close(w_M1_over_M0);
                close(w_M2_over_M0);
                close(w_color);
            end
            
            % generate additional videos
            %  - aberration coefficients variations over time
            close(h); % <- matlab cringe
            
%             [video_shack, video_iterative] = correction_coefs_video(aberration_correction);
%             video_phase = correction_phase_video(aberration_correction, local_Nx, local_Ny);
%             
%             
%             % write additional videos
%             if ~isempty(video_shack)
%                 file_video_shack = create_output_filename(app.filepath, app.filename, 'shack_hartmann_coefs', 'avi');
%                 w_video_shack = VideoWriter(sprintf('%s\\avi\\%s', output_path, file_video_shack));
%                 open(w_video_shack);
%                 writeVideo(w_video_shack, mat2gray(video_shack));
%                 close(w_video_shack);
%                 file_video_shack = strrep(file_video_shack, '.avi', '.mp4');
%                 w_video_shack = VideoWriter(sprintf('%s\\mp4\\%s', output_path, file_video_shack), 'MPEG-4');
%                 open(w_video_shack);
%                 writeVideo(w_video_shack, mat2gray(video_shack));
%                 close(w_video_shack);
%             end
%             if ~isempty(video_iterative)
%                 file_video_iterative = create_output_filename(app.filepath, app.filename, 'iterative_opt_coefs', 'avi');
%                 w_video_iterative = VideoWriter(sprintf('%s\\avi\\%s', output_path, file_video_iterative));
%                 open(w_video_iterative);
%                 writeVideo(w_video_iterative, mat2gray(video_iterative));
%                 close(w_video_iterative);
%                 file_video_iterative = strrep(file_video_iterative, '.avi', '.mp4');
%                 w_video_iterative = VideoWriter(sprintf('%s\\mp4\\%s', output_path, file_video_iterative), 'MPEG-4');
%                 open(w_video_iterative);
%                 writeVideo(w_video_iterative, mat2gray(video_iterative));
%                 close(w_video_iterative);
%             end
%             if ~isempty(video_shack) || ~isempty(video_iterative)
%                 file_video_phase = create_output_filename(app.filepath, app.filename, 'phase_correction', 'avi');
%                 w_video_phase = VideoWriter(sprintf('%s\\avi\\%s', output_path, file_video_phase));
%                 open(w_video_phase);
%                 writeVideo(w_video_phase, mat2gray(video_phase));
%                 close(w_video_phase);
%                 file_video_phase = strrep(file_video_phase, '.avi', '.mp4');
%                 w_video_phase = VideoWriter(sprintf('%s\\mp4\\%s', output_path, file_video_phase), 'MPEG-4');
%                 open(w_video_phase);
%                 writeVideo(w_video_phase, mat2gray(video_phase));
%                 close(w_video_phase);
%             end
            
            file_M0 = strrep(file_M0, '.mp4', '.png');
            if local_save_additional_videos
                file_M1 = strrep(file_M1, '.mp4', '.png');
                file_M2 = strrep(file_M2, '.mp4', '.png');
                file_M1_over_M0 = strrep(file_M1_over_M0, '.mp4', '.png');
                file_M2_over_M0 = strrep(file_M2_over_M0, '.mp4', '.png'); 
                file_color = strrep(file_color, '.mp4', '.png');
            end
            
            imwrite(mat2gray(sign*M0_avg), sprintf('%s\\png\\%s', output_path, file_M0));
            if local_save_additional_videos
                imwrite(mat2gray(M1_avg), sprintf('%s\\png\\%s', output_path, file_M1));
                imwrite(mat2gray(M2_avg), sprintf('%s\\png\\%s', output_path, file_M2));
                imwrite(mat2gray(M1_over_M0_avg), sprintf('%s\\png\\%s', output_path, file_M1_over_M0));
                imwrite(mat2gray(M2_over_M0_avg), sprintf('%s\\png\\%s', output_path, file_M2_over_M0));
                imwrite(color_img, sprintf('%s\\png\\%s', output_path, file_color));
            end
            
            imwrite(pulse_wave_modulation_depth_img, fullfile(app.filepath, output_dirname, 'png', 'PulseWaveModulationDepth.png'))
            
            if app.saverawvideosCheckBox.Value
                file_M0 = strrep(file_M0, '.png', '.raw');
                if local_save_additional_videos
                    file_M1 = strrep(file_M1, '.png', '.raw');
                    file_M2 = strrep(file_M2, '.png', '.raw');
                    file_M1_over_M0 = strrep(file_M1_over_M0, '.png', '.raw');
                    file_M2_over_M0 = strrep(file_M2_over_M0, '.png', '.raw');
                    file_color = strrep(file_color, '.png', '.raw');
                end
                       
                export_raw_video(sprintf('%s\\raw\\%s', output_path, file_M0), video_M0);
                if local_save_additional_videos
                    export_raw_video(sprintf('%s\\raw\\%s', output_path, file_M1), video_M1);
                    export_raw_video(sprintf('%s\\raw\\%s', output_path, file_M2), video_M2);
                    export_raw_video(sprintf('%s\\raw\\%s', output_path, file_M1_over_M0), video_M1_over_M0);
                    export_raw_video(sprintf('%s\\raw\\%s', output_path, file_M2_over_M0), video_M2_over_M0);
                    export_raw_video(sprintf('%s\\raw\\%s', output_path, file_color), video_color);
                end
            end
            
            % display elapsed time
            toc;
        end

        % Button pushed function: AutofocusButton
        function AutofocusButtonPushed(app, event)
            if ~app.file_loaded
                return
            end
            
            z0 = app.zEditField.Value;
            acquisition = DopplerAcquisition(app.Nx,app.Ny,app.Fs/1000,app.zEditField.Value,app.wavelengthEditField.Value,app.DX,app.DY,app.pix_width,app.pix_height);
            mask = construct_mask(0, app.gw, app.Nx, app.Ny);
            
            % free GPU memory
            app.FH = 0;
            
            z = autofocus(app.frame_batch, app.f1EditField.Value, app.f2EditField.Value, acquisition, z0, mask, app.gw, true);
            app.zEditField.Value = z;
            app.compute_kernel(false);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
            
            app.compute_color_hologram(true);
            app.show_color_hologram();
        end

        % Close request function: HoloDopplerUIFigure
        function HoloDopplerUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: positioninfileSlider
        function positioninfileSliderValueChanged(app, event)
            value = app.positioninfileSlider.Value;
            app.EditField.Value = value;
            
            if ~app.file_loaded
                return
            end
            
            app.frame_batch = app.interferogram_stream.read_frame_batch(app.batchsizeEditField.Value, floor(app.positioninfileSlider.Value));
            app.compute_kernel(false);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
            
            app.compute_color_hologram(true);
            app.show_color_hologram();
        end

        % Value changed function: DxEditField
        function DxEditFieldValueChanged(app, event)
            value = app.DxEditField.Value;
            
            x_shift = floor(app.Nx * value);
            app.DX = x_shift;
            
            if ~app.file_loaded
                return
            end
            
            app.compute_hologram(true);
            app.show_hologram();
            
            app.compute_color_hologram(true);
            app.show_color_hologram();
        end

        % Value changed function: DyEditField
        function DyEditFieldValueChanged(app, event)
            value = app.DyEditField.Value;
            
            y_shift = floor(app.Ny * value);
            app.DY = y_shift;
            if ~app.file_loaded
                return
            end
            
            app.compute_hologram(true);
            app.show_hologram();
            
            app.compute_color_hologram(true);
            app.show_color_hologram();
        end

        % Value changed function: SVDCheckBox
        function SVDCheckBoxValueChanged(app, event)
           if ~app.file_loaded
                return
            end
            
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            if ~app.file_loaded
                return
            end
            
            app.compute_FH(false);
            
            zernike_ranks = app.shackhartmannzernikeranksEditField.Value;
            num_subapertures = app.numsubapEditField.Value;
            subaperture_margin = app.subaperturemarginEditField.Value;
            
            calibration_factor = 20;
            corrmap_margin = 0.4;
            
            power_filter_corrector = 1;
            sigma_filter_corrector = 1;
            
            % select subapertures to exclude, depending on the number
            % of subaperture used. This is chosen experimentally, the
            % value of the number of subapertures is constrained in the
            % GUI so the following switch should always branch to a
            % valid choice of excluded_subapertures
            switch num_subapertures
                case 4
                    excluded_subapertures = [1; 4; 13; 16];
                case 5
                    excluded_subapertures = [1; 5; 21; 25];
                case 6
                    excluded_subapertures = [1; 2; 5; 6; 7; 12; 25; 30; 31; 32; 35; 36];
                case 7
                    excluded_subapertures = [1; 2; 6; 7; 8; 14; 36; 42; 43; 44; 48; 49];
                case 8
                    excluded_subapertures = [1; 2; 7; 8; 9; 16; 49; 56; 57; 58; 63; 64];
                otherwise
                    error('Unreachable code was reached. Check value of num_subapertures');
            end
            
            shack_hartmann = ShackHartmann(num_subapertures,[3 4 5],20,subaperture_margin,corrmap_margin,power_filter_corrector,sigma_filter_corrector);
            acquisition = DopplerAcquisition(app.Nx,app.Ny,app.Fs/1000,app.zEditField.Value,app.wavelengthEditField.Value,app.DX,app.DY,app.pix_width,app.pix_height);
            
            [shifts, stiched_moments_subap, stiched_corr_subapp] = shack_hartmann.compute_images_shifts(app.FH, app.f1EditField.Value, app.f2EditField.Value, app.gw, false, app.SVDCheckBox.Value, acquisition);
            
            imshow(mat2gray(stiched_moments_subap), 'Parent', app.UIAxes_3,...
                   'XData', [1 app.UIAxes_3.Position(3)], ...
                   'YData', [1 app.UIAxes_3.Position(4)]);
        end

        % Value changed function: compositef1EditField
        function compositef1EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            color_f1 = app.compositef1EditField.Value;
            color_f2 = app.compositef2EditField.Value;
            color_f3 = app.compositef3EditField.Value;
            
            if ~app.lowfrequencyCheckBox.Value
                app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f1, color_f2);
                app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f2, color_f3);
            else
                app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f2, color_f3);
                app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f1, color_f2);
            end
            
            app.compute_color_hologram(true);
            app.show_color_hologram();
        end

        % Value changed function: compositef2EditField
        function compositef2EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            color_f1 = app.compositef1EditField.Value;
            color_f2 = app.compositef2EditField.Value;
            color_f3 = app.compositef3EditField.Value;
            
            if ~app.lowfrequencyCheckBox.Value
                app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f1, color_f2);
                app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f2, color_f3);
            else
                app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f2, color_f3);
                app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f1, color_f2);
            end
            
            app.compute_color_hologram(true);
            app.show_color_hologram();
        end

        % Value changed function: compositef3EditField
        function compositef3EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            color_f1 = app.compositef1EditField.Value;
            color_f2 = app.compositef2EditField.Value;
            color_f3 = app.compositef3EditField.Value;
            
            if ~app.lowfrequencyCheckBox.Value
                app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f1, color_f2);
                app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f2, color_f3);
            else
                app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f2, color_f3);
                app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f1, color_f2);
            end
            
            app.compute_color_hologram(true);
            app.show_color_hologram();
        end

        % Value changed function: lowfrequencyCheckBox
        function lowfrequencyCheckBoxValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            color_f1 = app.compositef1EditField.Value;
            color_f2 = app.compositef2EditField.Value;
            color_f3 = app.compositef3EditField.Value;
            
            if ~app.lowfrequencyCheckBox.Value
                app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f1, color_f2);
                app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f2, color_f3);
            else
                app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f2, color_f3);
                app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f1, color_f2);
            end
            
            app.compute_hologram(true);
            app.show_hologram();
            
            app.compute_color_hologram(true);
            app.show_color_hologram();
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.positioninfileSlider.Value = value;
            
            if ~app.file_loaded
                return
            end
            
            app.frame_batch = app.interferogram_stream.read_frame_batch(app.batchsizeEditField.Value, floor(app.positioninfileSlider.Value));
            app.compute_kernel(false);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
            
            app.compute_color_hologram(true);
            app.show_color_hologram();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HoloDopplerUIFigure = uifigure('Visible', 'off');
            app.HoloDopplerUIFigure.Name = 'HoloDoppler';
            app.HoloDopplerUIFigure.CloseRequestFcn = createCallbackFcn(app, @HoloDopplerUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.HoloDopplerUIFigure);
            app.MainviewTab = uitab(app.TabGroup);
            app.MainviewTab.Title = 'Main view';
            app.FileselectionPanel = uipanel(app.MainviewTab);
            app.FileselectionPanel.Tooltip = {''};
            app.FileselectionPanel.Title = 'File selection';
            app.LoadfileButton = uibutton(app.FileselectionPanel, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file';
            app.SavevideosButton = uibutton(app.FileselectionPanel, 'push');
            app.SavevideosButton.ButtonPushedFcn = createCallbackFcn(app, @SavevideosButtonPushed, true);
            app.SavevideosButton.Text = 'Save videos';
            app.batchsizeEditFieldLabel = uilabel(app.FileselectionPanel);
            app.batchsizeEditFieldLabel.Text = 'batch size';
            app.batchsizeEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.batchsizeEditField.Limits = [1 Inf];
            app.batchsizeEditField.ValueChangedFcn = createCallbackFcn(app, @batchsizeEditFieldValueChanged, true);
            app.batchsizeEditField.Tooltip = {'Number of interferograms used to generate a single image. Increase value for better video quality.'};
            app.batchsizeEditField.Value = 512;
            app.batchstrideEditFieldLabel = uilabel(app.FileselectionPanel);
            app.batchstrideEditFieldLabel.Text = 'batch stride';
            app.batchstrideEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.batchstrideEditField.Limits = [1 Inf];
            app.batchstrideEditField.Tooltip = {'Number of interferograms to skip between two images. Increase for a shorter video.'};
            app.batchstrideEditField.Value = 1024;
            app.zEditFieldLabel = uilabel(app.FileselectionPanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.zEditField.Limits = [0 Inf];
            app.zEditField.ValueChangedFcn = createCallbackFcn(app, @zEditFieldValueChanged, true);
            app.zEditField.Tooltip = {'Reconstruction distance in mm. Can be computed automatically with the ''Autofocus'' button.'};
            app.zEditField.Value = 0.22;
            app.wavelengthEditFieldLabel = uilabel(app.FileselectionPanel);
            app.wavelengthEditFieldLabel.Text = 'wavelength';
            app.wavelengthEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.wavelengthEditField.Limits = [0 Inf];
            app.wavelengthEditField.ValueChangedFcn = createCallbackFcn(app, @wavelengthEditFieldValueChanged, true);
            app.wavelengthEditField.Tooltip = {'Laser wavelength in nanometers'};
            app.wavelengthEditField.Value = 7.85e-07;
            app.imageregistrationCheckBox = uicheckbox(app.FileselectionPanel);
            app.imageregistrationCheckBox.Text = ' image registration';
            app.imageregistrationCheckBox.Value = true;
            app.temporalfilterCheckBox = uicheckbox(app.FileselectionPanel);
            app.temporalfilterCheckBox.Text = 'temporal filter';
            app.temporalfilterCheckBox.Value = true;
            app.ParallelismDropDown = uidropdown(app.FileselectionPanel);
            app.ParallelismDropDown.Items = {'CPU multithread', 'GPU parallelization', 'CPU singlethread', 'CPU/GPU rendering/optim', ''};
            app.ParallelismDropDown.Value = 'CPU multithread';
            app.positioninfileSliderLabel = uilabel(app.FileselectionPanel);
            app.positioninfileSliderLabel.Text = {'position in file'; ''};
            app.positioninfileSlider = uislider(app.FileselectionPanel);
            app.positioninfileSlider.Limits = [0 1];
            app.positioninfileSlider.MajorTicks = [];
            app.positioninfileSlider.ValueChangedFcn = createCallbackFcn(app, @positioninfileSliderValueChanged, true);
            app.positioninfileSlider.MinorTicks = [];
            app.positioninfileSlider.Tooltip = {'Change value to display a different video timestamp in the gui.'};
            app.temporalfilterEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.temporalfilterEditField.Limits = [1 Inf];
            app.temporalfilterEditField.Tooltip = {'Laser wavelength in nanometers'};
            app.temporalfilterEditField.Value = 2;
            app.saverawvideosCheckBox = uicheckbox(app.FileselectionPanel);
            app.saverawvideosCheckBox.Text = 'save raw videos';
            app.AutofocusButton = uibutton(app.FileselectionPanel, 'push');
            app.AutofocusButton.ButtonPushedFcn = createCallbackFcn(app, @AutofocusButtonPushed, true);
            app.AutofocusButton.Text = 'Autofocus';
            app.SVDCheckBox = uicheckbox(app.FileselectionPanel);
            app.SVDCheckBox.ValueChangedFcn = createCallbackFcn(app, @SVDCheckBoxValueChanged, true);
            app.SVDCheckBox.Tooltip = {'Enable SVD hologram filtering in hologram construction'};
            app.SVDCheckBox.Text = 'SVD';
            app.SVDCheckBox.Value = true;
            app.phaseregistrationCheckBox = uicheckbox(app.FileselectionPanel);
            app.phaseregistrationCheckBox.Visible = 'off';
            app.phaseregistrationCheckBox.Tooltip = {'Activates video registration. Check this if the video is too shaky.'};
            app.phaseregistrationCheckBox.Text = 'phase registration';
            app.lowfrequencyCheckBox = uicheckbox(app.FileselectionPanel);
            app.lowfrequencyCheckBox.ValueChangedFcn = createCallbackFcn(app, @lowfrequencyCheckBoxValueChanged, true);
            app.lowfrequencyCheckBox.Text = 'low frequency';
            app.DxEditFieldLabel = uilabel(app.FileselectionPanel);
            app.DxEditFieldLabel.Text = 'Dx';
            app.DxEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.DxEditField.Limits = [-1 1];
            app.DxEditField.ValueChangedFcn = createCallbackFcn(app, @DxEditFieldValueChanged, true);
            app.DxEditField.Tooltip = {''};
            app.DyEditFieldLabel = uilabel(app.FileselectionPanel);
            app.DyEditFieldLabel.Text = 'Dy';
            app.DyEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.DyEditField.Limits = [-1 1];
            app.DyEditField.ValueChangedFcn = createCallbackFcn(app, @DyEditFieldValueChanged, true);
            app.DyEditField.Tooltip = {''};
            app.EditFieldLabel = uilabel(app.FileselectionPanel);
            app.EditFieldLabel.Text = '';
            app.EditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.RoundFractionalValues = 'on';
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Tooltip = {''};
            app.refbatchsizeEditFieldLabel = uilabel(app.FileselectionPanel);
            app.refbatchsizeEditFieldLabel.Text = 'ref batch size';
            app.refbatchsizeEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.refbatchsizeEditField.Limits = [1 Inf];
            app.refbatchsizeEditField.Tooltip = {'Number of interferograms used to generate a single image. Increase value for better video quality.'};
            app.refbatchsizeEditField.Value = 512;
            app.saveadditionalvideosCheckBox = uicheckbox(app.FileselectionPanel);
            app.saveadditionalvideosCheckBox.Text = 'save additional videos';
            app.saveadditionalvideosCheckBox.Value = true;
            app.CurrentFilePanel = uipanel(app.MainviewTab);
            app.CurrentFilePanel.Tooltip = {''};
            app.CurrentFilePanel.Title = 'Current file';
            app.GridLayout = uigridlayout(app.CurrentFilePanel);
            app.AberationcompensationPanel = uipanel(app.GridLayout);
            app.AberationcompensationPanel.Title = 'Aberation compensation';
            app.Label_2 = uilabel(app.AberationcompensationPanel);
            app.Label_2.Text = {''; ''};
            app.ShackHarmannaberrationcompensationCheckBox = uicheckbox(app.AberationcompensationPanel);
            app.ShackHarmannaberrationcompensationCheckBox.Tooltip = {'Perform aberration compensation with Shack Hartmann simulation'};
            app.ShackHarmannaberrationcompensationCheckBox.Text = {'Shack Harmann'; 'aberration compensation'};
            app.zernikeranksEditFieldLabel = uilabel(app.AberationcompensationPanel);
            app.zernikeranksEditFieldLabel.Text = 'zernike ranks';
            app.shackhartmannzernikeranksEditField = uieditfield(app.AberationcompensationPanel, 'numeric');
            app.shackhartmannzernikeranksEditField.Limits = [2 5];
            app.shackhartmannzernikeranksEditField.RoundFractionalValues = 'on';
            app.shackhartmannzernikeranksEditField.Tooltip = {'Number of zernike ranks to use for aberration correction'};
            app.shackhartmannzernikeranksEditField.Value = 2;
            app.numsubapEditFieldLabel = uilabel(app.AberationcompensationPanel);
            app.numsubapEditFieldLabel.Text = 'num subap';
            app.numsubapEditField = uieditfield(app.AberationcompensationPanel, 'numeric');
            app.numsubapEditField.Limits = [4 8];
            app.numsubapEditField.RoundFractionalValues = 'on';
            app.numsubapEditField.Tooltip = {'Number of subapertures used for Shack-Hartmann simulation'};
            app.numsubapEditField.Value = 5;
            app.subaperturemarginEditFieldLabel = uilabel(app.AberationcompensationPanel);
            app.subaperturemarginEditFieldLabel.Text = 'subaperture margin';
            app.subaperturemarginEditField = uieditfield(app.AberationcompensationPanel, 'numeric');
            app.subaperturemarginEditField.Limits = [0 Inf];
            app.subaperturemarginEditField.Tooltip = {'Number of subapertures used for Shack-Hartmann simulation'};
            app.subaperturemarginEditField.Value = 0.3;
            app.PreviewButton = uibutton(app.AberationcompensationPanel, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.IterativeaberrationcompensationCheckBox = uicheckbox(app.AberationcompensationPanel);
            app.IterativeaberrationcompensationCheckBox.Tooltip = {'Activate aberration compensation via image entropy optimization. This might significantly increase the computation time.'};
            app.IterativeaberrationcompensationCheckBox.Text = 'Iterative aberration compensation';
            app.zernikeranksEditField_2Label = uilabel(app.AberationcompensationPanel);
            app.zernikeranksEditField_2Label.Text = 'zernike ranks';
            app.optimizationzernikeranksEditField = uieditfield(app.AberationcompensationPanel, 'numeric');
            app.optimizationzernikeranksEditField.Limits = [2 Inf];
            app.optimizationzernikeranksEditField.Tooltip = {'Number of zernike ranks that constitue the aberration projection polynomial space'};
            app.optimizationzernikeranksEditField.Value = 2;
            app.tolLabel = uilabel(app.AberationcompensationPanel);
            app.tolLabel.Text = 'tol';
            app.zernikestolEditField = uieditfield(app.AberationcompensationPanel, 'numeric');
            app.zernikestolEditField.Limits = [0 Inf];
            app.zernikestolEditField.Tooltip = {''};
            app.zernikestolEditField.Value = 0.05;
            app.maxconstraintLabel = uilabel(app.AberationcompensationPanel);
            app.maxconstraintLabel.Text = 'max constraint';
            app.maxconstraintEditField = uieditfield(app.AberationcompensationPanel, 'numeric');
            app.maxconstraintEditField.Limits = [1 Inf];
            app.maxconstraintEditField.RoundFractionalValues = 'on';
            app.maxconstraintEditField.Tooltip = {''};
            app.maxconstraintEditField.Value = 10;
            app.masknumiterLabel = uilabel(app.AberationcompensationPanel);
            app.masknumiterLabel.Text = 'mask num iter';
            app.masknumiterEditField = uieditfield(app.AberationcompensationPanel, 'numeric');
            app.masknumiterEditField.Limits = [1 Inf];
            app.masknumiterEditField.RoundFractionalValues = 'on';
            app.masknumiterEditField.Tooltip = {''};
            app.masknumiterEditField.Value = 1;
            app.UIAxes_1 = uiaxes(app.GridLayout);
            title(app.UIAxes_1, '')
            xlabel(app.UIAxes_1, '')
            ylabel(app.UIAxes_1, '')
            app.UIAxes_1.CLim = [0 1];
            app.UIAxes_1.XTick = [];
            app.UIAxes_1.YTick = [];
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.CLim = [0 1];
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.GridLayout);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_3.CLim = [0 1];
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.PowerdopplerPanel = uipanel(app.GridLayout3);
            app.PowerdopplerPanel.Title = 'Power doppler';
            app.f1Label = uilabel(app.PowerdopplerPanel);
            app.f1Label.Text = 'f1';
            app.f1EditField = uieditfield(app.PowerdopplerPanel, 'numeric');
            app.f1EditField.Limits = [0 Inf];
            app.f1EditField.ValueChangedFcn = createCallbackFcn(app, @f1EditFieldValueChanged, true);
            app.f1EditField.Tooltip = {'Frequency lower integration bound in kHz.'};
            app.f1EditField.Value = 8;
            app.f2EditFieldLabel = uilabel(app.PowerdopplerPanel);
            app.f2EditFieldLabel.Text = 'f2';
            app.f2EditField = uieditfield(app.PowerdopplerPanel, 'numeric');
            app.f2EditField.Limits = [0 Inf];
            app.f2EditField.ValueChangedFcn = createCallbackFcn(app, @f2EditFieldValueChanged, true);
            app.f2EditField.Tooltip = {'Frequency upper integration bound in kHz.'};
            app.f2EditField.Value = 30;
            app.ColorimagePanel = uipanel(app.GridLayout3);
            app.ColorimagePanel.Title = 'Color image';
            app.LowfrequencyrangeLabel = uilabel(app.ColorimagePanel);
            app.LowfrequencyrangeLabel.Text = 'Low frequency range: 1..6';
            app.HighfrequencyrangeLabel = uilabel(app.ColorimagePanel);
            app.HighfrequencyrangeLabel.Text = 'High frequency range: 6..30';
            app.f1EditField_2Label = uilabel(app.ColorimagePanel);
            app.f1EditField_2Label.Text = 'f1';
            app.compositef1EditField = uieditfield(app.ColorimagePanel, 'numeric');
            app.compositef1EditField.Limits = [0 Inf];
            app.compositef1EditField.ValueChangedFcn = createCallbackFcn(app, @compositef1EditFieldValueChanged, true);
            app.compositef1EditField.Tooltip = {'Frequency lower integration bound in kHz.'};
            app.compositef1EditField.Value = 1;
            app.f2EditField_2Label = uilabel(app.ColorimagePanel);
            app.f2EditField_2Label.Text = 'f2';
            app.compositef2EditField = uieditfield(app.ColorimagePanel, 'numeric');
            app.compositef2EditField.Limits = [0 Inf];
            app.compositef2EditField.ValueChangedFcn = createCallbackFcn(app, @compositef2EditFieldValueChanged, true);
            app.compositef2EditField.Tooltip = {'Frequency upper integration bound in kHz.'};
            app.compositef2EditField.Value = 6;
            app.f3EditFieldLabel = uilabel(app.ColorimagePanel);
            app.f3EditFieldLabel.Text = 'f3';
            app.compositef3EditField = uieditfield(app.ColorimagePanel, 'numeric');
            app.compositef3EditField.Limits = [0 Inf];
            app.compositef3EditField.ValueChangedFcn = createCallbackFcn(app, @compositef3EditFieldValueChanged, true);
            app.compositef3EditField.Tooltip = {'Frequency upper integration bound in kHz.'};
            app.compositef3EditField.Value = 30;
            app.Panel_6 = uipanel(app.GridLayout2);
            app.NotesTextAreaLabel = uilabel(app.Panel_6);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.Panel_6);
            % Show the figure after all components are created
            app.HoloDopplerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HoloDoppler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HoloDopplerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HoloDopplerUIFigure)
        end
    end
end