classdef Registerapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RegistrationappUIFigure   matlab.ui.Figure
        OverWriteCheckBox         matlab.ui.control.CheckBox
        radiusEditField           matlab.ui.control.NumericEditField
        radiusEditFieldLabel      matlab.ui.control.Label
        Frame2frameCheckBox       matlab.ui.control.CheckBox
        ShowrefCheckBox           matlab.ui.control.CheckBox
        RegisterButton            matlab.ui.control.Button
        PlayvideoButton           matlab.ui.control.Button
        ShowrefButton             matlab.ui.control.Button
        ReferenceLabel            matlab.ui.control.Label
        RefLamp                   matlab.ui.control.Lamp
        DirLamp                   matlab.ui.control.Lamp
        LoadreferenceButton       matlab.ui.control.Button
        RegistrationdiscCheckBox  matlab.ui.control.CheckBox
        DirectoryLabel            matlab.ui.control.Label
        LoaddirectoryButton       matlab.ui.control.Button
    end

    
    properties (Access = public)
        dirpath char % path to the directory of videos to register
        video_paths % dictionary with the different video paths found in the directory
        refpath char % path to the reference image 
        shifts double % result matrix of the computed shifts
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoaddirectoryButton
        function LoaddirectoryButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            if (isempty(app.dirpath))
                app.dirpath = uigetdir();
            else
                app.dirpath = uigetdir(strcat(app.dirpath, "*.raw;*.cine;*.holo"));
            end
            delete(f); %delete the dummy figure
            app.video_paths = dictionary();
            try

            
                list_content = dir(fullfile(app.dirpath,'avi'));
                for i=1:length(list_content)
                    if endsWith(list_content(i).name,"M0.avi") % parametrize if needed
                        disp("main video M0.avi : found")
                        app.video_paths('main') = fullfile(app.dirpath,'avi',list_content(i).name);
                    end
                    if endsWith(list_content(i).name,"moment_0.avi")
                        disp("moment_0.avi : found")
                        app.video_paths('avi_moment_0') = fullfile(app.dirpath,'avi',list_content(i).name);
                    end
                    if endsWith(list_content(i).name,"moment_1.avi")
                        disp("moment_1.avi : found")
                        app.video_paths('avi_moment_1') = fullfile(app.dirpath,'avi',list_content(i).name);
                    end
                    if endsWith(list_content(i).name,"moment_2.avi")
                        disp("moment_2.avi : found")
                        app.video_paths('avi_moment_2') = fullfile(app.dirpath,'avi',list_content(i).name);
                    end
                end
                list_content = dir(fullfile(app.dirpath,'raw'));
                for i=1:length(list_content)
                    if endsWith(list_content(i).name,"moment_0.raw")
                        disp("moment_0.raw : found")
                        app.video_paths('raw_moment_0') = fullfile(app.dirpath,'raw',list_content(i).name);
                    end
                    if endsWith(list_content(i).name,"moment_1.raw")
                        disp("moment_1.raw : found")
                        app.video_paths('raw_moment_1') = fullfile(app.dirpath,'raw',list_content(i).name);
                    end
                    if endsWith(list_content(i).name,"moment_2.raw")
                        disp("moment_2.raw : found")
                        app.video_paths('raw_moment_2') = fullfile(app.dirpath,'raw',list_content(i).name);
                    end
                end
                if isKey(app.video_paths,'main')
                    app.DirectoryLabel.Text = app.dirpath;
                    app.DirLamp.Color = [0,1,0];
                end
            catch e %e is an MException struct
                app.DirLamp.Color = [1,0,0];
                app.DirectoryLabel.Text = app.dirpath;
                fprintf(1,'The identifier was:\n%s',e.identifier);
                fprintf(1,'\nError loading video. The message was:\n%s',e.message);
            end
                
            
        end

        % Button pushed function: LoadreferenceButton
        function LoadreferenceButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [fname, fpath] = uigetfile('*.png');
            app.refpath = fullfile(fpath,fname);
            delete(f);
            
            try 
                imread(app.refpath);
                app.ReferenceLabel.Text = app.refpath;
                app.RefLamp.Color = [0,1,0];
            catch e
                app.RefLamp.Color = [1,0,0];
                app.ReferenceLabel.Text = app.refpath;
                fprintf(1,'The identifier was:\n%s',e.identifier);
                fprintf(1,'\nUnable to load reference image. The message was:\n%s',e.message);
            end
        end

        % Button pushed function: PlayvideoButton
        function PlayvideoButtonPushed(app, event)
            try
                implay(app.video_paths('main'));
            catch
            end
        end

        % Button pushed function: ShowrefButton
        function ShowrefButtonPushed(app, event)
            figure;imshow(app.refpath);
        end

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)
            disp('Registering')

            if ~(all(app.RefLamp.Color == [0,1,0]) && all(app.DirLamp.Color == [0,1,0]))
                return
            end

            v = VideoReader(app.video_paths('main'));
            image_ref = imread(app.refpath);
            Nx = v.Width;
            Ny = v.Height;
            Nframes = v.NumFrames;
            video = read(v);
            
            if app.RegistrationdiscCheckBox.Value
                [X,Y] = meshgrid(linspace(-Nx/2,Nx/2,Nx),linspace(-Ny/2,Ny/2,Ny));
                disc_ratio = app.radiusEditField.Value; % parametrize this coef if needed
                disc = X.^2+Y.^2 < (disc_ratio * min(Nx,Ny)/2)^2; 
            else
                disc = ones([Ny,Nx]);
            end

            video_reg = single(video) .* disc - disc .* sum(single(video).* disc,[1,2])/nnz(disc); % sets the disc and sets the mean of the disc outside of it
            video_reg = mean(video_reg,3); % mat2gray
            video_reg = video_reg./(max(abs(video_reg),[],[1,2]));
            
            image_ref = mean(image_ref,3);
            image_ref = imresize(image_ref,[Ny,Nx]);
            image_ref = image_ref.*disc - disc .* sum(image_ref.*disc,[1,2])/nnz(disc); % minus the mean 
            image_ref = image_ref./(max(abs(image_ref),[],[1,2]));


            if app.ShowrefCheckBox.Value
                figure;imagesc(video_reg(:,:,1));title('video first frame');
                figure;imagesc(image_ref);title('image reference');
            end

            if app.Frame2frameCheckBox.Value
                [video_reg, app.shifts] = register_video_frame2frame(video_reg, 10); % 10th frame as ref
            else
                [video_reg, app.shifts] = register_video_from_reference(video_reg, image_ref);
            end
            disp("Shifts calculated")
            if app.ShowrefCheckBox.Value
                try
                    implay(rescale(video_reg));
                    figure(); imshow(rescale(cat(3,video_reg(:,:,:,1),image_ref,rescale(video(:,:,1,1)))));
                catch
                end
            end

            [folder_path,~,~] = fileparts(app.video_paths('main'));
            new_shifts = app.shifts;
            save(fullfile(folder_path,'new_image_registration.mat'),'new_shifts');

            list_vids = keys(app.video_paths);

            appendice = '_new_reg';
            if app.OverWriteCheckBox.Value
                appendice = '';
            end
            for i=1:length(list_vids)
                key = list_vids(i);
                disp(['Shifting : ',app.video_paths(key)])
                v = VideoReader(app.video_paths(key));
                
                vid = read(v);
                
                vid = register_video_from_shifts(vid, app.shifts);
                [path,name,ext] = fileparts(app.video_paths(key));
                v = VideoWriter(fullfile(path,[char(name),appendice,char(ext)]));
                open(v);
                disp(['Writing : ',char(fullfile(path,[char(name),appendice,char(ext)]))])
                writeVideo(v,vid);
                close(v);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RegistrationappUIFigure = uifigure('Visible', 'off');
            app.RegistrationappUIFigure.Name = 'Registration app';
            app.LoaddirectoryButton = uibutton(app.RegistrationappUIFigure, 'push');
            app.LoaddirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddirectoryButtonPushed, true);
            app.LoaddirectoryButton.Text = 'Load directory';
            app.DirectoryLabel = uilabel(app.RegistrationappUIFigure);
            app.DirectoryLabel.Text = '';
            app.RegistrationdiscCheckBox = uicheckbox(app.RegistrationappUIFigure);
            app.RegistrationdiscCheckBox.Text = 'registration disc';
            app.LoadreferenceButton = uibutton(app.RegistrationappUIFigure, 'push');
            app.LoadreferenceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadreferenceButtonPushed, true);
            app.LoadreferenceButton.Text = 'Load reference';
            app.DirLamp = uilamp(app.RegistrationappUIFigure);
            app.RefLamp = uilamp(app.RegistrationappUIFigure);
            app.ReferenceLabel = uilabel(app.RegistrationappUIFigure);
            app.ReferenceLabel.Text = '';
            app.ShowrefButton = uibutton(app.RegistrationappUIFigure, 'push');
            app.ShowrefButton.ButtonPushedFcn = createCallbackFcn(app, @ShowrefButtonPushed, true);
            app.ShowrefButton.Text = '>';
            app.PlayvideoButton = uibutton(app.RegistrationappUIFigure, 'push');
            app.PlayvideoButton.ButtonPushedFcn = createCallbackFcn(app, @PlayvideoButtonPushed, true);
            app.PlayvideoButton.Text = '>';
            app.RegisterButton = uibutton(app.RegistrationappUIFigure, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Text = 'Register';
            app.ShowrefCheckBox = uicheckbox(app.RegistrationappUIFigure);
            app.ShowrefCheckBox.Text = 'show';
            app.Frame2frameCheckBox = uicheckbox(app.RegistrationappUIFigure);
            app.Frame2frameCheckBox.Visible = 'off';
            app.Frame2frameCheckBox.Text = 'frame to frame';
            app.radiusEditFieldLabel = uilabel(app.RegistrationappUIFigure);
            app.radiusEditFieldLabel.Text = 'radius';
            app.radiusEditField = uieditfield(app.RegistrationappUIFigure, 'numeric');
            app.radiusEditField.Limits = [0 1];
            app.radiusEditField.Value = 0.7;
            app.OverWriteCheckBox = uicheckbox(app.RegistrationappUIFigure);
            app.OverWriteCheckBox.Text = 'overwrite';
            % Show the figure after all components are created
            app.RegistrationappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Registerapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RegistrationappUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RegistrationappUIFigure)
        end
    end
end