classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainviewTab                     matlab.ui.container.Tab
        FileselectionPanel              matlab.ui.container.Panel
        LoadfileButton                  matlab.ui.control.Button
        SavevideosButton                matlab.ui.control.Button
        batchsizeEditFieldLabel         matlab.ui.control.Label
        batchsizeEditField              matlab.ui.control.NumericEditField
        batchstrideEditFieldLabel       matlab.ui.control.Label
        batchstrideEditField            matlab.ui.control.NumericEditField
        zEditFieldLabel                 matlab.ui.control.Label
        zEditField                      matlab.ui.control.NumericEditField
        wavelengthEditFieldLabel        matlab.ui.control.Label
        wavelengthEditField             matlab.ui.control.NumericEditField
        FsEditFieldLabel                matlab.ui.control.Label
        FsEditField                     matlab.ui.control.NumericEditField
        f1Label                         matlab.ui.control.Label
        f1EditField                     matlab.ui.control.NumericEditField
        f2EditFieldLabel                matlab.ui.control.Label
        f2EditField                     matlab.ui.control.NumericEditField
        pixwidthEditFieldLabel          matlab.ui.control.Label
        pixwidthEditField               matlab.ui.control.NumericEditField
        pixheightEditFieldLabel         matlab.ui.control.Label
        pixheightEditField              matlab.ui.control.NumericEditField
        imageregistrationCheckBox       matlab.ui.control.CheckBox
        temporalfilterCheckBox          matlab.ui.control.CheckBox
        ParallelismDropDown             matlab.ui.control.DropDown
        positioninfileSliderLabel       matlab.ui.control.Label
        positioninfileSlider            matlab.ui.control.Slider
        temporalfilterEditField         matlab.ui.control.NumericEditField
        imagewidthEditFieldLabel        matlab.ui.control.Label
        imagewidthEditField             matlab.ui.control.NumericEditField
        imageheightEditFieldLabel       matlab.ui.control.Label
        imageheightEditField            matlab.ui.control.NumericEditField
        bigendianCheckBox               matlab.ui.control.CheckBox
        saverawvideosCheckBox           matlab.ui.control.CheckBox
        horizontalaxisflipCheckBox      matlab.ui.control.CheckBox
        verticalaxisflipCheckBox        matlab.ui.control.CheckBox
        AutofocusButton                 matlab.ui.control.Button
        SVDCheckBox                     matlab.ui.control.CheckBox
        CurrentFilePanel                matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        UIAxes_1                        matlab.ui.control.UIAxes
        Panel_2                         matlab.ui.container.Panel
        DxEditFieldLabel                matlab.ui.control.Label
        DxEditField                     matlab.ui.control.NumericEditField
        staticrelativeimageshiftLabel   matlab.ui.control.Label
        DyEditFieldLabel                matlab.ui.control.Label
        DyEditField                     matlab.ui.control.NumericEditField
        InformationsPanel               matlab.ui.container.Panel
        NotesTextAreaLabel              matlab.ui.control.Label
        NotesTextArea                   matlab.ui.control.TextArea
        AdaptiveopticsPanel             matlab.ui.container.Panel
        phaseregistrationCheckBox       matlab.ui.control.CheckBox
        aberrationcompensationCheckBox  matlab.ui.control.CheckBox
        batchsizefactorCheckBox         matlab.ui.control.CheckBox
        additionalzernikeranksLabel     matlab.ui.control.Label
        additionalzernikeranksEditField  matlab.ui.control.NumericEditField
        optimDropDownLabel              matlab.ui.control.Label
        optimDropDown                   matlab.ui.control.DropDown
        batchsizefactorLabel            matlab.ui.control.Label
        batchsizefactorEditField        matlab.ui.control.NumericEditField
        contrasttolSliderLabel          matlab.ui.control.Label
        contrasttolSlider               matlab.ui.control.Slider
        tolLabel                        matlab.ui.control.Label
        loworderzernikestolEditField    matlab.ui.control.NumericEditField
        tolLabel_2                      matlab.ui.control.Label
        highorderzernikestolEditField   matlab.ui.control.NumericEditField
        masknumiterLabel                matlab.ui.control.Label
        masknumiterEditField            matlab.ui.control.NumericEditField
        maxconstraintLabel              matlab.ui.control.Label
        lowordermaxconstraintEditField  matlab.ui.control.NumericEditField
        maxconstraintEditField_2Label   matlab.ui.control.Label
        highordermaxconstraintEditField  matlab.ui.control.NumericEditField
        GridLayout4                     matlab.ui.container.GridLayout
        UIAxes_2                        matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
        adaptiveopticsimagefrequencyrangeLabel  matlab.ui.control.Label
        f1EditField_3Label              matlab.ui.control.Label
        registrationf1EditField         matlab.ui.control.NumericEditField
        f2Label                         matlab.ui.control.Label
        registrationf2EditField         matlab.ui.control.NumericEditField
        GridLayout5                     matlab.ui.container.GridLayout
        UIAxes_3                        matlab.ui.control.UIAxes
        Panel_3                         matlab.ui.container.Panel
        spatialfilteringimagefrequenciesLabel  matlab.ui.control.Label
        f1EditField_3Label_2            matlab.ui.control.Label
        replicaf1EditField              matlab.ui.control.NumericEditField
        f2Label_2                       matlab.ui.control.Label
        replicaf2EditField              matlab.ui.control.NumericEditField
        SpatialfilteringPanel           matlab.ui.container.Panel
        ApplyremovecomplexmaskButton    matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        ShackHarmannCheckBox            matlab.ui.control.CheckBox
        zernikeranksEditFieldLabel      matlab.ui.control.Label
        zernikeranksEditField           matlab.ui.control.NumericEditField
        subaperturemarginEditFieldLabel  matlab.ui.control.Label
        subaperturemarginEditField      matlab.ui.control.NumericEditField
        numsubapEditFieldLabel          matlab.ui.control.Label
        numsubapEditField               matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        % skip callbacks if no file_M0 is loaded
        file_loaded
        
        filename % current file_M0 loaded
        filepath
        extension % .raw or .cine
        interferogram_stream
        
        % data related to current displayed frame
        frame_batch
        
        % misc proxy data
        Nx
        Ny
        gw
        kernel
        FH
        hologram
        DX % x circshift for registration
        DY % y circshift for registration
        
        complex_mask % complex mask to apply additional aberrations
        
        % misc
        cache % cache containg all parameters
    end
    
    methods (Access = public)
        function compute_kernel(app, use_gpu)
            app.kernel = propagation_kernel(app.Nx, app.Ny, app.zEditField.Value, app.wavelengthEditField.Value, app.pixwidthEditField.Value, app.pixheightEditField.Value, use_gpu);
        end
    
        function compute_FH(app, use_gpu)
            if use_gpu
                app.FH = gpuArray(fftshift(fft2(app.frame_batch)) .* app.kernel);
            else
                app.FH = fftshift(fft2(app.frame_batch)) .* app.kernel;
            end
        end
    
        function compute_hologram(app, use_gpu)
            acquisition = DopplerAcquisition(app.Nx,app.Ny,app.FsEditField.Value/1000,app.zEditField.Value,app.wavelengthEditField.Value,app.DX,app.DY,app.pixwidthEditField.Value,app.pixheightEditField.Value);
            if ~isempty(app.complex_mask)
                app.FH = app.FH .* app.complex_mask;
            end
            app.hologram = reconstruct_hologram(app.FH, app.f1EditField.Value, app.f2EditField.Value, acquisition, app.gw, use_gpu, app.SVDCheckBox.Value);
            if app.horizontalaxisflipCheckBox.Value
                app.hologram = flip(app.hologram);
            end
            if app.verticalaxisflipCheckBox.Value
                app.hologram = flip(app.hologram, 2);
            end
            if ~isempty(app.complex_mask)
                app.FH = app.FH ./ app.complex_mask;
            end
        end
    
        function compute_reg_hologram(app, use_gpu)
            acquisition = DopplerAcquisition(app.Nx,app.Ny,app.FsEditField.Value/1000,app.zEditField.Value,app.wavelengthEditField.Value,app.DX,app.DY,app.pixwidthEditField.Value,app.pixheightEditField.Value);
            if ~isempty(app.complex_mask)
                app.FH = app.FH .* app.complex_mask;
            end
            app.hologram = reconstruct_hologram(app.FH, app.registrationf1EditField.Value, app.registrationf2EditField.Value, acquisition, app.gw, use_gpu, app.SVDCheckBox.Value);
            if app.horizontalaxisflipCheckBox.Value
                app.hologram = flip(app.hologram);
            end
            if app.verticalaxisflipCheckBox.Value
                app.hologram = flip(app.hologram, 2);
            end
            if ~isempty(app.complex_mask)
                app.FH = app.FH ./ app.complex_mask;
            end
        end
    
        function compute_replica_hologram(app, use_gpu)
            acquisition = DopplerAcquisition(app.Nx,app.Ny,app.FsEditField.Value/1000,app.zEditField.Value,app.wavelengthEditField.Value,app.DX,app.DY,app.pixwidthEditField.Value,app.pixheightEditField.Value);
            if ~isempty(app.complex_mask)
                app.FH = app.FH .* app.complex_mask;
            end
            app.hologram = compute_RH(app.FH, app.replicaf1EditField.Value, app.replicaf2EditField.Value, acquisition, app.gw);
            if app.horizontalaxisflipCheckBox.Value
                app.hologram = flip(app.hologram);
            end
            if app.verticalaxisflipCheckBox.Value
                app.hologram = flip(app.hologram, 2);
            end
            if ~isempty(app.complex_mask)
                app.FH = app.FH ./ app.complex_mask;
            end
        end
    
        function show_hologram(app)
            imshow(mat2gray(app.hologram), 'Parent', app.UIAxes_1);
        end
    
        function show_reg_hologram(app)
            imshow(mat2gray(app.hologram), 'Parent', app.UIAxes_2);
        end
    
        function show_replica_hologram(app)
            imshow(mat2gray(app.hologram), 'Parent', app.UIAxes_3);
        end
    end
    
    methods (Access = private)   
        function create_cache(app)
            c = GuiCache();
            
            c.batch_size = app.batchsizeEditField.Value;
            c.batch_stride = app.batchstrideEditField.Value;
            c.z = app.zEditField.Value;
            c.f1 = app.f1EditField.Value;
            c.f2 = app.f2EditField.Value;
            c.wavelength = app.wavelengthEditField.Value;
            c.Fs = app.FsEditField.Value;
            c.pix_width = app.pixwidthEditField.Value;
            c.pix_height = app.pixheightEditField.Value;
            c.registration = app.imageregistrationCheckBox.Value;
            c.temporal_filter_flag = app.temporalfilterCheckBox.Value;
            c.temporal_filter = app.temporalfilterEditField.Value;
            c.parallelism = app.ParallelismDropDown.Value;
            c.registration_via_phase = app.phaseregistrationCheckBox.Value;
            c.aberration_compensation = app.aberrationcompensationCheckBox.Value;
            c.batch_size_factor_flag = app.batchsizefactorCheckBox.Value;
            c.batch_size_factor = app.batchsizefactorEditField.Value;
            c.optimization_plan = app.optimDropDown.Value;
            c.additional_zernike_ranks = app.additionalzernikeranksEditField.Value;
            c.registration_f1 = app.registrationf1EditField.Value;
            c.registration_f2 = app.registrationf2EditField.Value;
            c.notes = app.NotesTextArea.Value;
            c.DX = app.DX;
            c.DY = app.DY;
            c.horizontal_axis_flip = app.horizontalaxisflipCheckBox.Value;
            c.vertical_axis_flip = app.verticalaxisflipCheckBox.Value;
            
            % iterative optimization parameters
            c.mask_num_iter = app.masknumiterEditField.Value;
            c.low_order_zernikes_tol = app.loworderzernikestolEditField.Value;
            c.high_order_zernikes_tol = app.highorderzernikestolEditField.Value;
            c.low_order_max_constraint = app.lowordermaxconstraintEditField.Value;
            c.high_order_max_constraint = app.highordermaxconstraintEditField.Value;
            
            % shack-hartmann parameters
            c.zernike_ranks = app.zernikeranksEditField.Value;
            c.num_subapertures = app.numsubapEditField.Value;
            c.subaperture_margin = app.subaperturemarginEditField.Value;
            
            app.cache = c;
        end
    
        function reload_raw_file(app)
            % reload the current raw file_M0 without prompting again
            % for a file_M0 selection
            app.Nx = app.imagewidthEditField.Value;
            app.Ny = app.imageheightEditField.Value;
            acquisition = DopplerAcquisition(app.Nx, app.Ny,app.FsEditField.Value/1000,app.zEditField.Value,app.wavelengthEditField.Value,app.DX,app.DY,app.pixwidthEditField.Value,app.pixheightEditField.Value);
            if app.bigendianCheckBox.Value
                endianness = 'b';
            else
                endianness = 'l';
            end
            app.interferogram_stream = RawReader(fullfile(app.filepath, app.filename), endianness, acquisition, app.batchsizeEditField.Value, app.batchstrideEditField.Value);
        
            app.frame_batch = app.interferogram_stream.read_frame_batch(app.batchsizeEditField.Value, floor(app.positioninfileSlider.Value));
            
            app.compute_kernel(false);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.file_loaded = true;
            app.show_hologram();
            
            app.compute_reg_hologram(true);
            app.show_reg_hologram();
            
            app.compute_replica_hologram(true);
            app.show_replica_hologram();
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set app constants
            app.complex_mask = [];
            app.gw = 35;
            app.file_loaded = false;
            app.DX = 0;
            app.DY = 0;
        end

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            % open file_M0
            [fname, fpath] = uigetfile('*.raw;*.cine');
            if fname == 0
                return
            end
            app.complex_mask = [];
            app.Label_2.Text = '';
            [~, ~, ext] = fileparts(fname);
            app.extension = ext;
            
            switch ext
                case '.cine'
                    app.filename = fname;
                    app.filepath = fpath;
                    app.interferogram_stream = CineReader(fullfile(app.filepath, app.filename));
                case '.raw'
                    app.filename = fname;
                    app.filepath = fpath;

                    app.Nx = app.imagewidthEditField.Value;
                    app.Ny = app.imageheightEditField.Value;
                    acquisition = DopplerAcquisition(app.Nx, app.Ny,app.FsEditField.Value/1000,app.zEditField.Value,app.wavelengthEditField.Value,app.DX,app.DY,app.pixwidthEditField.Value,app.pixheightEditField.Value);
                    if app.bigendianCheckBox.Value
                        endianness = 'b';
                    else
                        endianness = 'l';
                    end
                    app.interferogram_stream = RawReader(fullfile(app.filepath, app.filename), endianness, acquisition, app.batchsizeEditField.Value, app.batchstrideEditField.Value);
            end
            
            % display file_M0 name in GUI
            app.CurrentFilePanel.Title = sprintf("Current file: %s", fullfile(app.filepath, app.filename));
            
            % modify some edit fields depending on the file_M0 type used
            switch ext
                case '.raw'
                    % enables some parameter fields interactivity for parameters
                    % read in cine files that are not present in raw files
                    color = [0.8, 0.8, 0.8];
                    app.FsEditField.Editable = true;
                    app.pixheightEditField.Editable = true;
                    app.pixwidthEditField.Editable = true;
                    
                    app.imagewidthEditField.Editable = true;
                    app.imageheightEditField.Editable = true;
                    app.bigendianCheckBox.Enable = true;
                case '.cine'
                    color = [0.5, 0.5, 0.5];
                    app.FsEditField.Editable = false;
                    app.pixheightEditField.Editable = false;
                    app.pixwidthEditField.Editable = false;
                    
                    app.imagewidthEditField.Editable = false;
                    app.imageheightEditField.Editable = false;
                    app.bigendianCheckBox.Enable = false;
            end
            
            app.FsEditField.FontColor = color;
            app.pixheightEditField.FontColor = color;
            app.pixwidthEditField.FontColor = color;
            app.imagewidthEditField.FontColor = color;
            app.imageheightEditField.FontColor = color;
            
            
            app.positioninfileSlider.Limits = [0, double(app.interferogram_stream.num_frames-1024)]; % TODO replace hardcoded 1024
            app.positioninfileSlider.Value = 0;
            
            app.frame_batch = app.interferogram_stream.read_frame_batch(app.batchsizeEditField.Value, floor(app.positioninfileSlider.Value));
            
            try
                [cache, cache_found] = fetch_cache(app.filepath, app.filename, ext);
            catch
                % the directory is present but is missing files or contains
                % corrupt data
                cache = [];
                cache_found = false;
            end
            
            if cache_found
                try
                    % set gui parameters from cache
                    app.batchsizeEditField.Value = cache.batch_size;
                    app.batchstrideEditField.Value = cache.batch_stride;
                    app.zEditField.Value = cache.z;
                    app.f1EditField.Value = cache.f1;
                    app.f2EditField.Value = cache.f2;
                    app.registrationf1EditField.Value = cache.registration_f1;
                    app.registrationf2EditField.Value = cache.registration_f2;
                    app.wavelengthEditField.Value = cache.wavelength;
                    
                    app.NotesTextArea.Value = cache.notes;
                    
                    app.masknumiterEditField.Value = cache.mask_num_iter;
                    app.loworderzernikestolEditField.Value = cache.low_order_zernikes;
                    app.highorderzernikestolEditField.Value = cache.high_order_zernikes;
                    app.lowordermaxconstraintEditField.Value = cache.low_order_max_constraint;
                    app.highordermaxconstraintEditField.Value = cache.high_order_max_constraint;
                    
                    app.zernikeranksEditField.Value = cache.zernike_ranks;
                    app.numsubapEditField.Value = cache.num_subapertures;
                    app.subaperturemarginEditField.Value = cache.subaperture_margin;
                    if strcmp(ext, '.raw')
                        app.FsEditField.Value = cache.Fs;
                        app.pixheightEditField.Value = cache.pix_height;
                        app.pixwidthEditField.Value = cache.pix_width;
                    end
                catch
                  % an expection may have been thrown if the .mat file does
                  % not contain all of the required fields (e.g. if the .mat file was generated by
                  % an older version of the software). This is not a bug.
                  
                  % do nothing
                end
            else
                % set default gui parameters
                
                % do nothing, default parameters are already set in the GUI
                % toolbox
            end
            
            % display acquisition information
            switch ext
                case '.cine'
                    app.FsEditField.Value = double(app.interferogram_stream.frame_rate);
                    app.f2EditField.Value = floor(app.FsEditField.Value / (2*1000));
                    app.registrationf2EditField.Value = floor(app.FsEditField.Value / (2*1000));
                    app.pixwidthEditField.Value = 1 / double(app.interferogram_stream.horizontal_pix_per_meter);
                    app.pixheightEditField.Value = 1 / double(app.interferogram_stream.vertical_pix_per_meter);
                    app.Nx = double(app.interferogram_stream.frame_width);
                    app.Ny = double(app.interferogram_stream.frame_height);
                case '.raw'
                    % do nothing
            end
            
            % display images on file load
            
            % compute FH
            app.file_loaded = true;
            app.compute_kernel(false);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
            
            app.compute_reg_hologram(true);
            app.show_reg_hologram();
            
            app.compute_replica_hologram(true);
            app.show_replica_hologram();
        end

        % Value changed function: zEditField
        function zEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.compute_kernel(false);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
            
            app.compute_reg_hologram(true);
            app.show_reg_hologram();
            
            app.compute_replica_hologram(true);
            app.show_replica_hologram();
        end

        % Value changed function: f1EditField
        function f1EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: f2EditField
        function f2EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: wavelengthEditField
        function wavelengthEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.compute_kernel(false);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
            
            app.compute_reg_hologram(true);
            app.show_reg_hologram();
            
            app.compute_replica_hologram(true);
            app.show_replica_hologram();
        end

        % Value changed function: batchsizeEditField
        function batchsizeEditFieldValueChanged(app, event)
            if app.batchsizefactorCheckBox.Value 
                % if the batch stride factor checkbox is checked
                % then we constrain stride == size
                % so here we need to update the stride as well
                app.batchstrideEditField.Value = app.batchsizeEditField.Value;
            end
            
            if ~app.file_loaded
                return
            end
            
            app.frame_batch = app.interferogram_stream.read_frame_batch(app.batchsizeEditField.Value, 0);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
            
            app.compute_reg_hologram(true);
            app.show_reg_hologram();
            
            app.compute_replica_hologram(true);
            app.show_replica_hologram();
        end

        % Button pushed function: SavevideosButton
        function SavevideosButtonPushed(app, event)
            % main program loop
            % Select a file and call other functions
            % to perform a full computation and create
            % output videos.
            
            if ~app.file_loaded
                return
            end
            
            % start clock to monitor
            % computation duration
            tic;
            
            % save all gui parameters to a struct.
            % The current computation will fetch every parameter
            % from the cache and not from the gui.
            % The purpose of this is to prevent gui interactions
            % to mess up the parameters while a computation is ongoing.
            app.create_cache();
            
            % select parallelism policy
            switch app.cache.parallelism
                case 'CPU multithread'
                    parfor_arg = Inf;
                    use_multithread = true;
                    use_gpu = false;
                case 'GPU parallelization'
                    parfor_arg = 0;
                    use_multithread = false;
                    use_gpu = true;
                case 'CPU singlethread'
                    parfor_arg = 0;
                    use_multithread = false;
                    use_gpu = false;
                case 'CPU/GPU rendering/optim'
                    % TODO
                    parfor_arg = Inf;
                    use_multithread = true;
                    use_gpu = true;
            end

            % allocated video buffers
            num_batches = floor((app.interferogram_stream.num_frames - app.cache.batch_size) / app.cache.batch_stride);
            video_M0 = zeros(app.Nx,app.Ny,1,num_batches-1);
            video_M1 = video_M0;
            video_M2 = video_M0;
            video_composite_1 = video_M0;
            video_composite_2 = video_M0;
            video_composite_3 = video_M0;
            video_M1_over_M0 = video_M0;
            video_M2_over_M0 = video_M0;
            
            % temporal variation of image average (currently unused)
            temporal_avg = zeros(num_batches, 1);
            
            % create proxy variables so that the entire gui
            % is not broadcasted to workers
            % in the parfor loops
            istream = app.interferogram_stream;
            j_win = app.cache.batch_size;
            j_step = app.cache.batch_stride;
            local_f1 = app.cache.f1;
            local_f2 = app.cache.f2;
            local_gw = app.gw;
            local_Nx = app.Nx;
            local_Ny = app.Ny;
            local_complex_mask = app.complex_mask;
            local_svd = app.SVDCheckBox.Value;
            
            local_kernel = app.kernel;
            acquisition = DopplerAcquisition(app.Nx,app.Ny,app.cache.Fs/1000,app.cache.z,app.cache.wavelength,app.cache.DX,app.cache.DY,app.cache.pix_width,app.cache.pix_height);
            
            % parfor waitbar
            % we have to use a DataQueue
            % since there are no synchronization
            % primitives in matlab (mutex)
            D = parallel.pool.DataQueue;
            h = waitbar(0, '');
            afterEach(D, @update_waitbar);
            N = double(num_batches-1);
            p = 1;
                
            %% first pass - compute shifts for video registration
            registration_pass = app.cache.registration_via_phase;
            
            if registration_pass
                shifts = compute_temporal_registration(istream, app.cache, j_win, j_step, local_gw,...
                                                       local_kernel,local_complex_mask, D, use_multithread);   
                % tilts zernikes used for translating an image
                zernikes = evaluate_zernikes([1 1], [1 -1], app.Nx, app.Ny);
            else
                % declare unused variables to make matlab parfor
                % loop parser happy - stupid matlab
                shifts = zeros(2, istream.num_frames);
                zernikes = zeros(app.Nx, app.Ny, 2);
            end
            
            aberration_correction_pass = app.cache.aberration_compensation;
            enable_shack_hartmann = app.ShackHarmannCheckBox.Value;
            
            % modify j_win and j_step if a batch_size_factor is provieded
            % by user
            if app.cache.batch_size_factor_flag
                batch_size_factor = app.cache.batch_size_factor;
                aberration_j_win = j_win * batch_size_factor;
                aberration_j_step = aberration_j_win; % j_step == j_win is constrained in gui
            else
                batch_size_factor = 1;
                aberration_j_win = j_win;
                aberration_j_step = j_step;
            end
            
            if aberration_correction_pass && ~enable_shack_hartmann
                % aberration computation pass
                %
                % Implementation - Entropy optimization
                %
                % here we generate a set of zernike coefficients
                % that represent the aberation present on the
                % reconstructed images
                
                aberration_num_batches = floor((istream.num_frames - aberration_j_win) / aberration_j_step);
                
                % zernikes correction coefficient buffer
                % This is a N x num_batches matrix
                % The i-th row contains the coefficients for zernike number
                % i + 2 (matching zernfun2 numbering) except for the first 
                % 3 rows that have the following layout:
                %   correction_coefs(1,:): defocus zernike
                %   correction_coefs(2,:): first astigmatism zernike
                %   correction_coefs(3,:): second astigmatism zernike
                correction_coefs = zeros(3, aberration_num_batches);
                
                switch app.cache.optimization_plan
                    case 'defocus'
                        correction_coefs = zeros(1, aberration_num_batches);
                        disp('optimizing defocus');
                        send(D, 1);
                        correction_coefs(1, :) = compute_correction(istream,app.cache,app.gw,app.complex_mask,D,use_gpu,use_multithread,4,app.cache.low_order_zernikes_tol,app.cache.mask_num_iter,app.cache.low_order_max_constraint,shifts);
                        all_zernike_indices_used = [4];
                    case 'astigmatism'
                        correction_coefs = zeros(2, aberration_num_batches);
                        disp('optimizing astigmatism');
                        send(D, 2);
                        correction_coefs(1:2, :) = compute_correction(istream,app.cache,app.gw,app.complex_mask,D,use_gpu,use_multithread,[3 5],app.cache.low_order_zernikes_tol,app.cache.mask_num_iter,app.cache.low_order_max_constraint,shifts);
                        all_zernike_indices_used = [3 5];
                    case 'defocus then astigmatism'
                        correction_coefs = zeros(3, aberration_num_batches);
                        
                        disp('optimizing defocus');
                        send(D, 1);
                        correction_coefs(1, :) = compute_correction(istream,app.cache,app.gw,app.complex_mask,D,use_gpu,use_multithread,4,app.cache.low_order_zernikes_tol,app.cache.mask_num_iter,app.cache.low_order_max_constraint,shifts);
                        
                        disp('optimizing astigmatism');
                        send(D, 2);
                        correction_coefs(2:3, :) = compute_correction(istream,app.cache,app.gw,app.complex_mask,D,use_gpu,use_multithread,[3 5],app.cache.low_order_zernikes_tol,app.cache.mask_num_iter,app.cache.low_order_max_constraint,shifts,3,correction_coefs(1, :));
                        all_zernike_indices_used = [4 3 5];
                    case 'defocus and astigmatism'
                        correction_coefs = zeros(3, aberration_num_batches);
                        disp('optimizing defocus and astigmatism');
                        send(D, 3);
                        correction_coefs(1:3, :) = compute_correction(istream,app.cache,app.gw,app.complex_mask,D,use_gpu,use_multithread,[4 3 5],app.cache.low_order_zernikes_tol,app.cache.mask_num_iter,app.cache.low_order_max_constraint,shifts);
                        all_zernike_indices_used = [4 3 5];
                end
                
                if app.cache.additional_zernike_ranks > 0
                    % compute coefficients for high order zernikes
                    % we make a different optimization pass for each
                    % additional zernike rank
                    for r = 1:app.cache.additional_zernike_ranks
                        send(D, 3); % reset progress bar
                        num_zernikes = r + 3; % map to zernfun zernike ordering
                        
                        % construct high order zernike indices
                        zernike_indices = (num_zernikes * (num_zernikes - 1) / 2):(num_zernikes * (num_zernikes - 1) / 2 + num_zernikes - 1);
                        
                        % compute correction
                        current_cor = compute_correction(istream,app.cache,app.gw,app.complex_mask,D,use_gpu,use_multithread,zernike_indices,app.cache.high_order_zernikes_tol,app.cache.mask_num_iter,app.cache.high_order_max_constraint,shifts,all_zernike_indices_used, correction_coefs);
                        
                        % append coefs to correction coefs array
                        correction_coefs = [correction_coefs; current_cor];
                        all_zernike_indices_used = [all_zernike_indices_used zernike_indices];
                    end
                end
                
                num_zernike_ranks = app.cache.additional_zernike_ranks;
                % total number of zernike functions wrt number of ranks
                % used. 
                num_zernikes = (num_zernike_ranks + 4) * (num_zernike_ranks + 3) / 2;
                [~, correction_zernikes] = zernike_phase([4 3 5 6:(num_zernikes-1)], app.Nx, app.Ny);
            elseif aberration_correction_pass && enable_shack_hartmann
                % aberration computation pass
                %
                % Implementation - Shack Hartmann simulation
                
                % shack hartmann params - TODO get them from GUI
                zernike_ranks = app.cache.zernike_ranks;
                num_subapertures = app.cache.num_subapertures;
                subaperture_margin = app.cache.subaperture_margin;
                
                calibration_factor = 20;
                corrmap_margin = 0.4;
                
                power_filter_corrector = 1;
                sigma_filter_corrector = 1;
                
                % select subapertures to exclude, depending on the number
                % of subaperture used. This is chosen experimentally, the
                % value of the number of subapertures is constrained in the
                % GUI so the following switch should always branch to a
                % valid choice of excluded_subapertures
                switch num_subapertures
                    case 4
                        excluded_subapertures = [1; 4; 13; 16];
                    case 5
                        excluded_subapertures = [1; 5; 21; 25];
                    case 6
                        excluded_subapertures = [1; 2; 5; 6; 7; 12; 25; 30; 31; 32; 35; 36];
                    case 7
                        excluded_subapertures = [1; 2; 6; 7; 8; 14; 36; 42; 43; 44; 48; 49];
                    case 8
                        excluded_subapertures = [1; 2; 7; 8; 9; 16; 49; 56; 57; 58; 63; 64];
                    otherwise
                        error('Unreachable code was reached. Check value of num_subapertures');
                end
                
                correction_coefs = [];
                correction_zernikes = [];
                
                % perform iterative aberration computation with shack-hartmann
                for r = 2:zernike_ranks
                    % r-th zernike line has r+1 zernikes in it
                    cur_num_zernikes = r + 1;
                    
                    
                    % compute indices of the zernikes of current rank
                    current_zernike_indices = (cur_num_zernikes * (cur_num_zernikes - 1) / 2):(cur_num_zernikes * (cur_num_zernikes - 1) / 2 + cur_num_zernikes - 1);
                    
                    previous_zernike_indices = 3:current_zernike_indices(1)-1;
                    
                    % apply previous aberration correction
                    current_correction_coefs = compute_correction_shack_hartmann(istream,app.cache,local_kernel,app.gw,app.complex_mask,D,use_gpu,use_multithread,current_zernike_indices,...
                                                                     shifts,num_subapertures,calibration_factor,subaperture_margin,corrmap_margin,...
                                                                     excluded_subapertures,power_filter_corrector,sigma_filter_corrector,...
                                                                     previous_zernike_indices, correction_coefs);
                    [~, current_correction_zernikes] = zernike_phase(current_zernike_indices, app.Nx, app.Ny);
                                                                 
                    correction_coefs = [correction_coefs; current_correction_coefs];
                    correction_zernikes = cat(3, correction_zernikes, current_correction_zernikes);
                end
            else
                % define dummy unused variables with good size
                % to make matlab parfor parser happy - stupid matlab
                correction_coefs = zeros(3, istream.num_frames);
                correction_zernikes = zeros(app.Nx, app.Ny, 3);
            end
            
            disp('corr done')
            
            %% Final pass
            % Here we apply all transfromations computed in previous passes
            % and construct the videos
            
            send(D,-2); % display 'video construction' on progress bar
            parfor (batch_idx = 1:num_batches-1, parfor_arg)
                frame_batch = istream.read_frame_batch(j_win, batch_idx * j_step);
                if ~isempty(local_complex_mask)
                    FH = compute_FH_from_frame_batch(frame_batch, local_kernel, local_complex_mask);
                else
                    FH = compute_FH_from_frame_batch(frame_batch, local_kernel);
                end
                
                % add tilted phase for FH registration
                if registration_pass
                    FH = register_FH(FH, shifts(:, batch_idx * batch_size_factor:(batch_idx+1)*batch_size_factor-1), j_win, batch_size_factor);
                end
                
                if aberration_correction_pass
                    % load correction coefs for the current batch
                    local_coefs = correction_coefs(:, floor(batch_idx / batch_size_factor));

                    phase = 0;
                    for i = 1:size(local_coefs, 1)
                        phase = phase + local_coefs(i) * correction_zernikes(:,:,i);
                    end
                    correction = exp(-1i * phase);
                    
                    % apply correction
                    FH = FH .* correction;
                end

                [M0, sqrt_M0, M1, M2, composite_1, composite_2, composite_3] = reconstruct_hologram(FH, local_f1, local_f2, acquisition, local_gw, false, local_svd);

                % store current batch computed frames to their video buffer
                video_M0(:,:,:,batch_idx) = gather(M0);
                video_M1(:,:,:,batch_idx) = gather(M1);
                video_M2(:,:,:,batch_idx) = gather(M2);
                video_composite_1(:,:,:,batch_idx) = gather(composite_1);
                video_composite_2(:,:,:,batch_idx) = gather(composite_2);
                video_composite_3(:,:,:,batch_idx) = gather(composite_3);
                video_M1_over_M0(:,:,:,batch_idx) = gather(M1) ./ gather(M0);
                video_M2_over_M0(:,:,:,batch_idx) = gather(M2) ./ gather(sqrt_M0);
                temporal_avg(batch_idx) = mean(mean(gather(M0), 1), 2);
                send(D, 0);
            end
            
            function update_waitbar(sig)
                % signal table
                % 0 => increment value
                % 1 => reset for stage 1 (registration)
                % 2 => reset for stage 2 (video_M0 computation)
                switch sig
                    case 0
                        waitbar(p/N, h);
                        p = p + 1;
                    case -1
                        waitbar(0,h,'Video registration...');
                        p = 1;
                    case -2
                        waitbar(0,h,'Video construction...');
                        p = 1;
                    case 1
                        waitbar(0,h,'Optimizing defocus...');
                        p = 1;
                    case 2
                        waitbar(0,h,'Optimizing astigmatism...');
                        p = 1;
                    otherwise
                        waitbar(0,h,'Optimizing other zernikes...');
                        p = 1;
                end
            end
        
            if app.cache.registration
                % post reconstruction image registration.
                % This registration is performed on the final video,
                % it does not use tilts zernikes to perform the
                % registration.
                video_M0 = register_video(mat2gray(video_M0));
                video_M1 = register_video(mat2gray(video_M1));
                video_M2 = register_video(mat2gray(video_M2));
                video_composite_1 = register_video(mat2gray(video_composite_1));
                video_composite_2 = register_video(mat2gray(video_composite_2));
                video_composite_3 = register_video(mat2gray(video_composite_3));
                video_M1_over_M0 = register_video(mat2gray(video_M1_over_M0));
                video_M2_over_M0 = register_video(mat2gray(video_M2_over_M0));
            end
            
            % guassian temporal filter
            if app.cache.temporal_filter_flag
                windowSize = app.cache.temporal_filter; 
                
                % sigma_x and sigma_y should be 0
                % but matlab won't accept that input
                sigma = [0.0001 0.0001 windowSize];
                
                % filter every video
                video_M0 = imgaussfilt3(reshape(video_M0, app.Nx, app.Nx, size(video_M0,4)), sigma);
                video_M0 = reshape(video_M0, app.Nx, app.Ny, 1, size(video_M0,3));
                video_M1 = imgaussfilt3(reshape(video_M1, app.Nx, app.Nx, size(video_M1,4)), sigma);
                video_M1 = reshape(video_M1, app.Nx, app.Ny, 1, size(video_M1,3));
                video_M2 = imgaussfilt3(reshape(video_M2, app.Nx, app.Nx, size(video_M2,4)), sigma);
                video_M2 = reshape(video_M2, app.Nx, app.Ny, 1, size(video_M2,3));
                video_composite_1 = imgaussfilt3(reshape(video_composite_1, app.Nx, app.Nx, size(video_composite_1,4)), sigma);
                video_composite_1 = reshape(video_composite_1, app.Nx, app.Ny, 1, size(video_composite_1,3));
                video_composite_2 = imgaussfilt3(reshape(video_composite_2, app.Nx, app.Nx, size(video_composite_2,4)), sigma);
                video_composite_2 = reshape(video_composite_2, app.Nx, app.Ny, 1, size(video_composite_2,3));
                video_composite_3 = imgaussfilt3(reshape(video_composite_3, app.Nx, app.Nx, size(video_composite_3,4)), sigma);
                video_composite_3 = reshape(video_composite_3, app.Nx, app.Ny, 1, size(video_composite_3,3));
                video_M1_over_M0 = imgaussfilt3(reshape(video_M1_over_M0, app.Nx, app.Nx, size(video_M1_over_M0,4)), sigma);
                video_M1_over_M0 = reshape(video_M1_over_M0, app.Nx, app.Ny, 1, size(video_M1_over_M0,3));
                video_M2_over_M0 = imgaussfilt3(reshape(video_M2_over_M0, app.Nx, app.Nx, size(video_M2_over_M0,4)), sigma);
                video_M2_over_M0 = reshape(video_M2_over_M0, app.Nx, app.Ny, 1, size(video_M2_over_M0,3));
            end
            
            % compute images to plot
            moment_mean = mean(video_M0,4);
            moment_std = std(video_M0,0,4);
            moment_std_avg = moment_std./moment_mean;
            
            % generate average images for each video
            M0_avg = mean(video_M0, 4);
            M1_avg = mean(video_M1, 4);
            M2_avg = mean(video_M2, 4);
            composite_1_avg = mean(video_composite_1, 4);
            composite_2_avg = mean(video_composite_2, 4);
            composite_3_avg = mean(video_composite_3, 4);
            M1_over_M0_avg = mean(video_M1_over_M0, 4);
            M2_over_M0_avg = mean(video_M2_over_M0, 4);
            
            % enhance video_M0 contrast
            tol_pdi_low = app.contrasttolSlider.Value;
            tol_pdi = [tol_pdi_low 1-tol_pdi_low];
            video_M0 = enhance_video_constrast(video_M0, tol_pdi);
            video_M1 = enhance_video_constrast(video_M1, tol_pdi);
            video_M2 = enhance_video_constrast(video_M2, tol_pdi);
            video_M2_over_M0 = enhance_video_constrast(video_M2_over_M0, tol_pdi);
            
            % fix intensity flashes
            video_M0 = video_M0 - mean(mean(video_M0,1),2);
            video_M1 = video_M1 - mean(mean(video_M1,1),2);
            video_M2 = video_M2 - mean(mean(video_M2,1),2);
            video_M1_over_M0 = video_M1_over_M0 - mean(mean(video_M1_over_M0,1),2);
            video_M2_over_M0 = video_M2_over_M0 - mean(mean(video_M2_over_M0,1),2);
            
            % flip videos
            if app.cache.horizontal_axis_flip
                video_M0 = flip(video_M0);
                video_M1 = flip(video_M1);
                video_M2 = flip(video_M2);
                video_composite_1 = flip(video_composite_1);
                video_composite_2 = flip(video_composite_2);
                video_composite_3 = flip(video_composite_3);
                video_M1_over_M0 = flip(video_M1_over_M0);
                video_M2_over_M0 = flip(video_M2_over_M0);
            end
            if app.cache.vertical_axis_flip
                video_M0 = flip(video_M0, 2);
                video_M1 = flip(video_M1, 2);
                video_M2 = flip(video_M2, 2);
                video_composite_1 = flip(video_composite_1, 2);
                video_composite_2 = flip(video_composite_2, 2);
                video_composite_3 = flip(video_composite_3, 2);
                video_M1_over_M0 = flip(video_M1_over_M0, 2);
                video_M2_over_M0 = flip(video_M2_over_M0, 2);
            end
            
            file_M0 = create_output_filename(app.filepath, app.filename, 'M0', 'avi');
            file_M1 = create_output_filename(app.filepath, app.filename, 'DopplerAVG', 'avi');
            file_M2 = create_output_filename(app.filepath, app.filename, 'DopplerRMS', 'avi');
            file_composite_1 = create_output_filename(app.filepath, app.filename, 'composite_1', 'avi');
            file_composite_2 = create_output_filename(app.filepath, app.filename, 'composite_2', 'avi');
            file_composite_3 = create_output_filename(app.filepath, app.filename, 'composite_3', 'avi');
            file_M1_over_M0 = create_output_filename(app.filepath, app.filename, 'NormalizedDopplerAVG', 'avi');
            file_M2_over_M0 = create_output_filename(app.filepath, app.filename, 'NormalizedDopplerRMS', 'avi');
            
            % create output directory
            output_dirname = strrep(file_M0, '_M0.avi', '');
            output_path = fullfile(app.filepath, output_dirname);
            mkdir(output_path);

            % export patient informations to text file_M0
            txt_filename = sprintf('%s.txt', output_dirname);
            save_string_cells_to_file(fullfile(output_path, txt_filename), app.cache.notes);
            
            power_doppler_avg_img = moment_mean;
            
            % save coefficient of variation
            % TODO: put this in a separate function
            bottom = [0 0 0.5];
            botmiddle = [0 0.5 1];
            middle = [1 1 1];
            topmiddle = [1 0 0];
            top = [0.5 0 0];
            vec = [bottom; botmiddle; middle; topmiddle; top];
            wave = interp1(1:length(vec), vec, linspace(1, length(vec), 256), 'linear');
            IMG = mat2gray(moment_std_avg);
            Tol = [0.03 0.997];
            lowhigh = stretchlim(IMG, Tol);
            img_avg_stretch = imadjust(IMG, lowhigh);
            pulse_wave_modulation_depth_img = ind2rgb(im2uint8(img_avg_stretch), wave);

            % export some workspace variables to .mat file
            mat_filename = sprintf('%s.mat', output_dirname);
            cache = app.cache;
            save(fullfile(output_path, mat_filename), 'cache');
            if registration_pass
                save(fullfile(output_path, mat_filename), 'shifts', '-append');
            end
            if aberration_correction_pass
                save(fullfile(output_path, mat_filename), 'correction_coefs', '-append');
            end
            save(fullfile(output_path, mat_filename), 'pulse_wave_modulation_depth_img', '-append');
            save(fullfile(output_path, mat_filename), 'power_doppler_avg_img', '-append');

%             plot(temporal_avg);
            
            % create folders for output videos
            mkdir(fullfile(output_path, "avi"));
            mkdir(fullfile(output_path, "mp4"));
            mkdir(fullfile(output_path, "raw"));
            mkdir(fullfile(output_path, "png"));
            
            w_M0 = VideoWriter(sprintf('%s\\avi\\%s', output_path, file_M0));
            w_M1 = VideoWriter(sprintf('%s\\avi\\%s', output_path, file_M1));
            w_M2 = VideoWriter(sprintf('%s\\avi\\%s', output_path, file_M2));
            w_composite_1 = VideoWriter(sprintf('%s\\avi\\%s', output_path, file_composite_1));
            w_composite_2 = VideoWriter(sprintf('%s\\avi\\%s', output_path, file_composite_2));
            w_composite_3 = VideoWriter(sprintf('%s\\avi\\%s', output_path, file_composite_3));
            w_M1_over_M0 = VideoWriter(sprintf('%s\\avi\\%s', output_path, file_M1_over_M0));
            w_M2_over_M0 = VideoWriter(sprintf('%s\\avi\\%s', output_path, file_M2_over_M0));
            
            open(w_M0);
            open(w_M1);
            open(w_M2);
            open(w_composite_1);
            open(w_composite_2);
            open(w_composite_3);
            open(w_M1_over_M0);
            open(w_M2_over_M0);
            
            writeVideo(w_M0, mat2gray(video_M0));
            writeVideo(w_M1, mat2gray(video_M1));
            writeVideo(w_M2, mat2gray(video_M2));
            writeVideo(w_composite_1, mat2gray(video_composite_1));
            writeVideo(w_composite_2, mat2gray(video_composite_2));
            writeVideo(w_composite_3, mat2gray(video_composite_3));
            writeVideo(w_M1_over_M0, mat2gray(video_M1_over_M0));
            writeVideo(w_M2_over_M0, mat2gray(video_M2_over_M0));
            
            close(w_M0);
            close(w_M1);
            close(w_M2);
            close(w_composite_1);
            close(w_composite_2);
            close(w_composite_3);
            close(w_M1_over_M0);
            close(w_M2_over_M0);
            
            file_M0 = strrep(file_M0, '.avi', '.mp4');
            file_M1 = strrep(file_M1, '.avi', '.mp4');
            file_M2 = strrep(file_M2, '.avi', '.mp4');
            file_composite_1 = strrep(file_composite_1, '.avi', '.mp4');
            file_composite_2 = strrep(file_composite_2, '.avi', '.mp4');
            file_composite_3 = strrep(file_composite_3, '.avi', '.mp4');
            file_M1_over_M0 = strrep(file_M1_over_M0, '.avi', '.mp4');
            file_M2_over_M0 = strrep(file_M2_over_M0, '.avi', '.mp4');
            
            w_M0 = VideoWriter(sprintf('%s\\mp4\\%s', output_path, file_M0), 'MPEG-4');
            w_M1 = VideoWriter(sprintf('%s\\mp4\\%s', output_path, file_M1), 'MPEG-4');
            w_M2 = VideoWriter(sprintf('%s\\mp4\\%s', output_path, file_M2), 'MPEG-4');
            w_composite_1 = VideoWriter(sprintf('%s\\mp4\\%s', output_path, file_composite_1), 'MPEG-4');
            w_composite_2 = VideoWriter(sprintf('%s\\mp4\\%s', output_path, file_composite_2), 'MPEG-4');
            w_composite_3 = VideoWriter(sprintf('%s\\mp4\\%s', output_path, file_composite_3), 'MPEG-4');
            w_M1_over_M0 = VideoWriter(sprintf('%s\\mp4\\%s', output_path, file_M1_over_M0), 'MPEG-4');
            w_M2_over_M0 = VideoWriter(sprintf('%s\\mp4\\%s', output_path, file_M2_over_M0), 'MPEG-4');
            
            open(w_M0);
            open(w_M1);
            open(w_M2);
            open(w_composite_1);
            open(w_composite_2);
            open(w_composite_3);
            open(w_M1_over_M0);
            open(w_M2_over_M0);
            
            writeVideo(w_M0, mat2gray(video_M0));
            writeVideo(w_M1, mat2gray(video_M1));
            writeVideo(w_M2, mat2gray(video_M2));
            writeVideo(w_composite_1, mat2gray(video_composite_1));
            writeVideo(w_composite_2, mat2gray(video_composite_2));
            writeVideo(w_composite_3, mat2gray(video_composite_3));
            writeVideo(w_M1_over_M0, mat2gray(video_M1_over_M0));
            writeVideo(w_M2_over_M0, mat2gray(video_M2_over_M0));
            
            close(w_M0);
            close(w_M1);
            close(w_M2);
            close(w_composite_1);
            close(w_composite_2);
            close(w_composite_3);
            close(w_M1_over_M0);
            close(w_M2_over_M0);
            
            file_M0 = strrep(file_M0, '.mp4', '.png');
            file_M1 = strrep(file_M1, '.mp4', '.png');
            file_M2 = strrep(file_M2, '.mp4', '.png');
            file_composite_1 = strrep(file_composite_1, '.mp4', '.png');
            file_composite_2 = strrep(file_composite_2, '.mp4', '.png');
            file_composite_3 = strrep(file_composite_3, '.mp4', '.png');
            file_M1_over_M0 = strrep(file_M1_over_M0, '.mp4', '.png');
            file_M2_over_M0 = strrep(file_M2_over_M0, '.mp4', '.png');  
            
            imwrite(mat2gray(M0_avg), sprintf('%s\\png\\%s', output_path, file_M0));
            imwrite(mat2gray(M1_avg), sprintf('%s\\png\\%s', output_path, file_M1));
            imwrite(mat2gray(M2_avg), sprintf('%s\\png\\%s', output_path, file_M2));
            imwrite(mat2gray(composite_1_avg), sprintf('%s\\png\\%s', output_path, file_composite_1));
            imwrite(mat2gray(composite_2_avg), sprintf('%s\\png\\%s', output_path, file_composite_2));
            imwrite(mat2gray(composite_3_avg), sprintf('%s\\png\\%s', output_path, file_composite_3));
            imwrite(mat2gray(M1_over_M0_avg), sprintf('%s\\png\\%s', output_path, file_M1_over_M0));
            imwrite(mat2gray(M2_over_M0_avg), sprintf('%s\\png\\%s', output_path, file_M2_over_M0));
            
            imwrite(pulse_wave_modulation_depth_img, fullfile(app.filepath, output_dirname, 'png', 'PulseWaveModulationDepth.png'))
            
            if app.saverawvideosCheckBox.Value
                file_M0 = strrep(file_M0, '.png', '.raw');
                file_M1 = strrep(file_M1, '.png', '.raw');
                file_M2 = strrep(file_M2, '.png', '.raw');
                file_composite_1 = strrep(file_composite_1, '.png', '.raw');
                file_composite_2 = strrep(file_composite_2, '.png', '.raw');
                file_composite_3 = strrep(file_composite_3, '.png', '.raw');
                file_M1_over_M0 = strrep(file_M1_over_M0, '.png', '.raw');
                file_M2_over_M0 = strrep(file_M2_over_M0, '.png', '.raw');
                       
                export_raw_video(sprintf('%s\\raw\\%s', output_path, file_M0), video_M0);
                export_raw_video(sprintf('%s\\raw\\%s', output_path, file_M1), video_M1);
                export_raw_video(sprintf('%s\\raw\\%s', output_path, file_M2), video_M2);
                export_raw_video(sprintf('%s\\raw\\%s', output_path, file_M1_over_M0), video_M1_over_M0);
                export_raw_video(sprintf('%s\\raw\\%s', output_path, file_M2_over_M0), video_M2_over_M0);
                export_raw_video(sprintf('%s\\raw\\%s', output_path, file_composite_1), video_composite_1);
                export_raw_video(sprintf('%s\\raw\\%s', output_path, file_composite_2), video_composite_2);
                export_raw_video(sprintf('%s\\raw\\%s', output_path, file_composite_3), video_composite_3);
            end
            
            close(h);
            
            % display elapsed time
            toc;
        end

        % Button pushed function: AutofocusButton
        function AutofocusButtonPushed(app, event)
            if ~app.file_loaded
                return
            end
            
            z0 = app.zEditField.Value;
            acquisition = DopplerAcquisition(app.Nx,app.Ny,app.FsEditField.Value/1000,app.zEditField.Value,app.wavelengthEditField.Value,app.DX,app.DY,app.pixwidthEditField.Value,app.pixheightEditField.Value);
            mask = construct_mask(0, app.gw, app.Nx, app.Ny);
            
            % free GPU memory
            app.FH = 0;
            
            z = autofocus(app.frame_batch, app.registrationf1EditField.Value, app.registrationf2EditField.Value, acquisition, z0, mask, app.gw, true);
            app.zEditField.Value = z;
            app.compute_kernel(false);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
            
            app.compute_reg_hologram(true);
            app.show_reg_hologram();
            
            app.compute_replica_hologram(true);
            app.show_replica_hologram();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: positioninfileSlider
        function positioninfileSliderValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.frame_batch = app.interferogram_stream.read_frame_batch(app.batchsizeEditField.Value, floor(app.positioninfileSlider.Value));
            app.compute_kernel(false);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
            
            app.compute_reg_hologram(true);
            app.show_reg_hologram();
            
            app.compute_replica_hologram(true);
            app.show_replica_hologram();
        end

        % Value changed function: aberrationcompensationCheckBox
        function aberrationcompensationCheckBoxValueChanged(app, event)
            value = app.aberrationcompensationCheckBox.Value;
            
            if value
                % turn on batch size factor widgets and set batch stride to
                % batch size
                app.batchsizefactorCheckBox.Enable = true;
                app.batchsizefactorEditField.Enable = true;
                app.optimDropDown.Enable = true;
                app.additionalzernikeranksEditField.Enable = true;
            else
                app.batchsizefactorCheckBox.Value = false;
                app.batchsizefactorCheckBox.Enable = false;
                app.batchsizefactorEditField.Enable = false;
                app.optimDropDown.Enable = false;
                app.additionalzernikeranksEditField.Enable = false;
                
                % revert j_step editfield editable
                color = [0.8, 0.8, 0.8];
                app.batchstrideEditField.Editable = true;
                app.batchstrideEditField.FontColor = color;
            end
        end

        % Value changed function: batchsizefactorCheckBox
        function batchsizefactorCheckBoxValueChanged(app, event)
            value = app.batchsizefactorCheckBox.Value;
            
            if value
                app.batchstrideEditField.Editable = false;
                app.batchstrideEditField.Value = app.batchsizeEditField.Value;
                color = [0.5, 0.5, 0.5];
            else
                app.batchstrideEditField.Editable = true;
                color = [0.8, 0.8, 0.8];
            end
            
            app.batchstrideEditField.FontColor = color;
        end

        % Callback function
        function FiletypeDropDownValueChanged(app, event)
            value = app.FiletypeDropDown.Value;
            
            % unload current file_M0
            app.file_loaded = false;
            
            % clear first image
            axes_handles_to_child_object = findobj(app.UIAxes_1, 'Type', 'image');
            if ~isempty(axes_handles_to_child_object)
                delete(axes_handles_to_child_object);
            end
            
            % clear second image
            axes_handles_to_child_object = findobj(app.UIAxes_2, 'Type', 'image');
            if ~isempty(axes_handles_to_child_object)
                delete(axes_handles_to_child_object);
            end
            
            switch value
                case 'raw'
                    % enables some parameter fields interactivity for parameters
                    % read in cine files that are not present in raw files
                    color = [0.8, 0.8, 0.8];
                    app.FsEditField.Editable = true;
                    app.pixheightEditField.Editable = true;
                    app.pixwidthEditField.Editable = true;
                case 'cine'
                    color = [0.5, 0.5, 0.5];
                    app.FsEditField.Editable = false;
                    app.pixheightEditField.Editable = false;
                    app.pixwidthEditField.Editable = false;
            end
            
            app.FsEditField.FontColor = color;
            app.pixheightEditField.FontColor = color;
            app.pixwidthEditField.FontColor = color;
        end

        % Value changed function: registrationf1EditField
        function registrationf1EditFieldValueChanged(app, event)
            app.compute_reg_hologram(true);
            app.show_reg_hologram();
        end

        % Value changed function: imagewidthEditField
        function imagewidthEditFieldValueChanged(app, event)
            value = app.imagewidthEditField.Value;
            app.Nx = value;
            app.reload_raw_file();
        end

        % Value changed function: imageheightEditField
        function imageheightEditFieldValueChanged(app, event)
            value = app.imageheightEditField.Value;
            app.Ny = value;
            app.reload_raw_file();
        end

        % Value changed function: bigendianCheckBox
        function bigendianCheckBoxValueChanged(app, event)
            app.reload_raw_file();
        end

        % Value changed function: registrationf2EditField
        function registrationf2EditFieldValueChanged(app, event)
            app.compute_reg_hologram(true);
            app.show_reg_hologram();
        end

        % Button pushed function: ApplyremovecomplexmaskButton
        function ApplyremovecomplexmaskButtonPushed(app, event)
            if ~app.file_loaded()
                return
            end
            
            if ~isempty(app.complex_mask)
                app.complex_mask = [];
                app.Label_2.Text = '';
                app.compute_hologram(true);
                app.compute_reg_hologram(true);
                
                app.show_hologram();
                app.show_reg_hologram();
                
                app.compute_replica_hologram(true);
                app.show_replica_hologram();
                return
            end
            
            [fname, fpath] = uigetfile('*.mat');
            if fname == 0
                return
            end
            
            load(fullfile(fpath, fname), 'complex_mask');
            if size(complex_mask) ~= [app.Nx app.Ny]
                error('complex mask has wrong size');
            end
            app.complex_mask = complex_mask;
            app.Label_2.Text = fname;
            
            app.compute_hologram(true);
            app.show_hologram();
            
            app.compute_reg_hologram(true);            
            app.show_reg_hologram();
            
            app.compute_replica_hologram(true);
            app.show_replica_hologram();
        end

        % Value changed function: DxEditField
        function DxEditFieldValueChanged(app, event)
            value = app.DxEditField.Value;
            
            x_shift = floor(app.Nx * value);
            app.DX = x_shift;
            
            if ~app.file_loaded
                return
            end
            
            app.compute_hologram(true);
            app.show_hologram();
            
            app.compute_reg_hologram(true);
            app.show_reg_hologram();
            
            app.compute_replica_hologram(true);
            app.show_replica_hologram();
        end

        % Value changed function: replicaf1EditField
        function replicaf1EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.compute_replica_hologram(true);
            app.show_replica_hologram();
        end

        % Value changed function: replicaf2EditField
        function replicaf2EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.compute_replica_hologram(true);
            app.show_replica_hologram();
        end

        % Value changed function: DyEditField
        function DyEditFieldValueChanged(app, event)
            value = app.DyEditField.Value;
            
            y_shift = floor(app.Ny * value);
            app.DY = y_shift;
            if ~app.file_loaded
                return
            end
            
            app.compute_hologram(true);
            app.show_hologram();
            
            app.compute_reg_hologram(true);
            app.show_reg_hologram();
            
            app.compute_replica_hologram(true);
            app.show_replica_hologram();
        end

        % Value changed function: horizontalaxisflipCheckBox
        function horizontalaxisflipCheckBoxValueChanged(app, event)
            app.compute_hologram(true);
            app.show_hologram();
            
            app.compute_reg_hologram(true);
            app.show_reg_hologram();
            
            app.compute_replica_hologram(true);
            app.show_replica_hologram();
        end

        % Value changed function: verticalaxisflipCheckBox
        function verticalaxisflipCheckBoxValueChanged(app, event)
            app.compute_hologram(true);
            app.show_hologram();
            
            app.compute_reg_hologram(true);
            app.show_reg_hologram();
            
            app.compute_replica_hologram(true);
            app.show_replica_hologram();
        end

        % Value changed function: SVDCheckBox
        function SVDCheckBoxValueChanged(app, event)
            value = app.SVDCheckBox.Value;
            
            if ~app.file_loaded
                return
            end
            
            app.compute_hologram(true);
            app.show_hologram();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainviewTab = uitab(app.TabGroup);
            app.MainviewTab.Title = 'Main view';
            app.FileselectionPanel = uipanel(app.MainviewTab);
            app.FileselectionPanel.Tooltip = {''};
            app.FileselectionPanel.Title = 'File selection';
            app.LoadfileButton = uibutton(app.FileselectionPanel, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file';
            app.SavevideosButton = uibutton(app.FileselectionPanel, 'push');
            app.SavevideosButton.ButtonPushedFcn = createCallbackFcn(app, @SavevideosButtonPushed, true);
            app.SavevideosButton.Text = 'Save videos';
            app.batchsizeEditFieldLabel = uilabel(app.FileselectionPanel);
            app.batchsizeEditFieldLabel.Text = 'batch size';
            app.batchsizeEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.batchsizeEditField.Limits = [1 Inf];
            app.batchsizeEditField.ValueChangedFcn = createCallbackFcn(app, @batchsizeEditFieldValueChanged, true);
            app.batchsizeEditField.Tooltip = {'Number of interferograms used to generate a single image. Increase value for better video quality.'};
            app.batchsizeEditField.Value = 512;
            app.batchstrideEditFieldLabel = uilabel(app.FileselectionPanel);
            app.batchstrideEditFieldLabel.Text = 'batch stride';
            app.batchstrideEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.batchstrideEditField.Limits = [1 Inf];
            app.batchstrideEditField.Tooltip = {'Number of interferograms to skip between two images. Increase for a shorter video.'};
            app.batchstrideEditField.Value = 1024;
            app.zEditFieldLabel = uilabel(app.FileselectionPanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.zEditField.Limits = [0 Inf];
            app.zEditField.ValueChangedFcn = createCallbackFcn(app, @zEditFieldValueChanged, true);
            app.zEditField.Tooltip = {'Reconstruction distance in mm. Can be computed automatically with the ''Autofocus'' button.'};
            app.zEditField.Value = 0.22;
            app.wavelengthEditFieldLabel = uilabel(app.FileselectionPanel);
            app.wavelengthEditFieldLabel.Text = 'wavelength';
            app.wavelengthEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.wavelengthEditField.Limits = [0 Inf];
            app.wavelengthEditField.ValueChangedFcn = createCallbackFcn(app, @wavelengthEditFieldValueChanged, true);
            app.wavelengthEditField.Tooltip = {'Laser wavelength in nanometers'};
            app.wavelengthEditField.Value = 7.85e-07;
            app.FsEditFieldLabel = uilabel(app.FileselectionPanel);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.FsEditField.Limits = [0 Inf];
            app.FsEditField.Editable = 'off';
            app.FsEditField.Tooltip = {'Video sampling frequency in Hz'};
            app.FsEditField.Value = 60000;
            app.f1Label = uilabel(app.FileselectionPanel);
            app.f1Label.Text = 'f1';
            app.f1EditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.f1EditField.Limits = [0 Inf];
            app.f1EditField.ValueChangedFcn = createCallbackFcn(app, @f1EditFieldValueChanged, true);
            app.f1EditField.Tooltip = {'Frequency lower integration bound in kHz.'};
            app.f1EditField.Value = 8;
            app.f2EditFieldLabel = uilabel(app.FileselectionPanel);
            app.f2EditFieldLabel.Text = 'f2';
            app.f2EditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.f2EditField.Limits = [0 Inf];
            app.f2EditField.ValueChangedFcn = createCallbackFcn(app, @f2EditFieldValueChanged, true);
            app.f2EditField.Tooltip = {'Frequency upper integration bound in kHz.'};
            app.f2EditField.Value = 30;
            app.pixwidthEditFieldLabel = uilabel(app.FileselectionPanel);
            app.pixwidthEditFieldLabel.Text = 'pix width';
            app.pixwidthEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.pixwidthEditField.Limits = [0 Inf];
            app.pixwidthEditField.Editable = 'off';
            app.pixwidthEditField.Tooltip = {'Camera pixel width'};
            app.pixwidthEditField.Value = 2.8e-05;
            app.pixheightEditFieldLabel = uilabel(app.FileselectionPanel);
            app.pixheightEditFieldLabel.Text = 'pix height';
            app.pixheightEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.pixheightEditField.Limits = [0 Inf];
            app.pixheightEditField.Editable = 'off';
            app.pixheightEditField.Tooltip = {'Camera pixel height'};
            app.pixheightEditField.Value = 2.8e-05;
            app.imageregistrationCheckBox = uicheckbox(app.FileselectionPanel);
            app.imageregistrationCheckBox.Text = ' image registration';
            app.imageregistrationCheckBox.Value = true;
            app.temporalfilterCheckBox = uicheckbox(app.FileselectionPanel);
            app.temporalfilterCheckBox.Text = 'temporal filter';
            app.temporalfilterCheckBox.Value = true;
            app.ParallelismDropDown = uidropdown(app.FileselectionPanel);
            app.ParallelismDropDown.Items = {'CPU multithread', 'GPU parallelization', 'CPU singlethread', 'CPU/GPU rendering/optim', ''};
            app.ParallelismDropDown.Value = 'CPU multithread';
            app.positioninfileSliderLabel = uilabel(app.FileselectionPanel);
            app.positioninfileSliderLabel.Text = {'position in file'; ''};
            app.positioninfileSlider = uislider(app.FileselectionPanel);
            app.positioninfileSlider.Limits = [0 1];
            app.positioninfileSlider.MajorTicks = [];
            app.positioninfileSlider.ValueChangedFcn = createCallbackFcn(app, @positioninfileSliderValueChanged, true);
            app.positioninfileSlider.MinorTicks = [];
            app.positioninfileSlider.Tooltip = {'Change value to display a different video timestamp in the gui.'};
            app.temporalfilterEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.temporalfilterEditField.Limits = [1 Inf];
            app.temporalfilterEditField.Tooltip = {'Laser wavelength in nanometers'};
            app.temporalfilterEditField.Value = 2;
            app.imagewidthEditFieldLabel = uilabel(app.FileselectionPanel);
            app.imagewidthEditFieldLabel.Text = 'image width';
            app.imagewidthEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.imagewidthEditField.Limits = [0 Inf];
            app.imagewidthEditField.ValueChangedFcn = createCallbackFcn(app, @imagewidthEditFieldValueChanged, true);
            app.imagewidthEditField.Tooltip = {'Image dimensions for raw files'};
            app.imagewidthEditField.Value = 512;
            app.imageheightEditFieldLabel = uilabel(app.FileselectionPanel);
            app.imageheightEditFieldLabel.Text = 'image height';
            app.imageheightEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.imageheightEditField.Limits = [0 Inf];
            app.imageheightEditField.ValueChangedFcn = createCallbackFcn(app, @imageheightEditFieldValueChanged, true);
            app.imageheightEditField.Tooltip = {'Image dimensions for raw files'};
            app.imageheightEditField.Value = 512;
            app.bigendianCheckBox = uicheckbox(app.FileselectionPanel);
            app.bigendianCheckBox.ValueChangedFcn = createCallbackFcn(app, @bigendianCheckBoxValueChanged, true);
            app.bigendianCheckBox.Text = 'big endian';
            app.saverawvideosCheckBox = uicheckbox(app.FileselectionPanel);
            app.saverawvideosCheckBox.Text = 'save raw videos';
            app.horizontalaxisflipCheckBox = uicheckbox(app.FileselectionPanel);
            app.horizontalaxisflipCheckBox.ValueChangedFcn = createCallbackFcn(app, @horizontalaxisflipCheckBoxValueChanged, true);
            app.horizontalaxisflipCheckBox.Text = 'horizontal axis flip';
            app.horizontalaxisflipCheckBox.Value = true;
            app.verticalaxisflipCheckBox = uicheckbox(app.FileselectionPanel);
            app.verticalaxisflipCheckBox.ValueChangedFcn = createCallbackFcn(app, @verticalaxisflipCheckBoxValueChanged, true);
            app.verticalaxisflipCheckBox.Text = 'vertical axis flip';
            app.AutofocusButton = uibutton(app.FileselectionPanel, 'push');
            app.AutofocusButton.ButtonPushedFcn = createCallbackFcn(app, @AutofocusButtonPushed, true);
            app.AutofocusButton.Text = 'Autofocus';
            app.SVDCheckBox = uicheckbox(app.FileselectionPanel);
            app.SVDCheckBox.ValueChangedFcn = createCallbackFcn(app, @SVDCheckBoxValueChanged, true);
            app.SVDCheckBox.Tooltip = {'Enable SVD hologram filtering in hologram construction'};
            app.SVDCheckBox.Text = 'SVD';
            app.SVDCheckBox.Value = true;
            app.CurrentFilePanel = uipanel(app.MainviewTab);
            app.CurrentFilePanel.Tooltip = {''};
            app.CurrentFilePanel.Title = 'Current file';
            app.GridLayout = uigridlayout(app.CurrentFilePanel);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.UIAxes_1 = uiaxes(app.GridLayout3);
            title(app.UIAxes_1, '')
            xlabel(app.UIAxes_1, '')
            ylabel(app.UIAxes_1, '')
            app.UIAxes_1.CLim = [0 1];
            app.UIAxes_1.XTick = [];
            app.UIAxes_1.YTick = [];
            app.Panel_2 = uipanel(app.GridLayout3);
            app.DxEditFieldLabel = uilabel(app.Panel_2);
            app.DxEditFieldLabel.Text = 'Dx';
            app.DxEditField = uieditfield(app.Panel_2, 'numeric');
            app.DxEditField.Limits = [-1 1];
            app.DxEditField.ValueChangedFcn = createCallbackFcn(app, @DxEditFieldValueChanged, true);
            app.DxEditField.Tooltip = {''};
            app.staticrelativeimageshiftLabel = uilabel(app.Panel_2);
            app.staticrelativeimageshiftLabel.Text = 'static relative image shift';
            app.DyEditFieldLabel = uilabel(app.Panel_2);
            app.DyEditFieldLabel.Text = 'Dy';
            app.DyEditField = uieditfield(app.Panel_2, 'numeric');
            app.DyEditField.Limits = [-1 1];
            app.DyEditField.ValueChangedFcn = createCallbackFcn(app, @DyEditFieldValueChanged, true);
            app.DyEditField.Tooltip = {''};
            app.InformationsPanel = uipanel(app.GridLayout);
            app.InformationsPanel.Title = 'Informations';
            app.NotesTextAreaLabel = uilabel(app.InformationsPanel);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.InformationsPanel);
            app.AdaptiveopticsPanel = uipanel(app.GridLayout);
            app.AdaptiveopticsPanel.Title = 'Adaptive optics';
            app.phaseregistrationCheckBox = uicheckbox(app.AdaptiveopticsPanel);
            app.phaseregistrationCheckBox.Tooltip = {'Activates video registration. Check this if the video is too shaky.'};
            app.phaseregistrationCheckBox.Text = 'phase registration';
            app.aberrationcompensationCheckBox = uicheckbox(app.AdaptiveopticsPanel);
            app.aberrationcompensationCheckBox.ValueChangedFcn = createCallbackFcn(app, @aberrationcompensationCheckBoxValueChanged, true);
            app.aberrationcompensationCheckBox.Tooltip = {'Activate aberration compensation via image entropy optimization. This might significantly increase the computation time.'};
            app.aberrationcompensationCheckBox.Text = 'aberration compensation';
            app.batchsizefactorCheckBox = uicheckbox(app.AdaptiveopticsPanel);
            app.batchsizefactorCheckBox.ValueChangedFcn = createCallbackFcn(app, @batchsizefactorCheckBoxValueChanged, true);
            app.batchsizefactorCheckBox.Enable = 'off';
            app.batchsizefactorCheckBox.Tooltip = {'Select a new batch stride for computing aberrations. Possible batch strides are multiples of the main stride. Checking this sets the main batch stride to the main batch size.'};
            app.batchsizefactorCheckBox.Text = '';
            app.additionalzernikeranksLabel = uilabel(app.AdaptiveopticsPanel);
            app.additionalzernikeranksLabel.Text = 'additional zernike ranks';
            app.additionalzernikeranksEditField = uieditfield(app.AdaptiveopticsPanel, 'numeric');
            app.additionalzernikeranksEditField.Limits = [0 Inf];
            app.additionalzernikeranksEditField.Enable = 'off';
            app.additionalzernikeranksEditField.Tooltip = {'Number of zernike ranks that constitue the aberration projection polynomial space'};
            app.optimDropDownLabel = uilabel(app.AdaptiveopticsPanel);
            app.optimDropDownLabel.Text = {'optim'; ''};
            app.optimDropDown = uidropdown(app.AdaptiveopticsPanel);
            app.optimDropDown.Items = {'defocus', 'astigmatism', 'defocus and astigmatism', 'defocus then astigmatism'};
            app.optimDropDown.Enable = 'off';
            app.optimDropDown.Tooltip = {'Specifies how zernike optimization is performed.'};
            app.optimDropDown.Value = 'defocus';
            app.batchsizefactorLabel = uilabel(app.AdaptiveopticsPanel);
            app.batchsizefactorLabel.Text = 'batch size factor';
            app.batchsizefactorEditField = uieditfield(app.AdaptiveopticsPanel, 'numeric');
            app.batchsizefactorEditField.Limits = [1 Inf];
            app.batchsizefactorEditField.Enable = 'off';
            app.batchsizefactorEditField.Tooltip = {''};
            app.batchsizefactorEditField.Value = 2;
            app.contrasttolSliderLabel = uilabel(app.AdaptiveopticsPanel);
            app.contrasttolSliderLabel.Text = 'contrast tol';
            app.contrasttolSlider = uislider(app.AdaptiveopticsPanel);
            app.contrasttolSlider.Limits = [0 0.0015];
            app.contrasttolSlider.Value = 0.0005;
            app.tolLabel = uilabel(app.AdaptiveopticsPanel);
            app.tolLabel.Text = 'tol';
            app.loworderzernikestolEditField = uieditfield(app.AdaptiveopticsPanel, 'numeric');
            app.loworderzernikestolEditField.Limits = [0 Inf];
            app.loworderzernikestolEditField.Tooltip = {''};
            app.loworderzernikestolEditField.Value = 0.05;
            app.tolLabel_2 = uilabel(app.AdaptiveopticsPanel);
            app.tolLabel_2.Text = 'tol';
            app.highorderzernikestolEditField = uieditfield(app.AdaptiveopticsPanel, 'numeric');
            app.highorderzernikestolEditField.Limits = [0 Inf];
            app.highorderzernikestolEditField.Tooltip = {''};
            app.highorderzernikestolEditField.Value = 0.05;
            app.masknumiterLabel = uilabel(app.AdaptiveopticsPanel);
            app.masknumiterLabel.Text = 'mask num iter';
            app.masknumiterEditField = uieditfield(app.AdaptiveopticsPanel, 'numeric');
            app.masknumiterEditField.Limits = [1 Inf];
            app.masknumiterEditField.RoundFractionalValues = 'on';
            app.masknumiterEditField.Tooltip = {''};
            app.masknumiterEditField.Value = 1;
            app.maxconstraintLabel = uilabel(app.AdaptiveopticsPanel);
            app.maxconstraintLabel.Text = {'max'; 'constraint'};
            app.lowordermaxconstraintEditField = uieditfield(app.AdaptiveopticsPanel, 'numeric');
            app.lowordermaxconstraintEditField.Limits = [1 Inf];
            app.lowordermaxconstraintEditField.RoundFractionalValues = 'on';
            app.lowordermaxconstraintEditField.Tooltip = {''};
            app.lowordermaxconstraintEditField.Value = 10;
            app.maxconstraintEditField_2Label = uilabel(app.AdaptiveopticsPanel);
            app.maxconstraintEditField_2Label.Text = {'max'; 'constraint'};
            app.highordermaxconstraintEditField = uieditfield(app.AdaptiveopticsPanel, 'numeric');
            app.highordermaxconstraintEditField.Limits = [1 Inf];
            app.highordermaxconstraintEditField.RoundFractionalValues = 'on';
            app.highordermaxconstraintEditField.Tooltip = {''};
            app.highordermaxconstraintEditField.Value = 10;
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.UIAxes_2 = uiaxes(app.GridLayout4);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.CLim = [0 1];
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.Panel = uipanel(app.GridLayout4);
            app.adaptiveopticsimagefrequencyrangeLabel = uilabel(app.Panel);
            app.adaptiveopticsimagefrequencyrangeLabel.Text = 'adaptive optics image frequency range';
            app.f1EditField_3Label = uilabel(app.Panel);
            app.f1EditField_3Label.Text = 'f1';
            app.registrationf1EditField = uieditfield(app.Panel, 'numeric');
            app.registrationf1EditField.Limits = [0 Inf];
            app.registrationf1EditField.ValueChangedFcn = createCallbackFcn(app, @registrationf1EditFieldValueChanged, true);
            app.registrationf1EditField.Tooltip = {'Frequency lower integration bound in kHz.'};
            app.registrationf1EditField.Value = 8;
            app.f2Label = uilabel(app.Panel);
            app.f2Label.Text = 'f2';
            app.registrationf2EditField = uieditfield(app.Panel, 'numeric');
            app.registrationf2EditField.Limits = [0 Inf];
            app.registrationf2EditField.ValueChangedFcn = createCallbackFcn(app, @registrationf2EditFieldValueChanged, true);
            app.registrationf2EditField.Tooltip = {'Frequency lower integration bound in kHz.'};
            app.registrationf2EditField.Value = 30;
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.UIAxes_3 = uiaxes(app.GridLayout5);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_3.CLim = [0 1];
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.Panel_3 = uipanel(app.GridLayout5);
            app.spatialfilteringimagefrequenciesLabel = uilabel(app.Panel_3);
            app.spatialfilteringimagefrequenciesLabel.Text = 'spatial filtering image frequencies';
            app.f1EditField_3Label_2 = uilabel(app.Panel_3);
            app.f1EditField_3Label_2.Text = 'f1';
            app.replicaf1EditField = uieditfield(app.Panel_3, 'numeric');
            app.replicaf1EditField.Limits = [0 Inf];
            app.replicaf1EditField.ValueChangedFcn = createCallbackFcn(app, @replicaf1EditFieldValueChanged, true);
            app.replicaf1EditField.Tooltip = {'Frequency lower integration bound in kHz.'};
            app.replicaf1EditField.Value = 8;
            app.f2Label_2 = uilabel(app.Panel_3);
            app.f2Label_2.Text = 'f2';
            app.replicaf2EditField = uieditfield(app.Panel_3, 'numeric');
            app.replicaf2EditField.Limits = [0 Inf];
            app.replicaf2EditField.ValueChangedFcn = createCallbackFcn(app, @replicaf2EditFieldValueChanged, true);
            app.replicaf2EditField.Tooltip = {'Frequency lower integration bound in kHz.'};
            app.replicaf2EditField.Value = 30;
            app.SpatialfilteringPanel = uipanel(app.GridLayout);
            app.SpatialfilteringPanel.Title = 'Spatial filtering';
            app.ApplyremovecomplexmaskButton = uibutton(app.SpatialfilteringPanel, 'push');
            app.ApplyremovecomplexmaskButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyremovecomplexmaskButtonPushed, true);
            app.ApplyremovecomplexmaskButton.Text = 'Apply/remove complex mask';
            app.Label_2 = uilabel(app.SpatialfilteringPanel);
            app.Label_2.Text = {''; ''};
            app.ShackHarmannCheckBox = uicheckbox(app.SpatialfilteringPanel);
            app.ShackHarmannCheckBox.Tooltip = {'Perform aberration compensation with Shack Hartmann simulation'};
            app.ShackHarmannCheckBox.Text = 'Shack Harmann';
            app.zernikeranksEditFieldLabel = uilabel(app.SpatialfilteringPanel);
            app.zernikeranksEditFieldLabel.Text = 'zernike ranks';
            app.zernikeranksEditField = uieditfield(app.SpatialfilteringPanel, 'numeric');
            app.zernikeranksEditField.Limits = [2 5];
            app.zernikeranksEditField.RoundFractionalValues = 'on';
            app.zernikeranksEditField.Tooltip = {'Number of zernike ranks to use for aberration correction'};
            app.zernikeranksEditField.Value = 2;
            app.numsubapEditFieldLabel = uilabel(app.SpatialfilteringPanel);
            app.numsubapEditFieldLabel.Text = 'num subap';
            app.numsubapEditField = uieditfield(app.SpatialfilteringPanel, 'numeric');
            app.numsubapEditField.Limits = [4 8];
            app.numsubapEditField.RoundFractionalValues = 'on';
            app.numsubapEditField.Tooltip = {'Number of subapertures used for Shack-Hartmann simulation'};
            app.numsubapEditField.Value = 5;
            app.subaperturemarginEditFieldLabel = uilabel(app.SpatialfilteringPanel);
            app.subaperturemarginEditFieldLabel.Text = 'subaperture margin';
            app.subaperturemarginEditField = uieditfield(app.SpatialfilteringPanel, 'numeric');
            app.subaperturemarginEditField.Limits = [0 Inf];
            app.subaperturemarginEditField.Tooltip = {'Number of subapertures used for Shack-Hartmann simulation'};
            app.subaperturemarginEditField.Value = 0.3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end