classdef holodoppler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HoloDopplerUIFigure             matlab.ui.Figure
        GridLayout4                     matlab.ui.container.GridLayout
        ImageRight                      matlab.ui.control.Image
        ImageLeft                       matlab.ui.control.Image
        CurrentFilePanel                matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        parametersPanel                 matlab.ui.container.Panel
        RenderPreviewButton             matlab.ui.control.Button
        flat_field_gwEditField          matlab.ui.control.NumericEditField
        flat_field_gwEditFieldLabel     matlab.ui.control.Label
        time_range2                     matlab.ui.control.NumericEditField
        time_range1                     matlab.ui.control.NumericEditField
        time_rangeLabel                 matlab.ui.control.Label
        time_transformDropDown          matlab.ui.control.DropDown
        time_transformDropDownLabel     matlab.ui.control.Label
        svd_thresholdEditField          matlab.ui.control.NumericEditField
        svd_thresholdEditFieldLabel     matlab.ui.control.Label
        svdx_filterCheckBox             matlab.ui.control.CheckBox
        svd_filterCheckBox              matlab.ui.control.CheckBox
        spatial_propagationEditField    matlab.ui.control.NumericEditField
        spatial_propagationEditFieldLabel  matlab.ui.control.Label
        spatial_transformationDropDown  matlab.ui.control.DropDown
        spatial_transformationDropDownLabel  matlab.ui.control.Label
        spatial_filter_range2           matlab.ui.control.NumericEditField
        spatial_filter_range1           matlab.ui.control.NumericEditField
        spatial_filter_rangeLabel       matlab.ui.control.Label
        hilbert_filterCheckBox          matlab.ui.control.CheckBox
        spatial_filterCheckBox          matlab.ui.control.CheckBox
        num_batches                     matlab.ui.control.Label
        AberrationcompensationPanel     matlab.ui.container.Panel
        imagesubapsizeratioEditField    matlab.ui.control.NumericEditField
        imagesubapsizeratioEditFieldLabel  matlab.ui.control.Label
        subapnumpositionsEditField      matlab.ui.control.NumericEditField
        subapnumpositionsEditFieldLabel  matlab.ui.control.Label
        referenceimageDropDown          matlab.ui.control.DropDown
        referenceimageDropDownLabel     matlab.ui.control.Label
        ZernikeProjectionCheckBox       matlab.ui.control.CheckBox
        savecoefsCheckBox               matlab.ui.control.CheckBox
        rangeYEditField                 matlab.ui.control.NumericEditField
        rangeYEditFieldLabel            matlab.ui.control.Label
        rangeZEditField                 matlab.ui.control.NumericEditField
        rangeZEditFieldLabel            matlab.ui.control.Label
        volumesizeEditField             matlab.ui.control.NumericEditField
        volumesizeEditFieldLabel        matlab.ui.control.Label
        maxSubAp_PCAEditField           matlab.ui.control.NumericEditField
        maxEditField_2Label             matlab.ui.control.Label
        minSubAp_PCAEditField           matlab.ui.control.NumericEditField
        minEditField_2Label             matlab.ui.control.Label
        SubAp_PCACheckBox               matlab.ui.control.CheckBox
        PreviewLabel                    matlab.ui.control.Label
        masknumiterEditField            matlab.ui.control.NumericEditField
        masknumiterEditFieldLabel       matlab.ui.control.Label
        maxconstraintEditField          matlab.ui.control.NumericEditField
        maxconstraintEditFieldLabel     matlab.ui.control.Label
        zernikestolEditField            matlab.ui.control.NumericEditField
        toleranceLabel                  matlab.ui.control.Label
        optimizationzernikeranksEditField  matlab.ui.control.NumericEditField
        zernikeranksEditField_2Label    matlab.ui.control.Label
        IterativeoptimizationCheckBox   matlab.ui.control.CheckBox
        subaperturemarginEditField      matlab.ui.control.NumericEditField
        subaperturemarginEditFieldLabel  matlab.ui.control.Label
        shackhartmannzernikeranksEditField  matlab.ui.control.NumericEditField
        zernikeranksEditFieldLabel      matlab.ui.control.Label
        ShackHartmannCheckBox           matlab.ui.control.CheckBox
        Label_2                         matlab.ui.control.Label
        UIAxes_aberrationPreview        matlab.ui.control.UIAxes
        AdvancedProcessingPanel         matlab.ui.container.Panel
        SVDTresholdEditFieldLabel_2     matlab.ui.control.Label
        SVDStrideEditField              matlab.ui.control.NumericEditField
        SVDThresholdCheckBox            matlab.ui.control.CheckBox
        SVDTresholdEditFieldLabel       matlab.ui.control.Label
        SVDThresholdEditField           matlab.ui.control.NumericEditField
        SVDxCheckBox                    matlab.ui.control.CheckBox
        SVDx_SubApEditField             matlab.ui.control.NumericEditField
        SVDx_SubApEditFieldLabel        matlab.ui.control.Label
        xystrideEditField               matlab.ui.control.NumericEditField
        xystrideEditFieldLabel          matlab.ui.control.Label
        r1EditField                     matlab.ui.control.NumericEditField
        r1EditFieldLabel                matlab.ui.control.Label
        unitcellsinlatticeEditField     matlab.ui.control.NumericEditField
        unitcellsinlatticeEditFieldLabel  matlab.ui.control.Label
        nu2EditField                    matlab.ui.control.NumericEditField
        nu2EditFieldLabel               matlab.ui.control.Label
        nu1EditField                    matlab.ui.control.NumericEditField
        nu1EditFieldLabel               matlab.ui.control.Label
        phi2EditField                   matlab.ui.control.NumericEditField
        phi2EditFieldLabel              matlab.ui.control.Label
        phi1EditField                   matlab.ui.control.NumericEditField
        phi1EditFieldLabel              matlab.ui.control.Label
        LocalfilteringLabel             matlab.ui.control.Label
        temporalCheckBox                matlab.ui.control.CheckBox
        spatialCheckBox                 matlab.ui.control.CheckBox
        SVDCheckBox                     matlab.ui.control.CheckBox
        FileselectionPanel              matlab.ui.container.Panel
        positioninfileSlider            matlab.ui.control.Slider
        positioninfileSliderLabel       matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        ppx                             matlab.ui.control.NumericEditField
        ppxy_label                      matlab.ui.control.Label
        ppy                             matlab.ui.control.NumericEditField
        setUpDropDown                   matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        parfor_arg                      matlab.ui.control.Spinner
        numworkersSpinnerLabel          matlab.ui.control.Label
        NotesTextArea                   matlab.ui.control.TextArea
        NotesTextAreaLabel              matlab.ui.control.Label
        fs                              matlab.ui.control.NumericEditField
        fsEditFieldLabel                matlab.ui.control.Label
        ParallelismDropDown             matlab.ui.control.DropDown
        lambda                          matlab.ui.control.NumericEditField
        wavelengthEditFieldLabel        matlab.ui.control.Label
        LoadfileButton                  matlab.ui.control.Button
        renderingPanel                  matlab.ui.container.Panel
        Image_typesListBox              matlab.ui.control.ListBox
        Image_typesListBoxLabel         matlab.ui.control.Label
        DxEditFieldLabel_2              matlab.ui.control.Label
        regDiscRatioEditField           matlab.ui.control.NumericEditField
        batch_size_registration         matlab.ui.control.NumericEditField
        refbatchsizeEditFieldLabel      matlab.ui.control.Label
        DyEditField                     matlab.ui.control.NumericEditField
        DyEditFieldLabel                matlab.ui.control.Label
        DxEditField                     matlab.ui.control.NumericEditField
        DxEditFieldLabel                matlab.ui.control.Label
        batch_stride                    matlab.ui.control.NumericEditField
        batchstrideEditFieldLabel       matlab.ui.control.Label
        imageregistrationCheckBox       matlab.ui.control.CheckBox
        SaveconfigButton                matlab.ui.control.Button
        showrefCheckBox                 matlab.ui.control.CheckBox
        iterativeregistrationCheckBox   matlab.ui.control.CheckBox
        saveconfigLamp                  matlab.ui.control.Lamp
        ApplytoallfilesCheckBox         matlab.ui.control.CheckBox
        VideoRenderingButton            matlab.ui.control.Button
        FoldermanagementButton          matlab.ui.control.Button
        temporalfilterEditField         matlab.ui.control.NumericEditField
        temporalfilterCheckBox          matlab.ui.control.CheckBox
        phaseregistrationCheckBox       matlab.ui.control.CheckBox
        rephasingCheckBox               matlab.ui.control.CheckBox
    end


    properties (Access = public)
        file_loaded
        HD %HoloDopplerClass

        drawer_list = {}
    end

    properties (Access = private)
        DialogApp % Dialog box app
        poolobj
    end
    methods (Access = public)
        
        function show_aberration_correction(app)
            if ~app.file_loaded
                return;
            end

            if app.ShackHartmannCheckBox.Value
                imshow(mat2gray(app.phasePlane), 'Parent', app.UIAxes_aberrationPreview,...
                    'XData', [1 app.UIAxes_aberrationPreview.Position(3)], ...
                    'YData', [1 app.UIAxes_aberrationPreview.Position(4)]);
            end
        end
        
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if exist("version.txt",'file')
                v = readlines('version.txt');
                fprintf("==========================================\n " + ...
                    "Welcome to HoloDoppler %s\n" + ...
                    "------------------------------------------\n" + ...
                    "Developed by the DigitalHolographyFoundation\n" + ...
                    "==========================================\n",v(1));
            end
            % add subfolders to path
            
            addpath('New Folder\',"ReaderClasses\"); % dossier à ajouter au research path matlab pour utiliser correctement l'application

            app.HD = HoloDopplerClass();

            
        end

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            %% open file and manage file extension
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            if (isempty(app.HD.file))
                [fname, fpath] = uigetfile('*.raw;*.cine;*.holo');
            else
                [fname, fpath] = uigetfile(strcat(app.HD.file.path, "*.raw;*.cine;*.holo"));
            end
            delete(f); %delete the dummy figure
            if fname == 0
                return
            end
            %config = fetch_config(fpath, fname);
            
            app.HD.LoadFile(fullfile(fpath, fname));
        end

        % Callback function: not associated with a component
        function spatial_propagationValueChanged(app, event)
            zChanged(app);
        end

        % Callback function: not associated with a component
        function f1EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            app.time_transform.f1 = app.f1EditField.Value;
        end

        % Callback function: not associated with a component
        function f2EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            app.time_transform.f2 = app.f2EditField.Value;
        end

        % Callback function: not associated with a component
        function wavelengthEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            app.compute_kernel(false);
        end

        % Callback function: not associated with a component
        function batchsizeEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            app.renderLamp.Color = [1, 0, 0];
            drawnow;

            app.positioninfileSlider.Limits = [0, double(app.interferogram_stream.num_frames-app.batchsizeEditField.Value)];
            app.positioninfileSlider.Value = min(app.positioninfileSlider.Value, app.positioninfileSlider.Limits(2));
            app.EditField.Limits = [0, double(app.interferogram_stream.num_frames-app.batchsizeEditField.Value)];
            app.EditField.Value = min(app.EditField.Value, app.EditField.Limits(2));
            app.max_PCAEditField.Limits = [0, double(app.batchsizeEditField.Value)];

            app.frame_batch = app.interferogram_stream.read_frame_batch(app.batchsizeEditField.Value, 0);

            app.renderLamp.Color = [0, 1, 0];
            drawnow;
        end

        % Button pushed function: VideoRenderingButton
        function VideoRenderingButtonPushed(app, event)
            if strcmp(app.ParallelismDropDown.Value,"LightGPU")
                LightRendervideoGPU(app);
            else
                Rendervideo(app);
            end
        end

        % Callback function: not associated with a component
        function AutofocusButtonPushed(app, event)
            Autofocus(app);
        end

        % Close request function: HoloDopplerUIFigure
        function HoloDopplerUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: positioninfileSlider
        function positioninfileSliderValueChanged(app, event)
            value = app.positioninfileSlider.Value;
            app.EditField.Value = value;

            if ~app.file_loaded
                return
            end

            Renderpreview(app)
            app.renderLamp.Color = [1, 0, 0];

            drawnow;

            app.compute_kernel(false);
            app.renderLamp.Color = [0, 1, 0];
            drawnow;
        end

        % Value changed function: DxEditField
        function DxEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            value = app.DxEditField.Value;

            x_shift = floor(app.Nx * value);
            app.DX = x_shift;
        end

        % Value changed function: DyEditField
        function DyEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            value = app.DyEditField.Value;

            y_shift = floor(app.Ny * value);
            app.DY = y_shift;
        end

        % Callback function
        function compositef1EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            %
            %             color_f1 = app.compositef1EditField.Value;
            %             color_f2 = app.compositef2EditField.Value;
            %             color_f3 = app.compositef3EditField.Value;

            %             if ~app.showSpatialFilterCheckBox.Value
            %                 app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f1, color_f2);
            %                 app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f2, color_f3);
            %             else
            %                 app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f2, color_f3);
            %                 app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f1, color_f2);
            %             end
        end

        % Callback function
        function compositef2EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            %
            %             color_f1 = app.compositef1EditField.Value;
            %             color_f2 = app.compositef2EditField.Value;
            %             color_f3 = app.compositef3EditField.Value;

            %             if ~app.showSpatialFilterCheckBox.Value
            %                 app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f1, color_f2);
            %                 app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f2, color_f3);
            %             else
            %                 app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f2, color_f3);
            %                 app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f1, color_f2);
            %             end
        end

        % Callback function: not associated with a component
        function compositef3EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            % color_f1 = app.compositef1EditField.Value;
            % color_f2 = app.compositef2EditField.Value;
            % color_f3 = app.compositef3EditField.Value;

            %             if ~app.showSpatialFilterCheckBox.Value
            %                 app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f1, color_f2);
            %                 app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f2, color_f3);
            %             else
            %                 app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f2, color_f3);
            %                 app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f1, color_f2);
            %             end
        end

        % Callback function: not associated with a component
        function showSpatialFilterCheckBoxValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            % color_f1 = app.compositef1EditField.Value;
            % color_f2 = app.compositef2EditField.Value;
            % color_f3 = app.compositef3EditField.Value;

            %             if ~app.showSpatialFilterCheckBox.Value
            %                 app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f1, color_f2);
            %                 app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f2, color_f3);
            %             else
            %                 app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f2, color_f3);
            %                 app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f1, color_f2);
            %             end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.positioninfileSlider.Value = value;app.rephasing_data

            if ~app.file_loaded
                return
            end

            app.frame_batch = app.interferogram_stream.read_frame_batch(app.batchsizeEditField.Value, floor(app.positioninfileSlider.Value));
            app.compute_kernel(false);
        end

        % Value changed function: rephasingCheckBox
        function rephasingCheckBoxValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            if app.rephasingCheckBox.Value
                try
                    [rephasing_data, rephasing_found] = fetch_rephasing_data(app.filepath, app.filename, app.extension);
                catch
                    rephasing_data = [];
                    rephasing_found = false;
                end

                if rephasing_found
                    app.rephasing_data = rephasing_data;

                    app.NotesTextArea.Value = {''};
                    for i = 1:numel(rephasing_data)
                        r = rephasing_data(i);
                        reg = ~isempty(r.aberration_correction.rephasing_zernike_coefs);
                        shack = ~isempty(r.aberration_correction.shack_hartmann_zernike_coefs);
                        opt = ~isempty(r.aberration_correction.iterative_opt_zernike_coefs);
                        text = sprintf('Run %d:\nsize = %d, stride = %d, reg = %s, shack = %s, opt = %s', i, r.batch_size, r.batch_stride, string(reg), string(shack), string(opt));

                        % compute mean correction
                        if shack
                            mean_1 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(1,:));
                            mean_2 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(2,:));
                            mean_3 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(3,:));
                        end

                        if opt
                            mean_1 = mean(r.aberration_correction.iterative_opt_zernike_coefs(1,:));
                            mean_2 = mean(r.aberration_correction.iterative_opt_zernike_coefs(2,:));
                            mean_3 = mean(r.aberration_correction.iterative_opt_zernike_coefs(3,:));
                        end

                        if shack && opt
                            mean_1 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(1,:) + r.aberration_correction.iterative_opt_zernike_coefs(1,:));
                            mean_2 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(2,:) + r.aberration_correction.iterative_opt_zernike_coefs(2,:));
                            mean_3 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(3,:) + r.aberration_correction.iterative_opt_zernike_coefs(3,:));
                        end

                        if shack || opt
                            text = sprintf('%s\nastig 1 = %0.1f, defocus = %0.1f, astig 2 = %0.1f', text, mean_1, mean_2, mean_3);
                        end

                        text = sprintf('%s\n',text);

                        app.NotesTextArea.Value = [{text}; app.NotesTextArea.Value];
                    end
                    app.NotesTextArea.Value = [{'Rephasing found:'}; app.NotesTextArea.Value];
                else
                    app.rephasing_data = [];
                    app.NotesTextArea.Value = {''};
                end
            end
        end

        % Callback function: not associated with a component
        function ImageChoiceDropDownValueChanged(app, event)
            value = app.ImageChoiceDropDown.Value;

            switch value
                case 'power Doppler'
                    app.f1EditField.Visible = true;
                    app.f2EditField.Visible = true;

                    app.compositef1EditField.Visible = false;
                    app.compositef2EditField.Visible = false;
                    app.compositef3EditField.Visible = false;
                    app.compositef3EditFieldLabel.Visible = false;

                    %                     app.LowfrequencyrangeLabel.Visible = false;
                    %                     app.HighfrequencyrangeLabel.Visible = false;

                case 'color Doppler'
                    app.f1EditField.Visible = false;
                    app.f2EditField.Visible = false;

                    app.compositef1EditField.Visible = true;
                    app.compositef2EditField.Visible = true;
                    app.compositef3EditField.Visible = true;
                    app.compositef3EditFieldLabel.Visible = true;

                    %                     app.LowfrequencyrangeLabel.Visible = true;
                    %                     app.HighfrequencyrangeLabel.Visible = true;

                case 'directional Doppler'
                    app.f1EditField.Visible = true;
                    app.f2EditField.Visible = true;

                    app.compositef1EditField.Visible = false;
                    app.compositef2EditField.Visible = false;
                    app.compositef3EditField.Visible = false;
                    app.compositef3EditFieldLabel.Visible = false;

                    %                     app.LowfrequencyrangeLabel.Visible = false;
                    %                     app.HighfrequencyrangeLabel.Visible = false;

                case 'velocity estimate'
                    app.f1EditField.Visible = true;
                    app.f2EditField.Visible = true;

                    app.compositef1EditField.Visible = false;
                    app.compositef2EditField.Visible = false;
                    app.compositef3EditField.Visible = false;
                    app.compositef3EditFieldLabel.Visible = false;

                    %                     app.LowfrequencyrangeLabel.Visible = false;
                    %                     app.HighfrequencyrangeLabel.Visible = false;

                case 'phase variation'
                    app.f1EditField.Visible = true;
                    app.f2EditField.Visible = true;

                    app.compositef1EditField.Visible = false;
                    app.compositef2EditField.Visible = false;
                    app.compositef3EditField.Visible = false;
                    app.compositef3EditFieldLabel.Visible = false;

                    %                     app.LowfrequencyrangeLabel.Visible = false;
                    %                     app.HighfrequencyrangeLabel.Visible = false;

                case 'dark field image'
                    app.f1EditField.Visible = true;
                    app.f2EditField.Visible = true;

                    app.compositef1EditField.Visible = false;
                    app.compositef2EditField.Visible = false;
                    app.compositef3EditField.Visible = false;
                    app.compositef3EditFieldLabel.Visible = false;

                    %                     app.LowfrequencyrangeLabel.Visible = false;
                    %                     app.HighfrequencyrangeLabel.Visible = false;

                case 'spectrogram'
                    app.f1EditField.Visible = true;
                    app.f2EditField.Visible = true;

                    app.compositef1EditField.Visible = false;
                    app.compositef2EditField.Visible = false;
                    app.compositef3EditField.Visible = false;
                    app.compositef3EditFieldLabel.Visible = false;

                    %                     app.LowfrequencyrangeLabel.Visible = false;
                    %                     app.HighfrequencyrangeLabel.Visible = false;
                otherwise
                    % unreachable
            end
        end

        % Callback function: not associated with a component
        function blurEditFieldValueChanged(app, event)
            value = app.blurEditField.Value;
            app.blur = value;

            if ~app.file_loaded
                return
            end
        end

        % Callback function: not associated with a component
        function spatialTransformationChanged(app, event)
            app.compute_kernel(false);
        end

        % Callback function: not associated with a component
        function setUpChanged(app, event)
            app.rangeYEditField.Visible = false;
            app.rangeYEditFieldLabel.Visible = false;
            app.rangeZEditField.Visible = false;
            app.rangeZEditFieldLabel.Visible = false;
            app.volumesizeEditField.Visible = false;
            app.volumesizeEditFieldLabel.Visible = false;
            app.imagetypesDropDown.Enable = true;
        end

        % Button pushed function: SaveconfigButton
        function SaveconfigButtonPushed(app, event)
            app.saveconfigLamp.Color = [1, 0, 0];
            drawnow;
            config = GuiCache(app);
            if app.ApplytoallfilesCheckBox.Value
                files = dir(fullfile(app.filepath, '*.holo'));
                files = cat(1, files, dir(fullfile(app.filepath, '*.cine')));
                files = cat(1, files, dir(fullfile(app.filepath, '*.raw')));
                for n = 1:length(files)
                    [~, file_name, ~] = fileparts(files(n).name);
                    file_name = sprintf("%s-config", file_name);
                    [file_name, suffix] = get_last_file_name(app.filepath, file_name, 'mat');
                    save(sprintf('%s%s_%d.mat', app.filepath, file_name, suffix + 1), "config");
                end
            else
                [~, file_name, ~] = fileparts(app.filename);
                file_name = sprintf("%s-config", file_name);
                [file_name, suffix] = get_last_file_name(app.filepath, file_name, 'mat');
                save(sprintf('%s%s_%d.mat', app.filepath, file_name, suffix + 1), "config");
            end
            app.saveconfigLamp.Color = [0, 1, 0];
        end

        % Callback function
        function PCACheckBoxValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            app.PCA.Value = app.PCACheckBox.Value;
            app.PCA.min = app.min_PCAEditField.Value;
            app.PCA.max = app.max_PCAEditField.Value;
        end

        % Value changed function: SubAp_PCACheckBox
        function SubAp_PCACheckBoxValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            app.SubAp_PCA.Value = app.SubAp_PCACheckBox.Value;
            app.SubAp_PCA.min = app.minSubAp_PCAEditField.Value;
            app.SubAp_PCA.max = app.maxSubAp_PCAEditField.Value;
        end

        % Value changed function: minSubAp_PCAEditField
        function minSubApPCAEditField_2ValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            app.SubAp_PCA.min = app.minSubAp_PCAEditField.Value;
        end

        % Value changed function: maxSubAp_PCAEditField
        function maxSubAp_PCAEditField_2_2ValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            app.SubAp_PCA.max = app.maxSubAp_PCAEditField.Value;
        end

        % Callback function: not associated with a component
        function min_PCAEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            app.time_transform.min_PCA = app.min_PCAEditField.Value;
        end

        % Callback function: not associated with a component
        function max_PCAEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            app.time_transform.max_PCA = app.max_PCAEditField.Value;
        end

        % Callback function: not associated with a component
        function TimeTransform(app, event)
            value = app.timetransformDropDown.Value;

            switch value
                case 'FFT'
                    app.time_transform.type = 'FFT';
                    % for now this is range of interest
                    app.time_transform.f1 = app.f1EditField.Value;
                    app.time_transform.f2 = app.f2EditField.Value;

                    app.ImageChoiceDropDown.Visible = true;
                    app.f1EditField.Visible = true;
                    app.f2EditField.Visible = true;
                    app.f1EditFieldLabel.Visible = true;
                    app.f2EditFieldLabel.Visible = true;

                    app.min_PCAEditField.Visible = false;
                    app.max_PCAEditField.Visible = false;
                    app.minEditFieldLabel.Visible = false;
                    app.maxEditFieldLabel.Visible = false;

                    if (strcmp(app.ImageChoiceDropDown.Value, 'color Doppler'))
                        app.f1EditField.Visible = false;
                        app.f2EditField.Visible = false;
                        app.f1EditFieldLabel.Visible = false;
                        app.f2EditFieldLabel.Visible = false;
                        app.compositef1EditField.Visible = true;
                        app.compositef1EditFieldLabel.Visible = true;
                        app.compositef2EditField.Visible = true;
                        app.compositef2EditFieldLabel.Visible = true;
                        app.compositef3EditField.Visible = true;
                        app.compositef3EditFieldLabel.Visible = true;

                        %                         app.LowfrequencyrangeLabel.Visible = true;
                        %                         app.HighfrequencyrangeLabel.Visible = true;
                    end

                case 'PCA'
                    app.time_transform.type = 'PCA';
                    app.time_transform.min_PCA = app.min_PCAEditField.Value;
                    app.time_transform.max_PCA = app.max_PCAEditField.Value;

                    app.ImageChoiceDropDown.Visible = false;
                    app.f1EditField.Visible = false;
                    app.f2EditField.Visible = false;
                    app.f1EditFieldLabel.Visible = false;
                    app.f2EditFieldLabel.Visible = false;

                    app.min_PCAEditField.Visible = true;
                    app.max_PCAEditField.Visible = true;
                    app.minEditFieldLabel.Visible = true;
                    app.maxEditFieldLabel.Visible = true;

                    app.compositef1EditField.Visible = false;
                    app.compositef2EditField.Visible = false;
                    app.compositef3EditField.Visible = false;
                    app.compositef3EditFieldLabel.Visible = false;

                    %                     app.LowfrequencyrangeLabel.Visible = false;
                    %                     app.HighfrequencyrangeLabel.Visible = false;
            end
        end

        % Callback function: not associated with a component
        function ZSwitchValueChanged(app, event)
            if ~app.file_loaded
                return
            end

            if app.Switch.Value == "z_iris"
                app.z_reconstruction = app.zirisEditField.Value;
            else
                app.z_reconstruction = app.spatial_propagation.Value;
            end

            app.compute_kernel(false);
        end

        % Callback function: not associated with a component
        function zirisEditFieldValueChanged(app, event)
            zChanged(app);
        end

        % Callback function: not associated with a component
        function outputVideo(app, event)
            value = app.imagetypesDropDown.Value;
            if strcmp(value,'dark field')
                app.Switch.Value = 'z_retina';
                ZSwitchValueChanged(app, event);
            end
        end

        % Callback function: not associated with a component
        function RenderpreviewButtonPushed(app, event)
            Renderpreview(app);
        end

        % Window key press function: HoloDopplerUIFigure
        function HoloDopplerUIFigureWindowKeyPress(app, event)
            switch event.Key
                case 'return'
                    app.RenderpreviewButtonPushed();
            end
        end

        % Button pushed function: FoldermanagementButton
        function FoldermanagementButtonPushed(app, event)
            d = dialog('Position', [300, 300, 750, 90 + length(app.drawer_list) * 14],...
                'Color', [0.2, 0.2, 0.2],...
                'Name', 'Folder management',...
                'Resize', 'on',...
                'WindowStyle', 'normal');

            txt = uicontrol('Parent', d,...
                'Style', 'text',...
                'FontName', 'Helvetica',...
                'BackgroundColor', [0.2, 0.2, 0.2],...
                'ForegroundColor', [0.8, 0.8, 0.8],...
                'Position', [20, 70, 710, length(app.drawer_list) * 14],...
                'HorizontalAlignment', 'left',...
                'String', app.drawer_list);

            uicontrol('Parent', d,...
                'Position', [20, 20, 100, 25],...
                'FontName', 'Helvetica',...
                'BackgroundColor', [0.5, 0.5, 0.5],...
                'ForegroundColor', [0.9 0.9 0.9],...
                'FontWeight', 'bold',...
                'String', 'Select folder',...
                'Callback', @select);

            uicontrol('Parent', d,...
                'Position', [140, 20, 100, 25],...
                'FontName', 'Helvetica',...
                'BackgroundColor', [0.5, 0.5, 0.5],...
                'ForegroundColor', [0.9 0.9 0.9],...
                'FontWeight', 'bold',...
                'String', 'Clear list',...
                'Callback', @clear_drawer);

            uicontrol('Parent', d,...
                'Position', [260, 20, 100, 25],...
                'FontName', 'Helvetica',...
                'BackgroundColor', [0.5, 0.5, 0.5],...
                'ForegroundColor', [0.9 0.9 0.9],...
                'FontWeight', 'bold',...
                'String', 'Render',...
                'Callback', @render);

            uiwait(d);

            function select(~, ~)
                selected_dir = uigetdir();
                if (selected_dir)
                    app.drawer_list{end + 1} = strcat(selected_dir, '\');
                end
                txt.String = app.drawer_list;
                d.Position(4) = 100 + length(app.drawer_list) * 14;
                txt.Position(4) = length(app.drawer_list) * 14;
            end

            function clear_drawer(~, ~)
                app.drawer_list = {};
                txt.String = app.drawer_list;
                d.Position(4) = 100 + length(app.drawer_list) * 14;
                txt.Position(4) = length(app.drawer_list) * 14;
            end

            function render(~, ~)
                file_list = {};
                for it_1 = 1:length(app.drawer_list) % Circles through the folders added by the user
                    FolderInfo = dir(app.drawer_list{it_1});
                    for it_2 = 1:length(FolderInfo) % Circles through the files of said folder
                        if (regexp(FolderInfo(it_2).name, "^[A-Za-z0-9_]+\.(holo|cine|raw)$")) % If the file is processable
                            config_list = get_all_config_file(app.drawer_list{it_1}, FolderInfo(it_2).name); % Get config files
                            if (~isempty(config_list))
                                file_list{end + 1} = {app.drawer_list{it_1}, FolderInfo(it_2).name, config_list}; %#ok<AGROW>
                            end
                        end
                    end
                end

                for i = 1:length(file_list)
                    if (~isempty(file_list{i}{3}))
                        for j = 1:length(file_list{i}{3})
                            config = load(fullfile(file_list{i}{1}, file_list{i}{3}{j}));
                            Loadfile(app,file_list{i}{2}, file_list{i}{1}, config.config);
                            app.VideoRenderingButtonPushed();
                        end
                    end
                end
            end
            delete(d);
        end

        % Callback function: not associated with a component
        function SavePreview(app, event)
            Savepreview(app);
        end

        % Value changed function: SVDThresholdCheckBox
        function SVDThresholdCheckBoxValueChanged(app, event)
            
            if app.SVDThresholdCheckBox.Value
                app.SVDThresholdEditField.Enable = true;
                
                app.SVDThresholdEditField.Value = round(app.f1EditField.Value * app.batchsizeEditField.Value / app.fs.Value)*2 + 1;
            else
                app.SVDThresholdEditField.Enable = false;
            end


        end

        % Value changed function: rangeZEditField
        function rangeZEditFieldValueChanged(app, event)
            value = app.rangeZEditField.Value;
            
        end

        % Value changed function: rangeYEditField
        function rangeYEditFieldValueChanged(app, event)
            value = app.rangeYEditField.Value;
            
        end

        % Image clicked function: ImageLeft
        function ImageLeftClicked(app, event)
            figure(332)
            imshow(app.ImageLeft.ImageSource);
            h=drawfreehand();
            try
                app.mask = createMask(h);
            end
        end

        % Image clicked function: ImageRight
        function ImageRightClicked(app, event)
            
        end

        % Value changed function: imageregistrationCheckBox
        function imageregistrationCheckBoxValueChanged(app, event)
            value = app.imageregistrationCheckBox.Value;
            app.batch_size_registration.Enable = value;
        end

        % Callback function: not associated with a component
        function ShowSpectrumButtonPushed(app, event)
            Show_spectrum(app);
        end

        % Value changed function: SVDThresholdEditField
        function SVDThresholdEditFieldValueChanged(app, event)
            value = app.SVDThresholdEditField.Value;
            if value > app.batchsizeEditField.Value 
                app.SVDThresholdEditField.Value = app.batchsizeEditField.Value ;
                disp("SVD Threshold should be smaller than the batch size.")
            end
        end

        % Callback function: not associated with a component
        function scan3DButtonPushed(app, event)
            Show_3D_scan(app);
        end

        % Value changed function: regDiscRatioEditField
        function registrationdiscCheckBoxValueChanged(app, event)
            value = app.registrationdiscCheckBox.Value;
            show_ref_disc(app,value);
        end

        % Callback function: not associated with a component
        function spatialfilterratioValueChanged(app, event)
            app.spatial_filter_mask = diskMask(app.Nx, app.Ny, app.spatialfilterratio.Value);
        end

        % Callback function: not associated with a component
        function ppxValueChanged(app, event)
            app.pix_height = app.ppy.Value;
            app.pix_width = app.ppx.Value;
            zChanged(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.HoloDopplerUIFigure = uifigure('Visible', 'off');
            app.HoloDopplerUIFigure.Name = 'HoloDoppler';
            app.HoloDopplerUIFigure.Icon = fullfile(pathToMLAPP, 'holowaves_logo_temp.png');
            app.HoloDopplerUIFigure.CloseRequestFcn = createCallbackFcn(app, @HoloDopplerUIFigureCloseRequest, true);
            app.HoloDopplerUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @HoloDopplerUIFigureWindowKeyPress, true);
            app.GridLayout4 = uigridlayout(app.HoloDopplerUIFigure);
            app.FileselectionPanel = uipanel(app.GridLayout4);
            app.FileselectionPanel.Tooltip = {'Select a reconstruction algorithm that minimizes memory footprint during post processing'};
            app.FileselectionPanel.Title = 'File selection';
            app.renderingPanel = uipanel(app.FileselectionPanel);
            app.renderingPanel.Title = 'rendering';
            app.rephasingCheckBox = uicheckbox(app.renderingPanel);
            app.rephasingCheckBox.ValueChangedFcn = createCallbackFcn(app, @rephasingCheckBoxValueChanged, true);
            app.rephasingCheckBox.Visible = 'off';
            app.rephasingCheckBox.Text = 'rephasing';
            app.rephasingCheckBox.Value = true;
            app.phaseregistrationCheckBox = uicheckbox(app.renderingPanel);
            app.phaseregistrationCheckBox.Visible = 'off';
            app.phaseregistrationCheckBox.Tooltip = {'Activates video registration. Check this if the video is too shaky.'};
            app.phaseregistrationCheckBox.Text = 'phase registration';
            app.temporalfilterCheckBox = uicheckbox(app.renderingPanel);
            app.temporalfilterCheckBox.Visible = 'off';
            app.temporalfilterCheckBox.Text = 'temporal filter';
            app.temporalfilterEditField = uieditfield(app.renderingPanel, 'numeric');
            app.temporalfilterEditField.Limits = [1 Inf];
            app.temporalfilterEditField.Visible = 'off';
            app.temporalfilterEditField.Tooltip = {'Laser wavelength in nanometers'};
            app.temporalfilterEditField.Value = 2;
            app.FoldermanagementButton = uibutton(app.renderingPanel, 'push');
            app.FoldermanagementButton.ButtonPushedFcn = createCallbackFcn(app, @FoldermanagementButtonPushed, true);
            app.FoldermanagementButton.Visible = 'off';
            app.FoldermanagementButton.Text = 'Folder management';
            app.VideoRenderingButton = uibutton(app.renderingPanel, 'push');
            app.VideoRenderingButton.ButtonPushedFcn = createCallbackFcn(app, @VideoRenderingButtonPushed, true);
            app.VideoRenderingButton.Text = 'Video Rendering';
            app.ApplytoallfilesCheckBox = uicheckbox(app.renderingPanel);
            app.ApplytoallfilesCheckBox.Visible = 'off';
            app.ApplytoallfilesCheckBox.Text = 'Apply to all files';
            app.saveconfigLamp = uilamp(app.renderingPanel);
            app.saveconfigLamp.Visible = 'off';
            app.iterativeregistrationCheckBox = uicheckbox(app.renderingPanel);
            app.iterativeregistrationCheckBox.Visible = 'off';
            app.iterativeregistrationCheckBox.Tooltip = {'''Activates video registration. Check this if the video is too shaky.'''};
            app.iterativeregistrationCheckBox.Text = 'iterative registration';
            app.showrefCheckBox = uicheckbox(app.renderingPanel);
            app.showrefCheckBox.Visible = 'off';
            app.showrefCheckBox.Text = 'show ref';
            app.SaveconfigButton = uibutton(app.renderingPanel, 'push');
            app.SaveconfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveconfigButtonPushed, true);
            app.SaveconfigButton.Visible = 'off';
            app.SaveconfigButton.Text = 'Save config';
            app.imageregistrationCheckBox = uicheckbox(app.renderingPanel);
            app.imageregistrationCheckBox.ValueChangedFcn = createCallbackFcn(app, @imageregistrationCheckBoxValueChanged, true);
            app.imageregistrationCheckBox.Text = 'image registration';
            app.batchstrideEditFieldLabel = uilabel(app.renderingPanel);
            app.batchstrideEditFieldLabel.Text = 'batch stride';
            app.batch_stride = uieditfield(app.renderingPanel, 'numeric');
            app.batch_stride.Limits = [0 Inf];
            app.batch_stride.ValueDisplayFormat = '%.0f';
            app.batch_stride.Tooltip = {'Number of interferograms to skip between two images. Increase for a shorter video.'};
            app.DxEditFieldLabel = uilabel(app.renderingPanel);
            app.DxEditFieldLabel.Visible = 'off';
            app.DxEditFieldLabel.Text = 'Dx';
            app.DxEditField = uieditfield(app.renderingPanel, 'numeric');
            app.DxEditField.Limits = [-1 1.01];
            app.DxEditField.ValueChangedFcn = createCallbackFcn(app, @DxEditFieldValueChanged, true);
            app.DxEditField.Visible = 'off';
            app.DxEditField.Tooltip = {''};
            app.DyEditFieldLabel = uilabel(app.renderingPanel);
            app.DyEditFieldLabel.Visible = 'off';
            app.DyEditFieldLabel.Text = 'Dy';
            app.DyEditField = uieditfield(app.renderingPanel, 'numeric');
            app.DyEditField.Limits = [-1 1.01];
            app.DyEditField.ValueChangedFcn = createCallbackFcn(app, @DyEditFieldValueChanged, true);
            app.DyEditField.Visible = 'off';
            app.DyEditField.Tooltip = {''};
            app.refbatchsizeEditFieldLabel = uilabel(app.renderingPanel);
            app.refbatchsizeEditFieldLabel.Text = 'reg batch size';
            app.batch_size_registration = uieditfield(app.renderingPanel, 'numeric');
            app.batch_size_registration.Limits = [0 Inf];
            app.batch_size_registration.Tooltip = {'Number of interferograms used to generate a single image. Increase value for better video quality.'};
            app.regDiscRatioEditField = uieditfield(app.renderingPanel, 'numeric');
            app.regDiscRatioEditField.Limits = [0 1000];
            app.regDiscRatioEditField.ValueChangedFcn = createCallbackFcn(app, @registrationdiscCheckBoxValueChanged, true);
            app.regDiscRatioEditField.Tooltip = {'Laser wavelength in nanometers'};
            app.DxEditFieldLabel_2 = uilabel(app.renderingPanel);
            app.DxEditFieldLabel_2.Text = 'd ratio';
            app.Image_typesListBoxLabel = uilabel(app.renderingPanel);
            app.Image_typesListBoxLabel.Text = 'Image_types';
            app.Image_typesListBox = uilistbox(app.renderingPanel);
            app.Image_typesListBox.Items = {};
            app.Image_typesListBox.Value = {};
            app.LoadfileButton = uibutton(app.FileselectionPanel, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file';
            app.wavelengthEditFieldLabel = uilabel(app.FileselectionPanel);
            app.wavelengthEditFieldLabel.Text = 'wavelength';
            app.lambda = uieditfield(app.FileselectionPanel, 'numeric');
            app.lambda.Limits = [0 Inf];
            app.lambda.Tooltip = {'Laser wavelength in nanometers'};
            app.ParallelismDropDown = uidropdown(app.FileselectionPanel);
            app.ParallelismDropDown.Items = {'CPU multithread', 'LightGPU', 'GPU parallelization', 'CPU singlethread', 'CPU/GPU rendering/optim'};
            app.ParallelismDropDown.Enable = 'off';
            app.ParallelismDropDown.Visible = 'off';
            app.ParallelismDropDown.Value = 'CPU multithread';
            app.fsEditFieldLabel = uilabel(app.FileselectionPanel);
            app.fsEditFieldLabel.Text = 'fs';
            app.fs = uieditfield(app.FileselectionPanel, 'numeric');
            app.fs.Limits = [0 Inf];
            app.fs.Editable = 'off';
            app.fs.Tooltip = {'Sampling frequency (kHz)'};
            app.NotesTextAreaLabel = uilabel(app.FileselectionPanel);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.FileselectionPanel);
            app.numworkersSpinnerLabel = uilabel(app.FileselectionPanel);
            app.numworkersSpinnerLabel.Text = 'num workers';
            app.parfor_arg = uispinner(app.FileselectionPanel);
            app.parfor_arg.Limits = [-1 32];
            app.parfor_arg.Value = 10;
            app.Label = uilabel(app.FileselectionPanel);
            app.Label.Text = '';
            app.setUpDropDown = uidropdown(app.FileselectionPanel);
            app.setUpDropDown.Items = {'Doppler'};
            app.setUpDropDown.Value = 'Doppler';
            app.ppy = uieditfield(app.FileselectionPanel, 'numeric');
            app.ppy.Limits = [0 10];
            app.ppy.Tooltip = {'Number of frequencies intervals you want to have the M0 of.'};
            app.ppxy_label = uilabel(app.FileselectionPanel);
            app.ppxy_label.Text = 'pp x y';
            app.ppx = uieditfield(app.FileselectionPanel, 'numeric');
            app.ppx.Limits = [0 10];
            app.ppx.Tooltip = {'Number of frequencies intervals you want to have the M0 of.'};
            app.EditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.RoundFractionalValues = 'on';
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Tooltip = {''};
            app.positioninfileSliderLabel = uilabel(app.FileselectionPanel);
            app.positioninfileSliderLabel.Text = {'position in file'; ''};
            app.positioninfileSlider = uislider(app.FileselectionPanel);
            app.positioninfileSlider.Limits = [0 1];
            app.positioninfileSlider.MajorTicks = [];
            app.positioninfileSlider.ValueChangedFcn = createCallbackFcn(app, @positioninfileSliderValueChanged, true);
            app.positioninfileSlider.MinorTicks = [];
            app.positioninfileSlider.Tooltip = {'Change value to display a different video timestamp in the gui.'};
            app.CurrentFilePanel = uipanel(app.GridLayout4);
            app.CurrentFilePanel.Tooltip = {''};
            app.CurrentFilePanel.Title = 'Current file';
            app.GridLayout = uigridlayout(app.CurrentFilePanel);
            app.AdvancedProcessingPanel = uipanel(app.GridLayout);
            app.AdvancedProcessingPanel.Title = 'Advanced Processing';
            app.AdvancedProcessingPanel.Visible = 'off';
            app.SVDCheckBox = uicheckbox(app.AdvancedProcessingPanel);
            app.SVDCheckBox.Tooltip = {'Enable SVD hologram filtering in hologram construction'};
            app.SVDCheckBox.Text = 'SVD';
            app.SVDCheckBox.Value = true;
            app.spatialCheckBox = uicheckbox(app.AdvancedProcessingPanel);
            app.spatialCheckBox.Text = 'spatial';
            app.temporalCheckBox = uicheckbox(app.AdvancedProcessingPanel);
            app.temporalCheckBox.Text = 'temporal';
            app.LocalfilteringLabel = uilabel(app.AdvancedProcessingPanel);
            app.LocalfilteringLabel.Text = 'Local filtering';
            app.phi1EditFieldLabel = uilabel(app.AdvancedProcessingPanel);
            app.phi1EditFieldLabel.Text = 'phi1';
            app.phi1EditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.phi2EditFieldLabel = uilabel(app.AdvancedProcessingPanel);
            app.phi2EditFieldLabel.Text = 'phi2';
            app.phi2EditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.nu1EditFieldLabel = uilabel(app.AdvancedProcessingPanel);
            app.nu1EditFieldLabel.Text = 'nu1';
            app.nu1EditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.nu2EditFieldLabel = uilabel(app.AdvancedProcessingPanel);
            app.nu2EditFieldLabel.Text = 'nu2';
            app.nu2EditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.unitcellsinlatticeEditFieldLabel = uilabel(app.AdvancedProcessingPanel);
            app.unitcellsinlatticeEditFieldLabel.Text = '# unit cells in lattice';
            app.unitcellsinlatticeEditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.unitcellsinlatticeEditField.Limits = [0 Inf];
            app.unitcellsinlatticeEditField.Value = 8;
            app.r1EditFieldLabel = uilabel(app.AdvancedProcessingPanel);
            app.r1EditFieldLabel.Text = 'r1';
            app.r1EditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.r1EditField.Limits = [0 Inf];
            app.r1EditField.Value = 3;
            app.xystrideEditFieldLabel = uilabel(app.AdvancedProcessingPanel);
            app.xystrideEditFieldLabel.Text = 'xy stride';
            app.xystrideEditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.xystrideEditField.Value = 32;
            app.SVDx_SubApEditFieldLabel = uilabel(app.AdvancedProcessingPanel);
            app.SVDx_SubApEditFieldLabel.Text = 'SVDx_SubAp';
            app.SVDx_SubApEditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.SVDx_SubApEditField.Limits = [0 20];
            app.SVDx_SubApEditField.Value = 3;
            app.SVDxCheckBox = uicheckbox(app.AdvancedProcessingPanel);
            app.SVDxCheckBox.Text = 'SVDx';
            app.SVDThresholdEditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.SVDThresholdEditField.Limits = [0 Inf];
            app.SVDThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @SVDThresholdEditFieldValueChanged, true);
            app.SVDThresholdEditField.Enable = 'off';
            app.SVDThresholdEditField.Value = 64;
            app.SVDTresholdEditFieldLabel = uilabel(app.AdvancedProcessingPanel);
            app.SVDTresholdEditFieldLabel.Text = 'SVD Threshold';
            app.SVDThresholdCheckBox = uicheckbox(app.AdvancedProcessingPanel);
            app.SVDThresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @SVDThresholdCheckBoxValueChanged, true);
            app.SVDThresholdCheckBox.Text = '';
            app.SVDStrideEditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.SVDStrideEditField.Limits = [0 Inf];
            app.SVDStrideEditField.Tooltip = {'Sub sampling parameter for faster SVD calculations. Defaults to 1 -> full image, 2 -> one pixel on two, ...'};
            app.SVDStrideEditField.Value = 1;
            app.SVDTresholdEditFieldLabel_2 = uilabel(app.AdvancedProcessingPanel);
            app.SVDTresholdEditFieldLabel_2.Text = 'SVD Stride';
            app.AberrationcompensationPanel = uipanel(app.GridLayout);
            app.AberrationcompensationPanel.Title = 'Aberration compensation';
            app.AberrationcompensationPanel.Visible = 'off';
            app.UIAxes_aberrationPreview = uiaxes(app.AberrationcompensationPanel);
            app.UIAxes_aberrationPreview.CLim = [0 1];
            app.UIAxes_aberrationPreview.XTick = [];
            app.UIAxes_aberrationPreview.YTick = [];
            app.Label_2 = uilabel(app.AberrationcompensationPanel);
            app.Label_2.Text = {''; ''};
            app.ShackHartmannCheckBox = uicheckbox(app.AberrationcompensationPanel);
            app.ShackHartmannCheckBox.Tooltip = {'Perform aberration compensation with Shack Hartmann simulation'};
            app.ShackHartmannCheckBox.Text = 'Shack Hartmann';
            app.zernikeranksEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.zernikeranksEditFieldLabel.Text = 'zernike ranks';
            app.shackhartmannzernikeranksEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.shackhartmannzernikeranksEditField.Limits = [2 6];
            app.shackhartmannzernikeranksEditField.RoundFractionalValues = 'on';
            app.shackhartmannzernikeranksEditField.Tooltip = {'Number of zernike ranks to use for aberration correction'};
            app.shackhartmannzernikeranksEditField.Value = 2;
            app.subaperturemarginEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.subaperturemarginEditFieldLabel.Text = 'subaperture margin';
            app.subaperturemarginEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.subaperturemarginEditField.Limits = [0 Inf];
            app.subaperturemarginEditField.Tooltip = {'Number of subapertures used for Shack-Hartmann simulation'};
            app.subaperturemarginEditField.Value = 0.3;
            app.IterativeoptimizationCheckBox = uicheckbox(app.AberrationcompensationPanel);
            app.IterativeoptimizationCheckBox.Tooltip = {'Activate aberration compensation via image entropy optimization. This might significantly increase the computation time.'};
            app.IterativeoptimizationCheckBox.Text = 'Iterative optimization';
            app.zernikeranksEditField_2Label = uilabel(app.AberrationcompensationPanel);
            app.zernikeranksEditField_2Label.Visible = 'off';
            app.zernikeranksEditField_2Label.Text = 'zernike ranks';
            app.optimizationzernikeranksEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.optimizationzernikeranksEditField.Limits = [2 Inf];
            app.optimizationzernikeranksEditField.Visible = 'off';
            app.optimizationzernikeranksEditField.Tooltip = {'Number of zernike ranks that constitue the aberration projection polynomial space'};
            app.optimizationzernikeranksEditField.Value = 2;
            app.toleranceLabel = uilabel(app.AberrationcompensationPanel);
            app.toleranceLabel.Visible = 'off';
            app.toleranceLabel.Text = 'tolerance';
            app.zernikestolEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.zernikestolEditField.Limits = [0 Inf];
            app.zernikestolEditField.Visible = 'off';
            app.zernikestolEditField.Tooltip = {''};
            app.zernikestolEditField.Value = 0.05;
            app.maxconstraintEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.maxconstraintEditFieldLabel.Visible = 'off';
            app.maxconstraintEditFieldLabel.Text = 'max constraint';
            app.maxconstraintEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.maxconstraintEditField.Limits = [1 Inf];
            app.maxconstraintEditField.RoundFractionalValues = 'on';
            app.maxconstraintEditField.Visible = 'off';
            app.maxconstraintEditField.Tooltip = {''};
            app.maxconstraintEditField.Value = 10;
            app.masknumiterEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.masknumiterEditFieldLabel.Visible = 'off';
            app.masknumiterEditFieldLabel.Text = 'mask num iter';
            app.masknumiterEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.masknumiterEditField.Limits = [1 Inf];
            app.masknumiterEditField.RoundFractionalValues = 'on';
            app.masknumiterEditField.Visible = 'off';
            app.masknumiterEditField.Tooltip = {''};
            app.masknumiterEditField.Value = 1;
            app.PreviewLabel = uilabel(app.AberrationcompensationPanel);
            app.PreviewLabel.Text = {'astig_1 : 0.0'; 'defocus: 0.0'; 'astig_2 : 0.0'};
            app.SubAp_PCACheckBox = uicheckbox(app.AberrationcompensationPanel);
            app.SubAp_PCACheckBox.ValueChangedFcn = createCallbackFcn(app, @SubAp_PCACheckBoxValueChanged, true);
            app.SubAp_PCACheckBox.Text = 'SubAp_PCA';
            app.minEditField_2Label = uilabel(app.AberrationcompensationPanel);
            app.minEditField_2Label.Text = 'min';
            app.minSubAp_PCAEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.minSubAp_PCAEditField.Limits = [1 Inf];
            app.minSubAp_PCAEditField.ValueChangedFcn = createCallbackFcn(app, @minSubApPCAEditField_2ValueChanged, true);
            app.minSubAp_PCAEditField.Value = 1;
            app.maxEditField_2Label = uilabel(app.AberrationcompensationPanel);
            app.maxEditField_2Label.Text = 'max';
            app.maxSubAp_PCAEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.maxSubAp_PCAEditField.Limits = [1 Inf];
            app.maxSubAp_PCAEditField.ValueChangedFcn = createCallbackFcn(app, @maxSubAp_PCAEditField_2_2ValueChanged, true);
            app.maxSubAp_PCAEditField.Value = 16;
            app.volumesizeEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.volumesizeEditFieldLabel.Visible = 'off';
            app.volumesizeEditFieldLabel.Text = 'volume size';
            app.volumesizeEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.volumesizeEditField.Limits = [1 Inf];
            app.volumesizeEditField.Visible = 'off';
            app.volumesizeEditField.Value = 256;
            app.rangeZEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.rangeZEditFieldLabel.Visible = 'off';
            app.rangeZEditFieldLabel.Text = 'rangeZ';
            app.rangeZEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.rangeZEditField.Limits = [1 Inf];
            app.rangeZEditField.ValueChangedFcn = createCallbackFcn(app, @rangeZEditFieldValueChanged, true);
            app.rangeZEditField.Visible = 'off';
            app.rangeZEditField.Value = 1;
            app.rangeYEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.rangeYEditFieldLabel.Visible = 'off';
            app.rangeYEditFieldLabel.Text = 'rangeY';
            app.rangeYEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.rangeYEditField.Limits = [1 Inf];
            app.rangeYEditField.ValueChangedFcn = createCallbackFcn(app, @rangeYEditFieldValueChanged, true);
            app.rangeYEditField.Visible = 'off';
            app.rangeYEditField.Value = 1;
            app.savecoefsCheckBox = uicheckbox(app.AberrationcompensationPanel);
            app.savecoefsCheckBox.Text = 'save coefs';
            app.ZernikeProjectionCheckBox = uicheckbox(app.AberrationcompensationPanel);
            app.ZernikeProjectionCheckBox.Tooltip = {'Perform aberration compensation with Shack Hartmann simulation'};
            app.ZernikeProjectionCheckBox.Text = 'Zernike Projection';
            app.referenceimageDropDownLabel = uilabel(app.AberrationcompensationPanel);
            app.referenceimageDropDownLabel.Text = 'reference image';
            app.referenceimageDropDown = uidropdown(app.AberrationcompensationPanel);
            app.referenceimageDropDown.Items = {'central subaperture', 'resized image'};
            app.referenceimageDropDown.Value = 'central subaperture';
            app.subapnumpositionsEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.subapnumpositionsEditFieldLabel.Text = 'subap num positions';
            app.subapnumpositionsEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.subapnumpositionsEditField.Limits = [1 20];
            app.subapnumpositionsEditField.RoundFractionalValues = 'on';
            app.subapnumpositionsEditField.Tooltip = {'Number of subapertures used for Shack-Hartmann simulation'};
            app.subapnumpositionsEditField.Value = 5;
            app.imagesubapsizeratioEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.imagesubapsizeratioEditFieldLabel.Text = 'image subap size ratio';
            app.imagesubapsizeratioEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.imagesubapsizeratioEditField.Limits = [1 20];
            app.imagesubapsizeratioEditField.RoundFractionalValues = 'on';
            app.imagesubapsizeratioEditField.Tooltip = {'Number of subapertures used for Shack-Hartmann simulation'};
            app.imagesubapsizeratioEditField.Value = 5;
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.parametersPanel = uipanel(app.GridLayout3);
            app.parametersPanel.Title = 'parameters';
            app.num_batches = uilabel(app.parametersPanel);
            app.num_batches.Text = '';
            app.spatial_filterCheckBox = uicheckbox(app.parametersPanel);
            app.spatial_filterCheckBox.Text = 'spatial_filter';
            app.hilbert_filterCheckBox = uicheckbox(app.parametersPanel);
            app.hilbert_filterCheckBox.Text = 'hilbert_filter';
            app.spatial_filter_rangeLabel = uilabel(app.parametersPanel);
            app.spatial_filter_rangeLabel.Text = 'spatial_filter_range';
            app.spatial_filter_range1 = uieditfield(app.parametersPanel, 'numeric');
            app.spatial_filter_range2 = uieditfield(app.parametersPanel, 'numeric');
            app.spatial_transformationDropDownLabel = uilabel(app.parametersPanel);
            app.spatial_transformationDropDownLabel.Text = 'spatial_transformation';
            app.spatial_transformationDropDown = uidropdown(app.parametersPanel);
            app.spatial_propagationEditFieldLabel = uilabel(app.parametersPanel);
            app.spatial_propagationEditFieldLabel.Text = 'spatial_propagation';
            app.spatial_propagationEditField = uieditfield(app.parametersPanel, 'numeric');
            app.svd_filterCheckBox = uicheckbox(app.parametersPanel);
            app.svd_filterCheckBox.Text = 'svd_filter';
            app.svdx_filterCheckBox = uicheckbox(app.parametersPanel);
            app.svdx_filterCheckBox.Text = 'svdx_filter';
            app.svd_thresholdEditFieldLabel = uilabel(app.parametersPanel);
            app.svd_thresholdEditFieldLabel.Text = 'svd_threshold';
            app.svd_thresholdEditField = uieditfield(app.parametersPanel, 'numeric');
            app.time_transformDropDownLabel = uilabel(app.parametersPanel);
            app.time_transformDropDownLabel.Text = 'time_transform';
            app.time_transformDropDown = uidropdown(app.parametersPanel);
            app.time_rangeLabel = uilabel(app.parametersPanel);
            app.time_rangeLabel.Text = 'time_range';
            app.time_range1 = uieditfield(app.parametersPanel, 'numeric');
            app.time_range2 = uieditfield(app.parametersPanel, 'numeric');
            app.flat_field_gwEditFieldLabel = uilabel(app.parametersPanel);
            app.flat_field_gwEditFieldLabel.Text = 'flat_field_gw';
            app.flat_field_gwEditField = uieditfield(app.parametersPanel, 'numeric');
            app.RenderPreviewButton = uibutton(app.parametersPanel, 'push');
            app.RenderPreviewButton.Text = 'Render Preview';
            app.ImageLeft = uiimage(app.GridLayout4);
            app.ImageLeft.ImageClickedFcn = createCallbackFcn(app, @ImageLeftClicked, true);
            app.ImageRight = uiimage(app.GridLayout4);
            app.ImageRight.ImageClickedFcn = createCallbackFcn(app, @ImageRightClicked, true);
            % Show the figure after all components are created
            app.HoloDopplerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = holodoppler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HoloDopplerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HoloDopplerUIFigure)
        end
    end
end