classdef manual_correction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        astig45Label                matlab.ui.control.Label
        astig45Slider               matlab.ui.control.Slider
        astig90Label                matlab.ui.control.Label
        astig90Slider               matlab.ui.control.Slider
        defocusSliderLabel          matlab.ui.control.Label
        defocusSlider               matlab.ui.control.Slider
        batchnumberLabel            matlab.ui.control.Label
        batchnumberSpinner          matlab.ui.control.Spinner
        OptimizeButton              matlab.ui.control.Button
        Lamp                        matlab.ui.control.Lamp
        RightPanel                  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        AdditionalaberrationPanel   matlab.ui.container.Panel
        ZernikecoefsEditFieldLabel  matlab.ui.control.Label
        ZernikecoefsEditField       matlab.ui.control.EditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        acquisition
        cine_file
        j_win
        interferogram_stream
        kernel
        FH_no_additional_aberration
        FH
        zernike_eval
        f1
        f2
        gw
        entropy_opt
        crop
        Nx
        Ny
        Nx_before_crop
        Ny_before_crop
    end
    
    methods (Access = private)
        
        function draw_image(app, img_batch)
            img = reconstruct_hologram(app.FH, app.f1, app.f2, app.acquisition, app.gw, true);
            imshow(mat2gray(img), 'Parent', app.UIAxes);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            config;
            app.j_win = j_win;
            app.crop = crop;
            app.Nx_before_crop = Nx_before_crop;
            app.Ny_before_crop = Ny_before_crop;
            app.Nx = Nx;
            app.Ny = Ny;
            app.cine_file = cine_file;
            
            % get interferogram filename
            if app.cine_file
                [data_filename, data_path] = uigetfile('.cine');
            else
                [data_filename, data_path] = uigetfile('.raw');
            end
            
            %% Construct InterferogramStream object
            fullpath = fullfile(data_path, data_filename);
            app.acquisition = DopplerAcquisition(Nx, Ny, fs, z, lambda, delta_x, delta_y, x_step, y_step);
            
            if app.cine_file
                app.interferogram_stream = CineReader(fullpath);
                app.acquisition.Nx = app.interferogram_stream.frame_width;
                app.acquisition.Ny = app.interferogram_stream.frame_height;
                fs = app.interferogram_stream.frame_rate / 1000; % in kHz
            else
                app.interferogram_stream = InterferogramStream(fullpath, endianness,app.acquisition, ...
                                                       j_win, j_step);
                app.interferogram_stream.acquisition.Nx = Nx_before_crop;
                app.interferogram_stream.acquisition.Ny = Ny_before_crop;
            end

%             app.batchnumberSpinner.Limits(2) = app.interferogram_stream.num_frame_batches();
            app.kernel = propagation_kernel(Nx, Ny, z, lambda, x_step, y_step, false);
            app.f1 = f1;
            app.f2 = f2;
            app.gw = gaussian_width;
            app.entropy_opt = EntropyOptimization(app.f1, app.f2, n, m, min_constraint, ...
                                          max_constraint, initial_guess, ...
                                          mask_radiuses);
            if app.cine_file
                img_batch = app.interferogram_stream.read_frame_batch(j_win, 1);
            else
                img_batch = app.interferogram_stream.read_batch(1);
            end
            if crop
               x_offset = floor((Nx_before_crop - Nx) / 2);
               y_offset = floor((Ny_before_crop - Ny) / 2);
               img_batch = img_batch(x_offset:x_offset+Nx-1, y_offset:y_offset+Ny-1, :);
            end
            
            
            % reconstruct hologram
            app.FH_no_additional_aberration = fftshift(fft2(img_batch)) .* app.kernel;
            app.FH = gpuArray(app.FH_no_additional_aberration);
            app.draw_image(img_batch);

            
            % eval zernikes
            app.zernike_eval = evaluate_zernikes(n, m, Nx, Ny);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {512, 512};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {215, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: astig45Slider
        function astig45SliderValueChanged(app, event)
            value = app.astig45Slider.Value;
            coefs = [value app.defocusSlider.Value app.astig90Slider.Value];
            phase_correction = compute_phase_correction(coefs, app.zernike_eval);
            hologram_corrected = reconstruct_hologram(app.FH, app.f1, app.f2, app.acquisition, app.gw, true, phase_correction);
            imshow(mat2gray(hologram_corrected), 'Parent', app.UIAxes);
        end

        % Value changed function: astig90Slider
        function astig90SliderValueChanged(app, event)
            value = app.astig90Slider.Value;
            coefs = [app.astig45Slider.Value app.defocusSlider.Value value];
            phase_correction = compute_phase_correction(coefs, app.zernike_eval);
            hologram_corrected = reconstruct_hologram(app.FH, app.f1, app.f2, app.acquisition, app.gw, true, phase_correction);
            imshow(mat2gray(hologram_corrected), 'Parent', app.UIAxes); 
        end

        % Value changed function: defocusSlider
        function defocusSliderValueChanged(app, event)
            value = app.defocusSlider.Value;
            coefs = [app.astig45Slider.Value value app.astig90Slider.Value];
            phase_correction = compute_phase_correction(coefs, app.zernike_eval);
            hologram_corrected = reconstruct_hologram(app.FH, app.f1, app.f2, app.acquisition, app.gw, true, phase_correction);
            imshow(mat2gray(hologram_corrected), 'Parent', app.UIAxes); 
        end

        % Value changed function: batchnumberSpinner
        function batchnumberSpinnerValueChanged(app, event)
            num_batch = app.batchnumberSpinner.Value;
            if app.cine_file
                frame_batch = app.interferogram_stream.read_frame_batch(1024, 512 * num_batch);
            else
                frame_batch = app.interferogram_stream.read_batch(num_batch);
            end
            if app.crop
               x_offset = floor((app.Nx_before_crop - app.Nx) / 2);
               y_offset = floor((app.Ny_before_crop - app.Ny) / 2);
               frame_batch = frame_batch(x_offset:x_offset+app.Nx-1, y_offset:y_offset+app.Ny-1, :);
            end
            app.FH = fftshift(fft2(frame_batch)) .* app.kernel;
            coefs = [app.astig45Slider.Value app.astig90Slider.Value app.astig90Slider.Value]; 
            phase_correction = compute_phase_correction(coefs, app.zernike_eval);
            hologram_corrected = reconstruct_hologram(app.FH, app.f1, app.f2, app.acquisition, app.gw, true, phase_correction);
            imshow(mat2gray(hologram_corrected), 'Parent', app.UIAxes); 
        end

        % Button pushed function: OptimizeButton
        function OptimizeButtonPushed(app, event)
            set(app.Lamp, 'Color', [1.00,0.00,0.00]);
            drawnow
            X = app.entropy_opt.optimize(app.FH, app.f1, app.f2, app.acquisition, app.gw, 0.4, true);
            set(app.astig45Slider,'Value',X(1));
            set(app.astig90Slider,'Value',X(3));
            set(app.defocusSlider,'Value',X(2));
            coefs = [app.astig45Slider.Value app.defocusSlider.Value app.astig90Slider.Value];
            phase_correction = compute_phase_correction(coefs, app.zernike_eval);
            hologram_corrected = reconstruct_hologram(app.FH, app.f1, app.f2, app.acquisition, app.gw, true, phase_correction);
            imshow(mat2gray(hologram_corrected), 'Parent', app.UIAxes);
            set(app.Lamp, 'Color', [0.39,0.83,0.07]);
        end

        % Value changed function: ZernikecoefsEditField
        function ZernikecoefsEditFieldValueChanged(app, event)
            value = app.ZernikecoefsEditField.Value;
            
            %% convert text to array
            b=erase(value,{'[',']'});
            a=split(b,{' ',','});
            coefs = str2double(a);
            
            %% compute zernikes (there may be more than 3)
            [~, zernikes] = zernike_phase(coefs, app.acquisition.Nx, app.acquisition.Ny);
            phase_aberration = compute_phase_correction(coefs', zernikes);
            
            %% apply aberration to image
            app.FH = gpuArray(app.FH_no_additional_aberration .* exp(1i*phase_aberration));
            app.draw_image(app.FH);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.astig45Label = uilabel(app.LeftPanel);
            app.astig45Label.Text = 'astig 45';
            app.astig45Slider = uislider(app.LeftPanel);
            app.astig45Slider.Limits = [-30 30];
            app.astig45Slider.ValueChangedFcn = createCallbackFcn(app, @astig45SliderValueChanged, true);
            app.astig90Label = uilabel(app.LeftPanel);
            app.astig90Label.Text = 'astig 90';
            app.astig90Slider = uislider(app.LeftPanel);
            app.astig90Slider.Limits = [-30 30];
            app.astig90Slider.ValueChangedFcn = createCallbackFcn(app, @astig90SliderValueChanged, true);
            app.defocusSliderLabel = uilabel(app.LeftPanel);
            app.defocusSliderLabel.Text = 'defocus';
            app.defocusSlider = uislider(app.LeftPanel);
            app.defocusSlider.Limits = [-30 30];
            app.defocusSlider.ValueChangedFcn = createCallbackFcn(app, @defocusSliderValueChanged, true);
            app.batchnumberLabel = uilabel(app.LeftPanel);
            app.batchnumberLabel.Text = 'batch number';
            app.batchnumberSpinner = uispinner(app.LeftPanel);
            app.batchnumberSpinner.Limits = [1 Inf];
            app.batchnumberSpinner.ValueChangedFcn = createCallbackFcn(app, @batchnumberSpinnerValueChanged, true);
            app.batchnumberSpinner.Value = 1;
            app.OptimizeButton = uibutton(app.LeftPanel, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeButtonPushed, true);
            app.OptimizeButton.Text = 'Optimize';
            app.Lamp = uilamp(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.AdditionalaberrationPanel = uipanel(app.RightPanel);
            app.AdditionalaberrationPanel.Title = 'Additional aberration';
            app.ZernikecoefsEditFieldLabel = uilabel(app.AdditionalaberrationPanel);
            app.ZernikecoefsEditFieldLabel.Text = 'Zernike coefs';
            app.ZernikecoefsEditField = uieditfield(app.AdditionalaberrationPanel, 'text');
            app.ZernikecoefsEditField.ValueChangedFcn = createCallbackFcn(app, @ZernikecoefsEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manual_correction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end